classdef Labsmith_Interface_Stop_NewWindow < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        DisconnectionPanel          matlab.ui.container.Panel
        DisconnectButton            matlab.ui.control.Button
        SyringesControlPanel        matlab.ui.container.Panel
        StopButton_SyringeControl   matlab.ui.control.Button
        FullAutocalibrationButton   matlab.ui.control.Button
        FRulminEditField            matlab.ui.control.NumericEditField
        FRulminEditFieldLabel       matlab.ui.control.Label
        RunBufferButton             matlab.ui.control.Button
        FillingButton               matlab.ui.control.Button
        ConnectionPanel             matlab.ui.container.Panel
        COMPORTEditField            matlab.ui.control.NumericEditField
        COMPORTEditFieldLabel       matlab.ui.control.Label
        LoadBoardButton             matlab.ui.control.Button
        ConnectButton               matlab.ui.control.Button
        ScriptsPanel                matlab.ui.container.Panel
        ShowButton                  matlab.ui.control.Button
        FileDropDown                matlab.ui.control.DropDown
        SearchButton                matlab.ui.control.Button
        StopButton_Script           matlab.ui.control.Button
        StartButton                 matlab.ui.control.Button
        ValveCheckPanel             matlab.ui.container.Panel
        StopButton_ValveCheck       matlab.ui.control.Button
        IterationEditField          matlab.ui.control.NumericEditField
        IterationEditFieldLabel     matlab.ui.control.Label
        SwitchValvesButton          matlab.ui.control.Button
        Panel_8                     matlab.ui.container.Panel
        StopButton_8                matlab.ui.control.Button
        NewNameEditField_8          matlab.ui.control.EditField
        NewNameEditField_8Label     matlab.ui.control.Label
        NameEditField_8             matlab.ui.control.EditField
        PanelC4VM_8                 matlab.ui.container.Panel
        nochange1A2closed3BLabel_8  matlab.ui.control.Label
        SwitchButton_8              matlab.ui.control.Button
        Valve1EditField_8           matlab.ui.control.NumericEditField
        Valve1EditField_8Label      matlab.ui.control.Label
        Valve4EditField_8           matlab.ui.control.NumericEditField
        Valve4EditField_8Label      matlab.ui.control.Label
        Valve3EditField_8           matlab.ui.control.NumericEditField
        Valve3EditField_8Label      matlab.ui.control.Label
        Valve2EditField_8           matlab.ui.control.NumericEditField
        Valve2EditField_8Label      matlab.ui.control.Label
        FlashButton_8               matlab.ui.control.Button
        ChangeButton_8              matlab.ui.control.Button
        Lamp_8                      matlab.ui.control.Lamp
        ClassEditField_8            matlab.ui.control.EditField
        ClassEditField_8Label       matlab.ui.control.Label
        AddrEditField_8             matlab.ui.control.NumericEditField
        AddrEditField_8Label        matlab.ui.control.Label
        PanelSPS01_8                matlab.ui.container.Panel
        FRulminEditField_8          matlab.ui.control.NumericEditField
        FRulminEditField_8Label     matlab.ui.control.Label
        DiammmEditField_8           matlab.ui.control.NumericEditField
        DiammmEditField_8Label      matlab.ui.control.Label
        EditField_8                 matlab.ui.control.EditField
        AutoCalibrationButton_8     matlab.ui.control.Button
        MaxVulEditField_8           matlab.ui.control.NumericEditField
        MaxVulEditField_8Label      matlab.ui.control.Label
        VfmmEditField_8             matlab.ui.control.NumericEditField
        VfmmEditField_8Label        matlab.ui.control.Label
        MoveToButton_8              matlab.ui.control.Button
        VmmEditField_8              matlab.ui.control.NumericEditField
        VmmEditField_8Label         matlab.ui.control.Label
        UpdateButton_8              matlab.ui.control.Button
        MaxFRulminEditField_8       matlab.ui.control.NumericEditField
        MaxFRulminEditField_8Label  matlab.ui.control.Label
        MinFRulminEditField_8       matlab.ui.control.NumericEditField
        MinFRulminEditField_8Label  matlab.ui.control.Label
        Panel_7                     matlab.ui.container.Panel
        StopButton_7                matlab.ui.control.Button
        NewNameEditField_7          matlab.ui.control.EditField
        NewNameEditField_7Label     matlab.ui.control.Label
        NameEditField_7             matlab.ui.control.EditField
        FlashButton_7               matlab.ui.control.Button
        ChangeButton_7              matlab.ui.control.Button
        Lamp_7                      matlab.ui.control.Lamp
        ClassEditField_7            matlab.ui.control.EditField
        ClassEditField_7Label       matlab.ui.control.Label
        AddrEditField_7             matlab.ui.control.NumericEditField
        AddrEditField_7Label        matlab.ui.control.Label
        PanelSPS01_7                matlab.ui.container.Panel
        FRulminEditField_7          matlab.ui.control.NumericEditField
        FRulminEditField_7Label     matlab.ui.control.Label
        DiammmEditField_7           matlab.ui.control.NumericEditField
        DiammmEditField_7Label      matlab.ui.control.Label
        EditField_7                 matlab.ui.control.EditField
        AutoCalibrationButton_7     matlab.ui.control.Button
        MaxVulEditField_7           matlab.ui.control.NumericEditField
        MaxVulEditField_7Label      matlab.ui.control.Label
        VfmmEditField_7             matlab.ui.control.NumericEditField
        VfmmEditField_7Label        matlab.ui.control.Label
        MoveToButton_7              matlab.ui.control.Button
        VmmEditField_7              matlab.ui.control.NumericEditField
        VmmEditField_7Label         matlab.ui.control.Label
        UpdateButton_7              matlab.ui.control.Button
        MaxFRulminEditField_7       matlab.ui.control.NumericEditField
        MaxFRulminEditField_7Label  matlab.ui.control.Label
        MinFRulminEditField_7       matlab.ui.control.NumericEditField
        MinFRulminEditField_7Label  matlab.ui.control.Label
        PanelC4VM_7                 matlab.ui.container.Panel
        nochange1A2closed3BLabel_7  matlab.ui.control.Label
        SwitchButton_7              matlab.ui.control.Button
        Valve1EditField_7           matlab.ui.control.NumericEditField
        Valve1EditField_7Label      matlab.ui.control.Label
        Valve4EditField_7           matlab.ui.control.NumericEditField
        Valve4EditField_7Label      matlab.ui.control.Label
        Valve3EditField_7           matlab.ui.control.NumericEditField
        Valve3EditField_7Label      matlab.ui.control.Label
        Valve2EditField_7           matlab.ui.control.NumericEditField
        Valve2EditField_7Label      matlab.ui.control.Label
        Panel_6                     matlab.ui.container.Panel
        StopButton_6                matlab.ui.control.Button
        NewNameEditField_6          matlab.ui.control.EditField
        NewNameEditField_6Label     matlab.ui.control.Label
        NameEditField_6             matlab.ui.control.EditField
        PanelC4VM_6                 matlab.ui.container.Panel
        nochange1A2closed3BLabel_6  matlab.ui.control.Label
        SwitchButton_6              matlab.ui.control.Button
        Valve1EditField_6           matlab.ui.control.NumericEditField
        Valve1EditField_6Label      matlab.ui.control.Label
        Valve4EditField_6           matlab.ui.control.NumericEditField
        Valve4EditField_6Label      matlab.ui.control.Label
        Valve3EditField_6           matlab.ui.control.NumericEditField
        Valve3EditField_6Label      matlab.ui.control.Label
        Valve2EditField_6           matlab.ui.control.NumericEditField
        Valve2EditField_6Label      matlab.ui.control.Label
        FlashButton_6               matlab.ui.control.Button
        ChangeButton_6              matlab.ui.control.Button
        Lamp_6                      matlab.ui.control.Lamp
        ClassEditField_6            matlab.ui.control.EditField
        ClassEditField_6Label       matlab.ui.control.Label
        AddrEditField_6             matlab.ui.control.NumericEditField
        AddrEditField_6Label        matlab.ui.control.Label
        PanelSPS01_6                matlab.ui.container.Panel
        FRulminEditField_6          matlab.ui.control.NumericEditField
        FRulminEditField_6Label     matlab.ui.control.Label
        DiammmEditField_6           matlab.ui.control.NumericEditField
        DiammmEditField_6Label      matlab.ui.control.Label
        EditField_6                 matlab.ui.control.EditField
        AutoCalibrationButton_6     matlab.ui.control.Button
        MaxVulEditField_6           matlab.ui.control.NumericEditField
        MaxVulEditField_6Label      matlab.ui.control.Label
        VfmmEditField_6             matlab.ui.control.NumericEditField
        VfmmEditField_6Label        matlab.ui.control.Label
        MoveToButton_6              matlab.ui.control.Button
        VmmEditField_6              matlab.ui.control.NumericEditField
        VmmEditField_6Label         matlab.ui.control.Label
        UpdateButton_6              matlab.ui.control.Button
        MaxFRulminEditField_6       matlab.ui.control.NumericEditField
        MaxFRulminEditField_6Label  matlab.ui.control.Label
        MinFRulminEditField_6       matlab.ui.control.NumericEditField
        MinFRulminEditField_6Label  matlab.ui.control.Label
        Panel_5                     matlab.ui.container.Panel
        StopButton_5                matlab.ui.control.Button
        NewNameEditField_5          matlab.ui.control.EditField
        NewNameEditField_5Label     matlab.ui.control.Label
        NameEditField_5             matlab.ui.control.EditField
        PanelC4VM_5                 matlab.ui.container.Panel
        nochange1A2closed3BLabel_5  matlab.ui.control.Label
        SwitchButton_5              matlab.ui.control.Button
        Valve1EditField_5           matlab.ui.control.NumericEditField
        Valve1EditField_5Label      matlab.ui.control.Label
        Valve4EditField_5           matlab.ui.control.NumericEditField
        Valve4EditField_5Label      matlab.ui.control.Label
        Valve3EditField_5           matlab.ui.control.NumericEditField
        Valve3EditField_5Label      matlab.ui.control.Label
        Valve2EditField_5           matlab.ui.control.NumericEditField
        Valve2EditField_5Label      matlab.ui.control.Label
        FlashButton_5               matlab.ui.control.Button
        ChangeButton_5              matlab.ui.control.Button
        Lamp_5                      matlab.ui.control.Lamp
        ClassEditField_5            matlab.ui.control.EditField
        ClassEditField_5Label       matlab.ui.control.Label
        AddrEditField_5             matlab.ui.control.NumericEditField
        AddrEditField_5Label        matlab.ui.control.Label
        PanelSPS01_5                matlab.ui.container.Panel
        FRulminEditField_5          matlab.ui.control.NumericEditField
        FRulminEditField_5Label     matlab.ui.control.Label
        DiammmEditField_5           matlab.ui.control.NumericEditField
        DiammmEditField_5Label      matlab.ui.control.Label
        EditField_5                 matlab.ui.control.EditField
        AutoCalibrationButton_5     matlab.ui.control.Button
        MaxVulEditField_5           matlab.ui.control.NumericEditField
        MaxVulEditField_5Label      matlab.ui.control.Label
        VfmmEditField_5             matlab.ui.control.NumericEditField
        VfmmEditField_5Label        matlab.ui.control.Label
        MoveToButton_5              matlab.ui.control.Button
        VmmEditField_5              matlab.ui.control.NumericEditField
        VmmEditField_5Label         matlab.ui.control.Label
        UpdateButton_5              matlab.ui.control.Button
        MaxFRulminEditField_5       matlab.ui.control.NumericEditField
        MaxFRulminEditField_5Label  matlab.ui.control.Label
        MinFRulminEditField_5       matlab.ui.control.NumericEditField
        MinFRulminEditField_5Label  matlab.ui.control.Label
        Panel_4                     matlab.ui.container.Panel
        StopButton_4                matlab.ui.control.Button
        NewNameEditField_4          matlab.ui.control.EditField
        NewNameEditField_4Label     matlab.ui.control.Label
        NameEditField_4             matlab.ui.control.EditField
        PanelC4VM_4                 matlab.ui.container.Panel
        nochange1A2closed3BLabel_4  matlab.ui.control.Label
        SwitchButton_4              matlab.ui.control.Button
        Valve1EditField_4           matlab.ui.control.NumericEditField
        Valve1EditField_4Label      matlab.ui.control.Label
        Valve4EditField_4           matlab.ui.control.NumericEditField
        Valve4EditField_4Label      matlab.ui.control.Label
        Valve3EditField_4           matlab.ui.control.NumericEditField
        Valve3EditField_4Label      matlab.ui.control.Label
        Valve2EditField_4           matlab.ui.control.NumericEditField
        Valve2EditField_4Label      matlab.ui.control.Label
        FlashButton_4               matlab.ui.control.Button
        ChangeButton_4              matlab.ui.control.Button
        Lamp_4                      matlab.ui.control.Lamp
        ClassEditField_4            matlab.ui.control.EditField
        ClassEditField_4Label       matlab.ui.control.Label
        AddrEditField_4             matlab.ui.control.NumericEditField
        AddrEditField_4Label        matlab.ui.control.Label
        PanelSPS01_4                matlab.ui.container.Panel
        FRulminEditField_4          matlab.ui.control.NumericEditField
        FRulminEditField_4Label     matlab.ui.control.Label
        DiammmEditField_4           matlab.ui.control.NumericEditField
        DiammmEditField_4Label      matlab.ui.control.Label
        EditField_4                 matlab.ui.control.EditField
        AutoCalibrationButton_4     matlab.ui.control.Button
        MaxVulEditField_4           matlab.ui.control.NumericEditField
        MaxVulEditField_4Label      matlab.ui.control.Label
        VfmmEditField_4             matlab.ui.control.NumericEditField
        VfmmEditField_4Label        matlab.ui.control.Label
        MoveToButton_4              matlab.ui.control.Button
        VmmEditField_4              matlab.ui.control.NumericEditField
        VmmEditField_4Label         matlab.ui.control.Label
        UpdateButton_4              matlab.ui.control.Button
        MaxFRulminEditField_4       matlab.ui.control.NumericEditField
        MaxFRulminEditField_4Label  matlab.ui.control.Label
        MinFRulminEditField_4       matlab.ui.control.NumericEditField
        MinFRulminEditField_4Label  matlab.ui.control.Label
        Panel_3                     matlab.ui.container.Panel
        StopButton_3                matlab.ui.control.Button
        NewNameEditField_3          matlab.ui.control.EditField
        NewNameEditField_3Label     matlab.ui.control.Label
        NameEditField_3             matlab.ui.control.EditField
        PanelC4VM_3                 matlab.ui.container.Panel
        nochange1A2closed3BLabel_3  matlab.ui.control.Label
        SwitchButton_3              matlab.ui.control.Button
        Valve1EditField_3           matlab.ui.control.NumericEditField
        Valve1EditField_3Label      matlab.ui.control.Label
        Valve4EditField_3           matlab.ui.control.NumericEditField
        Valve4EditField_3Label      matlab.ui.control.Label
        Valve3EditField_3           matlab.ui.control.NumericEditField
        Valve3EditField_3Label      matlab.ui.control.Label
        Valve2EditField_3           matlab.ui.control.NumericEditField
        Valve2EditField_3Label      matlab.ui.control.Label
        FlashButton_3               matlab.ui.control.Button
        ChangeButton_3              matlab.ui.control.Button
        Lamp_3                      matlab.ui.control.Lamp
        ClassEditField_3            matlab.ui.control.EditField
        ClassEditField_3Label       matlab.ui.control.Label
        AddrEditField_3             matlab.ui.control.NumericEditField
        AddrEditField_3Label        matlab.ui.control.Label
        PanelSPS01_3                matlab.ui.container.Panel
        FRulminEditField_3          matlab.ui.control.NumericEditField
        FRulminEditField_3Label     matlab.ui.control.Label
        DiammmEditField_3           matlab.ui.control.NumericEditField
        DiammmEditField_3Label      matlab.ui.control.Label
        EditField_3                 matlab.ui.control.EditField
        AutoCalibrationButton_3     matlab.ui.control.Button
        MaxVulEditField_3           matlab.ui.control.NumericEditField
        MaxVulEditField_3Label      matlab.ui.control.Label
        VfmmEditField_3             matlab.ui.control.NumericEditField
        VfmmEditField_3Label        matlab.ui.control.Label
        MoveToButton_3              matlab.ui.control.Button
        VmmEditField_3              matlab.ui.control.NumericEditField
        VmmEditField_3Label         matlab.ui.control.Label
        UpdateButton_3              matlab.ui.control.Button
        MaxFRulminEditField_3       matlab.ui.control.NumericEditField
        MaxFRulminEditField_3Label  matlab.ui.control.Label
        MinFRulminEditField_3       matlab.ui.control.NumericEditField
        MinFRulminEditField_3Label  matlab.ui.control.Label
        Panel_2                     matlab.ui.container.Panel
        StopButton_2                matlab.ui.control.Button
        NewNameEditField_2          matlab.ui.control.EditField
        NewNameEditField_2Label     matlab.ui.control.Label
        NameEditField_2             matlab.ui.control.EditField
        PanelC4VM_2                 matlab.ui.container.Panel
        nochange1A2closed3BLabel_2  matlab.ui.control.Label
        SwitchButton_2              matlab.ui.control.Button
        Valve1EditField_2           matlab.ui.control.NumericEditField
        Valve1EditField_2Label      matlab.ui.control.Label
        Valve4EditField_2           matlab.ui.control.NumericEditField
        Valve4EditField_2Label      matlab.ui.control.Label
        Valve3EditField_2           matlab.ui.control.NumericEditField
        Valve3EditField_2Label      matlab.ui.control.Label
        Valve2EditField_2           matlab.ui.control.NumericEditField
        Valve2EditField_2Label      matlab.ui.control.Label
        Lamp_2                      matlab.ui.control.Lamp
        ChangeButton_2              matlab.ui.control.Button
        FlashButton_2               matlab.ui.control.Button
        ClassEditField_2            matlab.ui.control.EditField
        ClassEditField_2Label       matlab.ui.control.Label
        AddrEditField_2             matlab.ui.control.NumericEditField
        AddrEditField_2Label        matlab.ui.control.Label
        PanelSPS01_2                matlab.ui.container.Panel
        FRulminEditField_2          matlab.ui.control.NumericEditField
        FRulminEditField_2Label_2   matlab.ui.control.Label
        DiammmEditField_2           matlab.ui.control.NumericEditField
        DiammmEditField_2Label      matlab.ui.control.Label
        EditField_2                 matlab.ui.control.EditField
        AutoCalibrationButton_2     matlab.ui.control.Button
        MaxVulEditField_2           matlab.ui.control.NumericEditField
        MaxVulEditField_2Label      matlab.ui.control.Label
        VfmmEditField_2             matlab.ui.control.NumericEditField
        VfmmEditField_2Label        matlab.ui.control.Label
        MoveToButton_2              matlab.ui.control.Button
        VmmEditField_2              matlab.ui.control.NumericEditField
        VmmEditField_2Label         matlab.ui.control.Label
        UpdateButton_2              matlab.ui.control.Button
        MaxFRulminEditField_2       matlab.ui.control.NumericEditField
        MaxFRulminEditField_2Label  matlab.ui.control.Label
        MinFRulminEditField_2       matlab.ui.control.NumericEditField
        MinFRulminEditField_2Label  matlab.ui.control.Label
        Panel_1                     matlab.ui.container.Panel
        StopButton_1                matlab.ui.control.Button
        NameEditField_1             matlab.ui.control.EditField
        ChangeButton                matlab.ui.control.Button
        NewNameEditField_1          matlab.ui.control.EditField
        NewNameLabel                matlab.ui.control.Label
        FlashButton_1               matlab.ui.control.Button
        ClassEditField_1            matlab.ui.control.EditField
        ClassEditFieldLabel         matlab.ui.control.Label
        AddrEditField_1             matlab.ui.control.NumericEditField
        AddrEditFieldLabel          matlab.ui.control.Label
        Lamp_1                      matlab.ui.control.Lamp
        PanelSPS01_1                matlab.ui.container.Panel
        FRulminEditField_1          matlab.ui.control.NumericEditField
        FRulminEditField_2Label     matlab.ui.control.Label
        VfmmEditField_1             matlab.ui.control.NumericEditField
        VfmmEditFieldLabel          matlab.ui.control.Label
        VmmEditField_1              matlab.ui.control.NumericEditField
        VmmEditFieldLabel           matlab.ui.control.Label
        MaxFRulminEditField_1       matlab.ui.control.NumericEditField
        MaxFRulminEditFieldLabel    matlab.ui.control.Label
        MinFRulminEditField_1       matlab.ui.control.NumericEditField
        MinFRulminEditFieldLabel    matlab.ui.control.Label
        DiammmEditField_1           matlab.ui.control.NumericEditField
        DiammmEditFieldLabel        matlab.ui.control.Label
        MoveToButton_1              matlab.ui.control.Button
        UpdateButton_1              matlab.ui.control.Button
        EditField_1                 matlab.ui.control.EditField
        AutoCalibrationButton_1     matlab.ui.control.Button
        MaxVulEditField_1           matlab.ui.control.NumericEditField
        MaxVulEditFieldLabel        matlab.ui.control.Label
        PanelC4VM_1                 matlab.ui.container.Panel
        nochange1A2closed3BLabel_1  matlab.ui.control.Label
        SwitchButton_1              matlab.ui.control.Button
        Valve1EditField_1           matlab.ui.control.NumericEditField
        Valve1EditFieldLabel        matlab.ui.control.Label
        Valve4EditField_1           matlab.ui.control.NumericEditField
        Valve4EditFieldLabel        matlab.ui.control.Label
        Valve3EditField_1           matlab.ui.control.NumericEditField
        Valve3EditFieldLabel        matlab.ui.control.Label
        Valve2EditField_1           matlab.ui.control.NumericEditField
        Valve2EditFieldLabel        matlab.ui.control.Label
        LabsmithInterfaceLabel      matlab.ui.control.Label
    end

    
    properties (Access = public)
        eib % Labsmith board OBJECT
        dev_list % String with all the devices
        m % Manifold C4VM Cell
        s % Syringe SPS01 Cell
        TotNumDev % num devices connected
        MaxNumDev = 8; % max num devices on the board
        panels      
        stop_status % it is 1 when you press the STOP Button
        stop_function
    end
    
    methods (Access = private)
        
        function results = EditPanel(app,add,dev)
         
            %find index of first available panel
            for j=1:app.MaxNumDev
                pstr=app.panels{j};
                nstr=findobj(pstr,'Tag','name');
                if isempty(nstr.Value)
                    index=j;
                    break
                end
            end
            
            %assign value to each panel
            for i=1:length(add)
                pstr=app.panels{index};                
                nstr=findobj(pstr,'Tag','name');
                cstr=findobj(pstr,'Tag','class');
                astr=findobj(pstr,'Tag','address');                    
                mstr=findobj(pstr,'Tag','mpanel');
                sstr=findobj(pstr,'Tag','spanel');
                dstr=findobj(sstr,'Tag','diam');
                mxstr=findobj(sstr,'Tag','maxFR');
                mnstr=findobj(sstr,'Tag','minFR');
                cvstr=findobj(sstr,'Tag','currentV');
               
                nstr.Value=dev{2,i};
                cstr.Value=dev{3,i};
                astr.Value=add(i);
                               
                if dev{3,i} == 'C4VM'
                    mstr.Visible='on';
                elseif dev{3,i} == 'SPS01'
                    sstr.Visible='on';
                    dstr.Value=dev{4,i};
                    mxstr.Value=dev{5,i};
                    mnstr.Value=dev{6,i};                  
                    cvstr.Value=app.s{1,i}.CmdGetVolume();
                end
                
                index=index+1;
            end            
            results=0;
        end
        
        function results = DeletePanel(app)
            for i=(app.TotNumDev+1):app.MaxNumDev
                    pstr=app.panels{i};
                    pstr.Visible="off";                
            end
            results=0;
        end
        
        function [index_s,index_m] = RecogniceDevice(app,index_panel)
            pstr=app.panels{index_panel};                
            nstr=findobj(pstr,'Tag','name');
            devicelabel=nstr.Value;
            index_s=[];
            index_m=[];
            for i=1:width(app.s)  
                if strcmp(devicelabel,app.s{2,i}) % (=1). In app.s{2,:} there are saved the names of all the syringes
                    index_s=i; % is empty if the device is a manifold
                    break
                end
            end
            for i=1:width(app.m)  
                if strcmp(devicelabel,app.m{2,i}) % (=1). In app.m{2,:} there are saved the names of all the manifolds
                    index_m=i; %is empty is the device is a syringe
                    break
                end
            end            
        end
        
        function results = Flash(app,index_s,index_m,lamp)
            if ~isempty(index_s)
                app.s{1,index_s}.CmdShowDevice(true);
                app.(lamp).Color='g';
                pause(2)
                app.s{1,index_s}.CmdShowDevice(false);
                app.(lamp).Color=[0.80,0.80,0.80];
                
            elseif ~isempty(index_m)
                app.m{1,index_m}.CmdShowDevice(true);
                app.(lamp).Color='g';
                pause(2)
                app.m{1,index_m}.CmdShowDevice(false);
                app.(lamp).Color=[0.80,0.80,0.80];
            end
            results=0;            
        end
        
        function [man,add_man,syr,add_syr] = Load(app)
            expression = '\,'; %i first split the string into multiple strings
            splitStr = regexp(app.dev_list,expression,'split');
            NumDev=size(splitStr);
            app.TotNumDev=NumDev(1,2);
            
            PAT_S=" <uProcess.CSyringe>";
            PAT_M=" <uProcess.C4VM>";
            PAT_S1="[<uProcess.CSyringe>";
            PAT_M1="[<uProcess.C4VM>";
            
            StrSyringe=[];
            StrManifold=[];
            
            TF_S = startsWith(splitStr{1,1},PAT_S1);
            TF_M = startsWith(splitStr{1,1},PAT_M1);
            if TF_S == 1
                StrSyringe=[StrSyringe splitStr{1,1}];
            elseif TF_M == 1
                StrManifold=[StrManifold splitStr{1,1}];
            end
            
            for i=2:app.TotNumDev
                TF_S = startsWith(splitStr{1,i},PAT_S);
                TF_M = startsWith(splitStr{1,i},PAT_M);
                if TF_S == 1
                    StrSyringe=[StrSyringe splitStr{1,i}];
                elseif TF_M == 1
                    StrManifold=[StrManifold splitStr{1,i}];
                end 
                
            end
            
            PAT="address " + digitsPattern;
            add_man=extract(StrManifold,PAT);
            PAT=digitsPattern;
            add_man=str2double(extract(add_man,PAT));
            
            PAT="address " + digitsPattern;
            add_syr=extract(StrSyringe,PAT);
            PAT=digitsPattern;
            add_syr=str2double(extract(add_syr,PAT));
            
            man=cell(3,length(add_man));
            syr=cell(6,length(add_syr));
            
            for i=1:length(add_man)
                man{1,i} = app.eib.New4VM(int8(add_man(i)));
                man{2,i} = char(man{1,i}.GetName);
                man{3,i} = "C4VM";
            end
            
            for i=1:length(add_syr)
                syr{1,i} = app.eib.NewSPS01(int8(add_syr(i)));
                syr{2,i} = char(syr{1,i}.GetName);
                syr{3,i} = "SPS01";
                syr{4,i} = syr{1,i}.CmdGetDiameter();
                syr{5,i} = syr{1,i}.GetMaxFlowrate();
                syr{6,i} = syr{1,i}.GetMinFlowrate();
            end
            
        end
        
        function results = ChangeName(app,index_s,index_m,index_panel)
            
             pstr=app.panels{index_panel};                
             nwstr=findobj(pstr,'Tag','nwname');
             label=nwstr.Value;
             
             %Check first that the new name is not empty and that the is
             %not equal to any other devices'names
             
             check=0;
             
             if ~isempty(label)
                 for i=1:app.TotNumDev
                     pstr=app.panels{i};             
                     nstr=findobj(pstr,'Tag','name');
                     if strcmp(label,nstr.Value)
                        check=1;                     
                     end                     
                 end  
             else
                 check=1;
             end
             
             %Change name to the right device
             if ~check
                 if ~isempty(index_s)
                     app.s{1,index_s}.CmdSetName(label);
                     app.s{2,index_s}=char(app.s{1,index_s}.GetName);
                 elseif ~isempty(index_m)
                     app.m{1,index_m}.CmdSetName(label);
                     app.m{2,index_m}=char(app.m{1,index_m}.GetName);      
                 end
                 pstr=app.panels{index_panel};
                 nstr=findobj(pstr,'Tag','name');
                 nstr.Value=label;
                 nwstr.Value='';        
             end
             
             results=0; 
                        
        end
        
        function results = ManifoldSwitch(app,index_m,index_panel)
             pstr=app.panels{index_panel};
             v1str=findobj(pstr,'Tag','valve1');
             v2str=findobj(pstr,'Tag','valve2');
             v3str=findobj(pstr,'Tag','valve3');
             v4str=findobj(pstr,'Tag','valve4');
             
             if ~isempty(index_m)
                app.m{1,index_m}.CmdSetValves(int8(v1str.Value),int8(v2str.Value),int8(v3str.Value),int8(v4str.Value));                
             end 
             results=0;
        end
        
        function results = UpdateCurrentVolume(app,index_s,index_panel)
            pstr=app.panels{index_panel};
            vcstr=findobj(pstr,'Tag','currentV');
            if ~isempty(index_s)
                vcstr.Value=app.s{1,index_s}.CmdGetVolume();                
             end 
             results=0;            
        end
    
        function results = AutoCalibration(app,index_s,index_panel)
            pstr=app.panels{index_panel};
            dstr=findobj(pstr,'Tag','diam');
            vmstr=findobj(pstr,'Tag','maxV');
            cmstr=findobj(pstr,'Tag','comment');         
            if ~isempty(index_s)
                app.s{1,index_s}.CmdAutoCal();
                vmstr.Value=app.s{1,index_s}.GetMaxVolume();
                if dstr.Value == 1.458 %small
                    Vmaxnom=20;
                elseif dstr.Value > 3 %big (== 3.256 gives error!)
                    Vmaxnom=80;
                end
                if vmstr.Value>(Vmaxnom-1) && vmstr.Value<(Vmaxnom+1)
                   cmstr.Value="Calibrated";
                   cmstr.FontColor=[0.33,0.5,0.11];                   
                else
                   cmstr.Value="Out of Range";
                   cmstr.FontColor=[0.64,0.08,0.18];
                end
            end 
             results=0;
        end
        
        function results = MoveToVolume(app,index_s,index_panel)
            app.stop_status=0;
            pstr=app.panels{index_panel};
            vfstr=findobj(pstr,'Tag','finalV'); 
            vcstr=findobj(pstr,'Tag','currentV');
            frstr=findobj(pstr,'Tag','FlowRate');
            mnstr=findobj(pstr,'Tag','minFR');
            mxstr=findobj(pstr,'Tag','maxFR');
            vmstr=findobj(pstr,'Tag','maxV');
            
            if ~isempty(index_s)
                if frstr.Value>mnstr.Value && frstr.Value<mxstr.Value
                    app.s{1,index_s}.CmdSetFlowrate(double(frstr.Value));
                    if vfstr.Value < vmstr.Value
                        app.s{1,index_s}.CmdMoveToVolume(double(vfstr.Value));
                        scan_rate=0.1; %the scan rate of the counter
                        target=((abs(vfstr.Value-vcstr.Value))/(frstr.Value))*60/scan_rate; %this is the final time of the counter. It is calculated based on the flow rate and the volume to pull/push
                        for i=1:target %this is a counter clock to check if the stop_status variable has changed
                            if app.stop_status==1
                                app.s{1,index_s}.CmdStop();
                                break
                            end
                            pause(scan_rate)
                        end
%                         r = UpdateCurrentVolume(app,index_s,index_panel);
                    end
                end             
             end 
             results=0;             
        end
        
        function results = UpdateVolumeSyringeIsDone(app,index_s,index_panel)
            app.s{1,index_s}.CmdGetStatus();
            syr_status = app.s{1,index_s}.IsDone();            
            while syr_status == false
              app.s{1,index_s}.CmdGetStatus();
              syr_status = app.s{1,index_s}.IsDone();
            end
            r =UpdateCurrentVolume(app,index_s,index_panel);
            results=0;
        end


        function results = RunCode(app,scriptname)
            app.stop_function=0;
            run(scriptname)
            scan_rate=0.1; %the scan rate of the counter
            target=2*60/scan_rate;
            for i=1:target
                if app.stop_function==1
%                     for index_m=1:size(app.m,2) %numb of manifolds
%                         app.m{1,index_m}.CmdStop();
%                     end
%                     for index_s=1:size(app.s,2) %numb of syringes
%                         app.s{1,index_s}.CmdStop();
%                     end
                    results=3
                    return
                end
            end
            results=0
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            p1=findobj(gcf,'Tag','panel1');
            p2=findobj(gcf,'Tag','panel2');
            p3=findobj(gcf,'Tag','panel3');
            p4=findobj(gcf,'Tag','panel4');
            p5=findobj(gcf,'Tag','panel5');
            p6=findobj(gcf,'Tag','panel6');
            p7=findobj(gcf,'Tag','panel7');
            p8=findobj(gcf,'Tag','panel8');
            app.panels={p1;p2;p3;p4;p5;p6;p7;p8};            
        end

        % Button pushed function: ConnectButton
        function ConnectButtonPushed(app, event)
            mod = py.importlib.import_module('uProcess_x64');
            app.eib=py.uProcess_x64.CEIB();             
            open_status=int64(app.eib.InitConnection(int8(app.COMPORTEditField.Value)));
            app.dev_list = char(app.eib.CmdCreateDeviceList());  
            
                            
            if ~open_status % python returns 0 if encounters no errors
               %initialise the app 
                for i=1:app.MaxNumDev                    
                    
                    pstr=app.panels{i};
                    nstr=findobj(pstr,'Tag','name');
                    cstr=findobj(pstr,'Tag','class');
                    astr=findobj(pstr,'Tag','address');                    
                    mstr=findobj(pstr,'Tag','mpanel');
                    sstr=findobj(pstr,'Tag','spanel');
                    
                    dstr=findobj(sstr,'Tag','diam');
                    mxstr=findobj(sstr,'Tag','maxFR');
                    mnstr=findobj(sstr,'Tag','minFR');
                    cvstr=findobj(sstr,'Tag','currentV');
                    v1str=findobj(mstr,'Tag','valve1');
                    v2str=findobj(mstr,'Tag','valve2');
                    v3str=findobj(mstr,'Tag','valve3');
                    v4str=findobj(mstr,'Tag','valve4');
                    
                    pstr.Visible="on"; 
                    nstr.Value='';
                    cstr.Value='';
                    astr.Value=0;
                    mstr.Visible='off';
                    sstr.Visible='off';
                    v1str.Value=0;
                    v2str.Value=0;
                    v3str.Value=0;
                    v4str.Value=0;
                    dstr.Value=0;
                    mxstr.Value=0;
                    mnstr.Value=0;                  
                    cvstr.Value=0; 
                    
                    
                end
                app.ConnectButton.BackgroundColor='g';
                app.ConnectButton.Text='Connected';  
                app.DisconnectButton.BackgroundColor='w';
                app.DisconnectButton.Text='Disconnect';                
            end
                        
            
        end

        % Button pushed function: DisconnectButton
        function DisconnectButtonPushed(app, event)
            close_status=int64(app.eib.CloseConnection());
            
            if ~close_status % python returns 0 if encounters no errors              
                app.ConnectButton.BackgroundColor='w';
                app.ConnectButton.Text='Connect'; 
                app.LoadBoardButton.BackgroundColor='w';
                app.LoadBoardButton.Text='Load Board';
                app.DisconnectButton.BackgroundColor='r';
                app.DisconnectButton.Text='Disconnected';
            end
            
        end

        % Button pushed function: LoadBoardButton
        function LoadBoardButtonPushed(app, event)
                                 
            [app.m,address_M,app.s,address_S] = Load(app);
           
            r = EditPanel(app,address_M,app.m);
            r = EditPanel(app,address_S,app.s);  
            r = DeletePanel(app); 
            
            app.LoadBoardButton.BackgroundColor='g';
            app.LoadBoardButton.Text='Board loaded';
                        
        end

        % Button pushed function: FlashButton_1
        function FlashButton_1Pushed(app, event)
            [index_s,index_m] = RecogniceDevice(app,1);
            r = Flash(app,index_s,index_m,"Lamp_1");
        end

        % Button pushed function: FlashButton_2
        function FlashButton_2Pushed(app, event)
            [index_s,index_m] = RecogniceDevice(app,2);
            r = Flash(app,index_s,index_m,"Lamp_2");
        end

        % Button pushed function: FlashButton_3
        function FlashButton_3Pushed(app, event)
            [index_s,index_m] = RecogniceDevice(app,3);
            r = Flash(app,index_s,index_m,"Lamp_3");
        end

        % Button pushed function: FlashButton_4
        function FlashButton_4Pushed(app, event)
            [index_s,index_m] = RecogniceDevice(app,4);
            r = Flash(app,index_s,index_m,"Lamp_4");
        end

        % Button pushed function: FlashButton_5
        function FlashButton_5Pushed(app, event)
            [index_s,index_m] = RecogniceDevice(app,5);
            r = Flash(app,index_s,index_m,"Lamp_5");
        end

        % Button pushed function: FlashButton_6
        function FlashButton_6Pushed(app, event)
            [index_s,index_m] = RecogniceDevice(app,6);
            r = Flash(app,index_s,index_m,"Lamp_6");
        end

        % Button pushed function: FlashButton_7
        function FlashButton_7Pushed(app, event)
            [index_s,index_m] = RecogniceDevice(app,7);
            r = Flash(app,index_s,index_m,"Lamp_7");
        end

        % Button pushed function: FlashButton_8
        function FlashButton_8Pushed(app, event)
            [index_s,index_m] = RecogniceDevice(app,8);
            r = Flash(app,index_s,index_m,"Lamp_8");
        end

        % Button pushed function: ChangeButton
        function ChangeButtonPushed(app, event)
            [index_s,index_m] = RecogniceDevice(app,1);
            r= ChangeName(app,index_s,index_m,1);
        end

        % Button pushed function: ChangeButton_2
        function ChangeButton_2Pushed(app, event)
           [index_s,index_m] = RecogniceDevice(app,2);
            r= ChangeName(app,index_s,index_m,2); 
        end

        % Button pushed function: ChangeButton_3
        function ChangeButton_3Pushed(app, event)
            [index_s,index_m] = RecogniceDevice(app,3);
            r= ChangeName(app,index_s,index_m,3);
        end

        % Button pushed function: ChangeButton_4
        function ChangeButton_4Pushed(app, event)
            [index_s,index_m] = RecogniceDevice(app,4);
            r= ChangeName(app,index_s,index_m,4);
        end

        % Button pushed function: ChangeButton_5
        function ChangeButton_5Pushed(app, event)
            [index_s,index_m] = RecogniceDevice(app,5);
            r= ChangeName(app,index_s,index_m,5);
        end

        % Button pushed function: ChangeButton_6
        function ChangeButton_6Pushed(app, event)
            [index_s,index_m] = RecogniceDevice(app,6);
            r= ChangeName(app,index_s,index_m,6);
        end

        % Button pushed function: ChangeButton_7
        function ChangeButton_7Pushed(app, event)
            [index_s,index_m] = RecogniceDevice(app,7);
            r= ChangeName(app,index_s,index_m,7);
        end

        % Button pushed function: ChangeButton_8
        function ChangeButton_8Pushed(app, event)
            [index_s,index_m] = RecogniceDevice(app,8);
            r= ChangeName(app,index_s,index_m,8);
        end

        % Button pushed function: SwitchButton_1
        function SwitchButton_1Pushed(app, event)
            [~,index_m] = RecogniceDevice(app,1);
            r = ManifoldSwitch(app,index_m,1);
        end

        % Button pushed function: SwitchButton_2
        function SwitchButton_2Pushed(app, event)
            [~,index_m] = RecogniceDevice(app,2);
            r = ManifoldSwitch(app,index_m,2);
        end

        % Button pushed function: SwitchButton_3
        function SwitchButton_3Pushed(app, event)
            [~,index_m] = RecogniceDevice(app,3);
            r = ManifoldSwitch(app,index_m,3);
        end

        % Button pushed function: SwitchButton_4
        function SwitchButton_4Pushed(app, event)
            [~,index_m] = RecogniceDevice(app,4);
            r = ManifoldSwitch(app,index_m,4);
        end

        % Button pushed function: SwitchButton_5
        function SwitchButton_5Pushed(app, event)
            [~,index_m] = RecogniceDevice(app,5);
            r = ManifoldSwitch(app,index_m,5);
        end

        % Button pushed function: SwitchButton_6
        function SwitchButton_6Pushed(app, event)
            [~,index_m] = RecogniceDevice(app,6);
            r = ManifoldSwitch(app,index_m,6);
        end

        % Button pushed function: SwitchButton_7
        function SwitchButton_7Pushed(app, event)
            [~,index_m] = RecogniceDevice(app,7);
            r = ManifoldSwitch(app,index_m,7);
        end

        % Button pushed function: SwitchButton_8
        function SwitchButton_8Pushed(app, event)
            [~,index_m] = RecogniceDevice(app,8);
            r = ManifoldSwitch(app,index_m,8);
        end

        % Button pushed function: UpdateButton_1
        function UpdateButton_1Pushed(app, event)
            [index_s,~] = RecogniceDevice(app,1);
            r=UpdateCurrentVolume(app,index_s,1);
        end

        % Button pushed function: UpdateButton_2
        function UpdateButton_2Pushed(app, event)
            [index_s,~] = RecogniceDevice(app,2);
            r=UpdateCurrentVolume(app,index_s,2);
        end

        % Button pushed function: UpdateButton_3
        function UpdateButton_3Pushed(app, event)
            [index_s,~] = RecogniceDevice(app,3);
            r=UpdateCurrentVolume(app,index_s,3);
        end

        % Button pushed function: UpdateButton_4
        function UpdateButton_4Pushed(app, event)
            [index_s,~] = RecogniceDevice(app,4);
            r=UpdateCurrentVolume(app,index_s,4);
        end

        % Button pushed function: UpdateButton_5
        function UpdateButton_5Pushed(app, event)
            [index_s,~] = RecogniceDevice(app,5);
            r=UpdateCurrentVolume(app,index_s,5);
        end

        % Button pushed function: UpdateButton_6
        function UpdateButton_6Pushed(app, event)
            [index_s,~] = RecogniceDevice(app,6);
            r=UpdateCurrentVolume(app,index_s,6);
        end

        % Button pushed function: UpdateButton_7
        function UpdateButton_7Pushed(app, event)
            [index_s,~] = RecogniceDevice(app,7);
            r=UpdateCurrentVolume(app,index_s,7);
        end

        % Button pushed function: UpdateButton_8
        function UpdateButton_8Pushed(app, event)
            [index_s,~] = RecogniceDevice(app,8);
            r=UpdateCurrentVolume(app,index_s,8);
        end

        % Button pushed function: AutoCalibrationButton_1
        function AutoCalibrationButton_1Pushed(app, event)
            [index_s,~] = RecogniceDevice(app,1);
            r= AutoCalibration(app,index_s,1);
            r = UpdateVolumeSyringeIsDone(app,index_s,1);
        end

        % Button pushed function: AutoCalibrationButton_2
        function AutoCalibrationButton_2Pushed(app, event)
            [index_s,~] = RecogniceDevice(app,2);
            r= AutoCalibration(app,index_s,2);
            r = UpdateVolumeSyringeIsDone(app,index_s,2);
        end

        % Button pushed function: AutoCalibrationButton_3
        function AutoCalibrationButton_3Pushed(app, event)
            [index_s,~] = RecogniceDevice(app,3);
            r= AutoCalibration(app,index_s,3);
            r = UpdateVolumeSyringeIsDone(app,index_s,3);
        end

        % Button pushed function: AutoCalibrationButton_4
        function AutoCalibrationButton_4Pushed(app, event)
            [index_s,~] = RecogniceDevice(app,4);
            r= AutoCalibration(app,index_s,4);
            r = UpdateVolumeSyringeIsDone(app,index_s,4);
        end

        % Button pushed function: AutoCalibrationButton_5
        function AutoCalibrationButton_5Pushed(app, event)
            [index_s,~] = RecogniceDevice(app,5);
            r= AutoCalibration(app,index_s,5);
            r = UpdateVolumeSyringeIsDone(app,index_s,5);
        end

        % Button pushed function: AutoCalibrationButton_6
        function AutoCalibrationButton_6Pushed(app, event)
            [index_s,~] = RecogniceDevice(app,6);
            r= AutoCalibration(app,index_s,6);
            r = UpdateVolumeSyringeIsDone(app,index_s,6);
        end

        % Button pushed function: AutoCalibrationButton_7
        function AutoCalibrationButton_7Pushed(app, event)
            [index_s,~] = RecogniceDevice(app,7);
            r= AutoCalibration(app,index_s,7);
            r = UpdateVolumeSyringeIsDone(app,index_s,7);
        end

        % Button pushed function: AutoCalibrationButton_8
        function AutoCalibrationButton_8Pushed(app, event)
            [index_s,~] = RecogniceDevice(app,8);
            r= AutoCalibration(app,index_s,8);
            r = UpdateVolumeSyringeIsDone(app,index_s,8);
        end

        % Button pushed function: MoveToButton_1
        function MoveToButton_1Pushed(app, event)
            [index_s,~] = RecogniceDevice(app,1);
            r = MoveToVolume(app,index_s,1);
            r = UpdateVolumeSyringeIsDone(app,index_s,1);
        end

        % Button pushed function: MoveToButton_2
        function MoveToButton_2Pushed(app, event)
            [index_s,~] = RecogniceDevice(app,2);
            r = MoveToVolume(app,index_s,2);
            r = UpdateVolumeSyringeIsDone(app,index_s,2);
        end

        % Button pushed function: MoveToButton_3
        function MoveToButton_3Pushed(app, event)
            [index_s,~] = RecogniceDevice(app,3);
            r = MoveToVolume(app,index_s,3);
            r = UpdateVolumeSyringeIsDone(app,index_s,3);
        end

        % Button pushed function: MoveToButton_4
        function MoveToButton_4Pushed(app, event)
            [index_s,~] = RecogniceDevice(app,4);
            r = MoveToVolume(app,index_s,4);
            r = UpdateVolumeSyringeIsDone(app,index_s,4);
        end

        % Button pushed function: MoveToButton_5
        function MoveToButton_5Pushed(app, event)
            [index_s,~] = RecogniceDevice(app,5);
            r = MoveToVolume(app,index_s,5);
            r = UpdateVolumeSyringeIsDone(app,index_s,5);
        end

        % Button pushed function: MoveToButton_6
        function MoveToButton_6Pushed(app, event)
            [index_s,~] = RecogniceDevice(app,6);
            r = MoveToVolume(app,index_s,6);
            r = UpdateVolumeSyringeIsDone(app,index_s,6);
        end

        % Button pushed function: MoveToButton_7
        function MoveToButton_7Pushed(app, event)
            [index_s,~] = RecogniceDevice(app,7);
            r = MoveToVolume(app,index_s,7);
            r = UpdateVolumeSyringeIsDone(app,index_s,7);
        end

        % Button pushed function: MoveToButton_8
        function MoveToButton_8Pushed(app, event)
            [index_s,~] = RecogniceDevice(app,8);
            r = MoveToVolume(app,index_s,8);
            r = UpdateVolumeSyringeIsDone(app,index_s,8);
        end

        % Button pushed function: StopButton_1
        function StopButton_1Pushed(app, event)
            app.stop_status=1;
        end

        % Button pushed function: StopButton_2
        function StopButton_2Pushed(app, event)
            app.stop_status=1;
        end

        % Button pushed function: StopButton_3
        function StopButton_3Pushed(app, event)
            app.stop_status=1;
        end

        % Button pushed function: StopButton_4
        function StopButton_4Pushed(app, event)
            app.stop_status=1;
        end

        % Button pushed function: StopButton_5
        function StopButton_5Pushed(app, event)
            app.stop_status=1;
        end

        % Button pushed function: StopButton_6
        function StopButton_6Pushed(app, event)
            app.stop_status=1;
        end

        % Button pushed function: StopButton_7
        function StopButton_7Pushed(app, event)
            app.stop_status=1;
        end

        % Button pushed function: StopButton_8
        function StopButton_8Pushed(app, event)
            app.stop_status=1;
        end

        % Button pushed function: StopButton_SyringeControl
        function StopButton_SyringeControlPushed(app, event)
            app.stop_status=1;
        end

        % Button pushed function: FullAutocalibrationButton
        function FullAutocalibrationButtonPushed(app, event)
            All_classes=cell(1,app.TotNumDev);
            for j=1:app.TotNumDev
                pstr=app.panels{j};      
                cstr=findobj(pstr,'Tag','class');
                All_classes{j}=cstr.Value;
            end
            k=strfind(All_classes,'SPS01');
            for j=1:app.TotNumDev
                if k{j}==1
                    [index_s,~] = RecogniceDevice(app,j);     
                    r = AutoCalibration(app,index_s,j);
                    r = UpdateVolumeSyringeIsDone(app,index_s,j);
                end
            end 
            app.FullAutocalibrationButton.Text= 'Fully Autocalibrated';
            app.FullAutocalibrationButton.BackgroundColor='g';
        end

        % Button pushed function: SwitchValvesButton
        function SwitchValvesButtonPushed(app, event)
            valves_switches=[1 2 3 2];
            
            app.stop_status=0;
            
            flag=0;
                        
            for i=1:app.IterationEditField.Value  
                for k=1:4
                    pos=valves_switches(k);
                    if app.stop_status==0
                        for index_man=1:size(app.m,2)
                            app.m{1,index_man}.CmdSetValves(int8(pos),int8(pos),int8(pos),int8(pos));
                        end
                    else 
                        for index_man=1:size(app.m,2)
                            app.m{1,index_man}.CmdStop();
                        end
                        flag=1;
                        break
                    end
                    pause(1)
                end
                if flag==1
                    break
                end
            end
        end

        % Button pushed function: StopButton_ValveCheck
        function StopButton_ValveCheckPushed(app, event)
            app.stop_status=1;
        end

        % Callback function
        function LOADButtonPushed(app, event)
            app.EditField_9.Value
        end

        % Button pushed function: SearchButton
        function SearchButtonPushed(app, event)
            dname = uigetdir('C:\','Select Script Folder');
            dir_struct= dir ('**/*.m');
%             dir_struct= dir ('**/*.txt');
            [sorted_names,~] = sortrows({dir_struct.name}');
            app.FileDropDown.Items=sorted_names;
        end

        % Button pushed function: ShowButton
        function ShowButtonPushed(app, event)
            open(app.FileDropDown.Value)
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
%             app.stop_function=0;
%             run(app.FileDropDown.Value)
            r = RunCode(app,app.FileDropDown.Value);
        end

        % Button pushed function: StopButton_Script
        function StopButton_ScriptPushed(app, event)
            app.stop_function=1;
            a=146
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.LabsmithInterfaceLabel = uilabel(app.UIFigure);
            app.LabsmithInterfaceLabel.Text = 'Labsmith Interface';
            app.Panel_1 = uipanel(app.UIFigure);
            app.Panel_1.Tag = 'panel1';
            app.PanelC4VM_1 = uipanel(app.Panel_1);
            app.PanelC4VM_1.Visible = 'off';
            app.PanelC4VM_1.Tag = 'mpanel';
            app.Valve2EditFieldLabel = uilabel(app.PanelC4VM_1);
            app.Valve2EditFieldLabel.Text = 'Valve 2';
            app.Valve2EditField_1 = uieditfield(app.PanelC4VM_1, 'numeric');
            app.Valve2EditField_1.Tag = 'valve2';
            app.Valve3EditFieldLabel = uilabel(app.PanelC4VM_1);
            app.Valve3EditFieldLabel.Text = 'Valve 3';
            app.Valve3EditField_1 = uieditfield(app.PanelC4VM_1, 'numeric');
            app.Valve3EditField_1.Tag = 'valve3';
            app.Valve4EditFieldLabel = uilabel(app.PanelC4VM_1);
            app.Valve4EditFieldLabel.Text = 'Valve 4';
            app.Valve4EditField_1 = uieditfield(app.PanelC4VM_1, 'numeric');
            app.Valve4EditField_1.Tag = 'valve4';
            app.Valve1EditFieldLabel = uilabel(app.PanelC4VM_1);
            app.Valve1EditFieldLabel.Text = 'Valve 1';
            app.Valve1EditField_1 = uieditfield(app.PanelC4VM_1, 'numeric');
            app.Valve1EditField_1.Tag = 'valve1';
            app.SwitchButton_1 = uibutton(app.PanelC4VM_1, 'push');
            app.SwitchButton_1.ButtonPushedFcn = createCallbackFcn(app, @SwitchButton_1Pushed, true);
            app.SwitchButton_1.Tag = 'switch1';
            app.SwitchButton_1.Text = 'Switch';
            app.nochange1A2closed3BLabel_1 = uilabel(app.PanelC4VM_1);
            app.nochange1A2closed3BLabel_1.Text = {'0 - no change'; '1 - A'; '2 - closed'; '3 - B'};
            app.PanelSPS01_1 = uipanel(app.Panel_1);
            app.PanelSPS01_1.Visible = 'off';
            app.PanelSPS01_1.Tag = 'spanel';
            app.MaxVulEditFieldLabel = uilabel(app.PanelSPS01_1);
            app.MaxVulEditFieldLabel.Text = 'MaxV (ul)';
            app.MaxVulEditField_1 = uieditfield(app.PanelSPS01_1, 'numeric');
            app.MaxVulEditField_1.Tag = 'maxV';
            app.MaxVulEditField_1.Editable = 'off';
            app.AutoCalibrationButton_1 = uibutton(app.PanelSPS01_1, 'push');
            app.AutoCalibrationButton_1.ButtonPushedFcn = createCallbackFcn(app, @AutoCalibrationButton_1Pushed, true);
            app.AutoCalibrationButton_1.Text = 'Auto Calibration';
            app.EditField_1 = uieditfield(app.PanelSPS01_1, 'text');
            app.EditField_1.Tag = 'comment';
            app.EditField_1.Editable = 'off';
            app.UpdateButton_1 = uibutton(app.PanelSPS01_1, 'push');
            app.UpdateButton_1.ButtonPushedFcn = createCallbackFcn(app, @UpdateButton_1Pushed, true);
            app.UpdateButton_1.Text = 'Update';
            app.MoveToButton_1 = uibutton(app.PanelSPS01_1, 'push');
            app.MoveToButton_1.ButtonPushedFcn = createCallbackFcn(app, @MoveToButton_1Pushed, true);
            app.MoveToButton_1.Text = 'MoveTo';
            app.DiammmEditFieldLabel = uilabel(app.PanelSPS01_1);
            app.DiammmEditFieldLabel.Text = 'Diam (mm)';
            app.DiammmEditField_1 = uieditfield(app.PanelSPS01_1, 'numeric');
            app.DiammmEditField_1.Tag = 'diam';
            app.DiammmEditField_1.Editable = 'off';
            app.MinFRulminEditFieldLabel = uilabel(app.PanelSPS01_1);
            app.MinFRulminEditFieldLabel.Text = 'MinFR (ul/min)';
            app.MinFRulminEditField_1 = uieditfield(app.PanelSPS01_1, 'numeric');
            app.MinFRulminEditField_1.Tag = 'minFR';
            app.MinFRulminEditField_1.Editable = 'off';
            app.MaxFRulminEditFieldLabel = uilabel(app.PanelSPS01_1);
            app.MaxFRulminEditFieldLabel.Text = 'MaxFR (ul/min)';
            app.MaxFRulminEditField_1 = uieditfield(app.PanelSPS01_1, 'numeric');
            app.MaxFRulminEditField_1.Tag = 'maxFR';
            app.MaxFRulminEditField_1.Editable = 'off';
            app.VmmEditFieldLabel = uilabel(app.PanelSPS01_1);
            app.VmmEditFieldLabel.Text = 'V (mm)';
            app.VmmEditField_1 = uieditfield(app.PanelSPS01_1, 'numeric');
            app.VmmEditField_1.Tag = 'currentV';
            app.VmmEditField_1.Editable = 'off';
            app.VfmmEditFieldLabel = uilabel(app.PanelSPS01_1);
            app.VfmmEditFieldLabel.Text = 'Vf (mm)';
            app.VfmmEditField_1 = uieditfield(app.PanelSPS01_1, 'numeric');
            app.VfmmEditField_1.Tag = 'finalV';
            app.FRulminEditField_2Label = uilabel(app.PanelSPS01_1);
            app.FRulminEditField_2Label.Text = 'FR(ul/min)';
            app.FRulminEditField_1 = uieditfield(app.PanelSPS01_1, 'numeric');
            app.FRulminEditField_1.Tag = 'FlowRate';
            app.Lamp_1 = uilamp(app.Panel_1);
            app.Lamp_1.Tag = 'lamp1';
            app.AddrEditFieldLabel = uilabel(app.Panel_1);
            app.AddrEditFieldLabel.Text = 'Addr';
            app.AddrEditField_1 = uieditfield(app.Panel_1, 'numeric');
            app.AddrEditField_1.Tag = 'address';
            app.AddrEditField_1.Editable = 'off';
            app.ClassEditFieldLabel = uilabel(app.Panel_1);
            app.ClassEditFieldLabel.Text = 'Class';
            app.ClassEditField_1 = uieditfield(app.Panel_1, 'text');
            app.ClassEditField_1.Tag = 'class';
            app.ClassEditField_1.Editable = 'off';
            app.FlashButton_1 = uibutton(app.Panel_1, 'push');
            app.FlashButton_1.ButtonPushedFcn = createCallbackFcn(app, @FlashButton_1Pushed, true);
            app.FlashButton_1.Text = 'Flash';
            app.NewNameLabel = uilabel(app.Panel_1);
            app.NewNameLabel.Text = 'New Name:';
            app.NewNameEditField_1 = uieditfield(app.Panel_1, 'text');
            app.NewNameEditField_1.Tag = 'nwname';
            app.ChangeButton = uibutton(app.Panel_1, 'push');
            app.ChangeButton.ButtonPushedFcn = createCallbackFcn(app, @ChangeButtonPushed, true);
            app.ChangeButton.Text = 'Change';
            app.NameEditField_1 = uieditfield(app.Panel_1, 'text');
            app.NameEditField_1.Tag = 'name';
            app.StopButton_1 = uibutton(app.Panel_1, 'push');
            app.StopButton_1.ButtonPushedFcn = createCallbackFcn(app, @StopButton_1Pushed, true);
            app.StopButton_1.Text = 'Stop';
            app.Panel_2 = uipanel(app.UIFigure);
            app.Panel_2.Tag = 'panel2';
            app.PanelSPS01_2 = uipanel(app.Panel_2);
            app.PanelSPS01_2.Visible = 'off';
            app.PanelSPS01_2.Tag = 'spanel';
            app.MinFRulminEditField_2Label = uilabel(app.PanelSPS01_2);
            app.MinFRulminEditField_2Label.Text = 'MinFR (ul/min)';
            app.MinFRulminEditField_2 = uieditfield(app.PanelSPS01_2, 'numeric');
            app.MinFRulminEditField_2.Tag = 'minFR';
            app.MinFRulminEditField_2.Editable = 'off';
            app.MaxFRulminEditField_2Label = uilabel(app.PanelSPS01_2);
            app.MaxFRulminEditField_2Label.Text = 'MaxFR (ul/min)';
            app.MaxFRulminEditField_2 = uieditfield(app.PanelSPS01_2, 'numeric');
            app.MaxFRulminEditField_2.Tag = 'maxFR';
            app.MaxFRulminEditField_2.Editable = 'off';
            app.UpdateButton_2 = uibutton(app.PanelSPS01_2, 'push');
            app.UpdateButton_2.ButtonPushedFcn = createCallbackFcn(app, @UpdateButton_2Pushed, true);
            app.UpdateButton_2.Text = 'Update';
            app.VmmEditField_2Label = uilabel(app.PanelSPS01_2);
            app.VmmEditField_2Label.Text = 'V (mm)';
            app.VmmEditField_2 = uieditfield(app.PanelSPS01_2, 'numeric');
            app.VmmEditField_2.Tag = 'currentV';
            app.VmmEditField_2.Editable = 'off';
            app.MoveToButton_2 = uibutton(app.PanelSPS01_2, 'push');
            app.MoveToButton_2.ButtonPushedFcn = createCallbackFcn(app, @MoveToButton_2Pushed, true);
            app.MoveToButton_2.Text = 'MoveTo';
            app.VfmmEditField_2Label = uilabel(app.PanelSPS01_2);
            app.VfmmEditField_2Label.Text = 'Vf (mm)';
            app.VfmmEditField_2 = uieditfield(app.PanelSPS01_2, 'numeric');
            app.VfmmEditField_2.Tag = 'finalV';
            app.MaxVulEditField_2Label = uilabel(app.PanelSPS01_2);
            app.MaxVulEditField_2Label.Text = 'MaxV (ul)';
            app.MaxVulEditField_2 = uieditfield(app.PanelSPS01_2, 'numeric');
            app.MaxVulEditField_2.Tag = 'maxV';
            app.MaxVulEditField_2.Editable = 'off';
            app.AutoCalibrationButton_2 = uibutton(app.PanelSPS01_2, 'push');
            app.AutoCalibrationButton_2.ButtonPushedFcn = createCallbackFcn(app, @AutoCalibrationButton_2Pushed, true);
            app.AutoCalibrationButton_2.Text = 'Auto Calibration';
            app.EditField_2 = uieditfield(app.PanelSPS01_2, 'text');
            app.EditField_2.Tag = 'comment';
            app.EditField_2.Editable = 'off';
            app.DiammmEditField_2Label = uilabel(app.PanelSPS01_2);
            app.DiammmEditField_2Label.Text = 'Diam (mm)';
            app.DiammmEditField_2 = uieditfield(app.PanelSPS01_2, 'numeric');
            app.DiammmEditField_2.Tag = 'diam';
            app.DiammmEditField_2.Editable = 'off';
            app.FRulminEditField_2Label_2 = uilabel(app.PanelSPS01_2);
            app.FRulminEditField_2Label_2.Text = 'FR(ul/min)';
            app.FRulminEditField_2 = uieditfield(app.PanelSPS01_2, 'numeric');
            app.FRulminEditField_2.Tag = 'FlowRate';
            app.AddrEditField_2Label = uilabel(app.Panel_2);
            app.AddrEditField_2Label.Text = 'Addr';
            app.AddrEditField_2 = uieditfield(app.Panel_2, 'numeric');
            app.AddrEditField_2.Tag = 'address';
            app.AddrEditField_2.Editable = 'off';
            app.ClassEditField_2Label = uilabel(app.Panel_2);
            app.ClassEditField_2Label.Text = 'Class';
            app.ClassEditField_2 = uieditfield(app.Panel_2, 'text');
            app.ClassEditField_2.Tag = 'class';
            app.ClassEditField_2.Editable = 'off';
            app.FlashButton_2 = uibutton(app.Panel_2, 'push');
            app.FlashButton_2.ButtonPushedFcn = createCallbackFcn(app, @FlashButton_2Pushed, true);
            app.FlashButton_2.Text = 'Flash';
            app.ChangeButton_2 = uibutton(app.Panel_2, 'push');
            app.ChangeButton_2.ButtonPushedFcn = createCallbackFcn(app, @ChangeButton_2Pushed, true);
            app.ChangeButton_2.Text = 'Change';
            app.Lamp_2 = uilamp(app.Panel_2);
            app.PanelC4VM_2 = uipanel(app.Panel_2);
            app.PanelC4VM_2.Visible = 'off';
            app.PanelC4VM_2.Tag = 'mpanel';
            app.Valve2EditField_2Label = uilabel(app.PanelC4VM_2);
            app.Valve2EditField_2Label.Text = 'Valve 2';
            app.Valve2EditField_2 = uieditfield(app.PanelC4VM_2, 'numeric');
            app.Valve2EditField_2.Tag = 'valve2';
            app.Valve3EditField_2Label = uilabel(app.PanelC4VM_2);
            app.Valve3EditField_2Label.Text = 'Valve 3';
            app.Valve3EditField_2 = uieditfield(app.PanelC4VM_2, 'numeric');
            app.Valve3EditField_2.Tag = 'valve3';
            app.Valve4EditField_2Label = uilabel(app.PanelC4VM_2);
            app.Valve4EditField_2Label.Text = 'Valve 4';
            app.Valve4EditField_2 = uieditfield(app.PanelC4VM_2, 'numeric');
            app.Valve4EditField_2.Tag = 'valve4';
            app.Valve1EditField_2Label = uilabel(app.PanelC4VM_2);
            app.Valve1EditField_2Label.Text = 'Valve 1';
            app.Valve1EditField_2 = uieditfield(app.PanelC4VM_2, 'numeric');
            app.Valve1EditField_2.Tag = 'valve1';
            app.SwitchButton_2 = uibutton(app.PanelC4VM_2, 'push');
            app.SwitchButton_2.ButtonPushedFcn = createCallbackFcn(app, @SwitchButton_2Pushed, true);
            app.SwitchButton_2.Text = 'Switch';
            app.nochange1A2closed3BLabel_2 = uilabel(app.PanelC4VM_2);
            app.nochange1A2closed3BLabel_2.Text = {'0 - no change'; '1 - A'; '2 - closed'; '3 - B'};
            app.NameEditField_2 = uieditfield(app.Panel_2, 'text');
            app.NameEditField_2.Tag = 'name';
            app.NewNameEditField_2Label = uilabel(app.Panel_2);
            app.NewNameEditField_2Label.Text = 'New Name:';
            app.NewNameEditField_2 = uieditfield(app.Panel_2, 'text');
            app.NewNameEditField_2.Tag = 'nwname';
            app.StopButton_2 = uibutton(app.Panel_2, 'push');
            app.StopButton_2.ButtonPushedFcn = createCallbackFcn(app, @StopButton_2Pushed, true);
            app.StopButton_2.Text = 'Stop';
            app.Panel_3 = uipanel(app.UIFigure);
            app.Panel_3.Tag = 'panel3';
            app.PanelSPS01_3 = uipanel(app.Panel_3);
            app.PanelSPS01_3.Visible = 'off';
            app.PanelSPS01_3.Tag = 'spanel';
            app.MinFRulminEditField_3Label = uilabel(app.PanelSPS01_3);
            app.MinFRulminEditField_3Label.Text = 'MinFR (ul/min)';
            app.MinFRulminEditField_3 = uieditfield(app.PanelSPS01_3, 'numeric');
            app.MinFRulminEditField_3.Tag = 'minFR';
            app.MinFRulminEditField_3.Editable = 'off';
            app.MaxFRulminEditField_3Label = uilabel(app.PanelSPS01_3);
            app.MaxFRulminEditField_3Label.Text = 'MaxFR (ul/min)';
            app.MaxFRulminEditField_3 = uieditfield(app.PanelSPS01_3, 'numeric');
            app.MaxFRulminEditField_3.Tag = 'maxFR';
            app.MaxFRulminEditField_3.Editable = 'off';
            app.UpdateButton_3 = uibutton(app.PanelSPS01_3, 'push');
            app.UpdateButton_3.ButtonPushedFcn = createCallbackFcn(app, @UpdateButton_3Pushed, true);
            app.UpdateButton_3.Text = 'Update';
            app.VmmEditField_3Label = uilabel(app.PanelSPS01_3);
            app.VmmEditField_3Label.Text = 'V (mm)';
            app.VmmEditField_3 = uieditfield(app.PanelSPS01_3, 'numeric');
            app.VmmEditField_3.Tag = 'currentV';
            app.VmmEditField_3.Editable = 'off';
            app.MoveToButton_3 = uibutton(app.PanelSPS01_3, 'push');
            app.MoveToButton_3.ButtonPushedFcn = createCallbackFcn(app, @MoveToButton_3Pushed, true);
            app.MoveToButton_3.Text = 'MoveTo';
            app.VfmmEditField_3Label = uilabel(app.PanelSPS01_3);
            app.VfmmEditField_3Label.Text = 'Vf (mm)';
            app.VfmmEditField_3 = uieditfield(app.PanelSPS01_3, 'numeric');
            app.VfmmEditField_3.Tag = 'finalV';
            app.MaxVulEditField_3Label = uilabel(app.PanelSPS01_3);
            app.MaxVulEditField_3Label.Text = 'MaxV (ul)';
            app.MaxVulEditField_3 = uieditfield(app.PanelSPS01_3, 'numeric');
            app.MaxVulEditField_3.Tag = 'maxV';
            app.MaxVulEditField_3.Editable = 'off';
            app.AutoCalibrationButton_3 = uibutton(app.PanelSPS01_3, 'push');
            app.AutoCalibrationButton_3.ButtonPushedFcn = createCallbackFcn(app, @AutoCalibrationButton_3Pushed, true);
            app.AutoCalibrationButton_3.Text = 'Auto Calibration';
            app.EditField_3 = uieditfield(app.PanelSPS01_3, 'text');
            app.EditField_3.Tag = 'comment';
            app.EditField_3.Editable = 'off';
            app.DiammmEditField_3Label = uilabel(app.PanelSPS01_3);
            app.DiammmEditField_3Label.Text = 'Diam (mm)';
            app.DiammmEditField_3 = uieditfield(app.PanelSPS01_3, 'numeric');
            app.DiammmEditField_3.Tag = 'diam';
            app.DiammmEditField_3.Editable = 'off';
            app.FRulminEditField_3Label = uilabel(app.PanelSPS01_3);
            app.FRulminEditField_3Label.Text = 'FR(ul/min)';
            app.FRulminEditField_3 = uieditfield(app.PanelSPS01_3, 'numeric');
            app.FRulminEditField_3.Tag = 'FlowRate';
            app.AddrEditField_3Label = uilabel(app.Panel_3);
            app.AddrEditField_3Label.Text = 'Addr';
            app.AddrEditField_3 = uieditfield(app.Panel_3, 'numeric');
            app.AddrEditField_3.Tag = 'address';
            app.AddrEditField_3.Editable = 'off';
            app.ClassEditField_3Label = uilabel(app.Panel_3);
            app.ClassEditField_3Label.Text = 'Class';
            app.ClassEditField_3 = uieditfield(app.Panel_3, 'text');
            app.ClassEditField_3.Tag = 'class';
            app.ClassEditField_3.Editable = 'off';
            app.Lamp_3 = uilamp(app.Panel_3);
            app.ChangeButton_3 = uibutton(app.Panel_3, 'push');
            app.ChangeButton_3.ButtonPushedFcn = createCallbackFcn(app, @ChangeButton_3Pushed, true);
            app.ChangeButton_3.Text = 'Change';
            app.FlashButton_3 = uibutton(app.Panel_3, 'push');
            app.FlashButton_3.ButtonPushedFcn = createCallbackFcn(app, @FlashButton_3Pushed, true);
            app.FlashButton_3.Text = 'Flash';
            app.PanelC4VM_3 = uipanel(app.Panel_3);
            app.PanelC4VM_3.Visible = 'off';
            app.PanelC4VM_3.Tag = 'mpanel';
            app.Valve2EditField_3Label = uilabel(app.PanelC4VM_3);
            app.Valve2EditField_3Label.Text = 'Valve 2';
            app.Valve2EditField_3 = uieditfield(app.PanelC4VM_3, 'numeric');
            app.Valve2EditField_3.Tag = 'valve2';
            app.Valve3EditField_3Label = uilabel(app.PanelC4VM_3);
            app.Valve3EditField_3Label.Text = 'Valve 3';
            app.Valve3EditField_3 = uieditfield(app.PanelC4VM_3, 'numeric');
            app.Valve3EditField_3.Tag = 'valve3';
            app.Valve4EditField_3Label = uilabel(app.PanelC4VM_3);
            app.Valve4EditField_3Label.Text = 'Valve 4';
            app.Valve4EditField_3 = uieditfield(app.PanelC4VM_3, 'numeric');
            app.Valve4EditField_3.Tag = 'valve4';
            app.Valve1EditField_3Label = uilabel(app.PanelC4VM_3);
            app.Valve1EditField_3Label.Text = 'Valve 1';
            app.Valve1EditField_3 = uieditfield(app.PanelC4VM_3, 'numeric');
            app.Valve1EditField_3.Tag = 'valve1';
            app.SwitchButton_3 = uibutton(app.PanelC4VM_3, 'push');
            app.SwitchButton_3.ButtonPushedFcn = createCallbackFcn(app, @SwitchButton_3Pushed, true);
            app.SwitchButton_3.Text = 'Switch';
            app.nochange1A2closed3BLabel_3 = uilabel(app.PanelC4VM_3);
            app.nochange1A2closed3BLabel_3.Text = {'0 - no change'; '1 - A'; '2 - closed'; '3 - B'};
            app.NameEditField_3 = uieditfield(app.Panel_3, 'text');
            app.NameEditField_3.Tag = 'name';
            app.NewNameEditField_3Label = uilabel(app.Panel_3);
            app.NewNameEditField_3Label.Text = 'New Name:';
            app.NewNameEditField_3 = uieditfield(app.Panel_3, 'text');
            app.NewNameEditField_3.Tag = 'nwname';
            app.StopButton_3 = uibutton(app.Panel_3, 'push');
            app.StopButton_3.ButtonPushedFcn = createCallbackFcn(app, @StopButton_3Pushed, true);
            app.StopButton_3.Text = 'Stop';
            app.Panel_4 = uipanel(app.UIFigure);
            app.Panel_4.Tag = 'panel4';
            app.PanelSPS01_4 = uipanel(app.Panel_4);
            app.PanelSPS01_4.Visible = 'off';
            app.PanelSPS01_4.Tag = 'spanel';
            app.MinFRulminEditField_4Label = uilabel(app.PanelSPS01_4);
            app.MinFRulminEditField_4Label.Text = 'MinFR (ul/min)';
            app.MinFRulminEditField_4 = uieditfield(app.PanelSPS01_4, 'numeric');
            app.MinFRulminEditField_4.Tag = 'minFR';
            app.MinFRulminEditField_4.Editable = 'off';
            app.MaxFRulminEditField_4Label = uilabel(app.PanelSPS01_4);
            app.MaxFRulminEditField_4Label.Text = 'MaxFR (ul/min)';
            app.MaxFRulminEditField_4 = uieditfield(app.PanelSPS01_4, 'numeric');
            app.MaxFRulminEditField_4.Tag = 'maxFR';
            app.MaxFRulminEditField_4.Editable = 'off';
            app.UpdateButton_4 = uibutton(app.PanelSPS01_4, 'push');
            app.UpdateButton_4.ButtonPushedFcn = createCallbackFcn(app, @UpdateButton_4Pushed, true);
            app.UpdateButton_4.Text = 'Update';
            app.VmmEditField_4Label = uilabel(app.PanelSPS01_4);
            app.VmmEditField_4Label.Text = 'V (mm)';
            app.VmmEditField_4 = uieditfield(app.PanelSPS01_4, 'numeric');
            app.VmmEditField_4.Tag = 'currentV';
            app.VmmEditField_4.Editable = 'off';
            app.MoveToButton_4 = uibutton(app.PanelSPS01_4, 'push');
            app.MoveToButton_4.ButtonPushedFcn = createCallbackFcn(app, @MoveToButton_4Pushed, true);
            app.MoveToButton_4.Text = 'MoveTo';
            app.VfmmEditField_4Label = uilabel(app.PanelSPS01_4);
            app.VfmmEditField_4Label.Text = 'Vf (mm)';
            app.VfmmEditField_4 = uieditfield(app.PanelSPS01_4, 'numeric');
            app.VfmmEditField_4.Tag = 'finalV';
            app.MaxVulEditField_4Label = uilabel(app.PanelSPS01_4);
            app.MaxVulEditField_4Label.Text = 'MaxV (ul)';
            app.MaxVulEditField_4 = uieditfield(app.PanelSPS01_4, 'numeric');
            app.MaxVulEditField_4.Tag = 'maxV';
            app.MaxVulEditField_4.Editable = 'off';
            app.AutoCalibrationButton_4 = uibutton(app.PanelSPS01_4, 'push');
            app.AutoCalibrationButton_4.ButtonPushedFcn = createCallbackFcn(app, @AutoCalibrationButton_4Pushed, true);
            app.AutoCalibrationButton_4.Text = 'Auto Calibration';
            app.EditField_4 = uieditfield(app.PanelSPS01_4, 'text');
            app.EditField_4.Tag = 'comment';
            app.EditField_4.Editable = 'off';
            app.DiammmEditField_4Label = uilabel(app.PanelSPS01_4);
            app.DiammmEditField_4Label.Text = 'Diam (mm)';
            app.DiammmEditField_4 = uieditfield(app.PanelSPS01_4, 'numeric');
            app.DiammmEditField_4.Tag = 'diam';
            app.DiammmEditField_4.Editable = 'off';
            app.FRulminEditField_4Label = uilabel(app.PanelSPS01_4);
            app.FRulminEditField_4Label.Text = 'FR(ul/min)';
            app.FRulminEditField_4 = uieditfield(app.PanelSPS01_4, 'numeric');
            app.FRulminEditField_4.Tag = 'FlowRate';
            app.AddrEditField_4Label = uilabel(app.Panel_4);
            app.AddrEditField_4Label.Text = 'Addr';
            app.AddrEditField_4 = uieditfield(app.Panel_4, 'numeric');
            app.AddrEditField_4.Tag = 'address';
            app.AddrEditField_4.Editable = 'off';
            app.ClassEditField_4Label = uilabel(app.Panel_4);
            app.ClassEditField_4Label.Text = 'Class';
            app.ClassEditField_4 = uieditfield(app.Panel_4, 'text');
            app.ClassEditField_4.Tag = 'class';
            app.ClassEditField_4.Editable = 'off';
            app.Lamp_4 = uilamp(app.Panel_4);
            app.ChangeButton_4 = uibutton(app.Panel_4, 'push');
            app.ChangeButton_4.ButtonPushedFcn = createCallbackFcn(app, @ChangeButton_4Pushed, true);
            app.ChangeButton_4.Text = 'Change';
            app.FlashButton_4 = uibutton(app.Panel_4, 'push');
            app.FlashButton_4.ButtonPushedFcn = createCallbackFcn(app, @FlashButton_4Pushed, true);
            app.FlashButton_4.Text = 'Flash';
            app.PanelC4VM_4 = uipanel(app.Panel_4);
            app.PanelC4VM_4.Visible = 'off';
            app.PanelC4VM_4.Tag = 'mpanel';
            app.Valve2EditField_4Label = uilabel(app.PanelC4VM_4);
            app.Valve2EditField_4Label.Text = 'Valve 2';
            app.Valve2EditField_4 = uieditfield(app.PanelC4VM_4, 'numeric');
            app.Valve2EditField_4.Tag = 'valve2';
            app.Valve3EditField_4Label = uilabel(app.PanelC4VM_4);
            app.Valve3EditField_4Label.Text = 'Valve 3';
            app.Valve3EditField_4 = uieditfield(app.PanelC4VM_4, 'numeric');
            app.Valve3EditField_4.Tag = 'valve3';
            app.Valve4EditField_4Label = uilabel(app.PanelC4VM_4);
            app.Valve4EditField_4Label.Text = 'Valve 4';
            app.Valve4EditField_4 = uieditfield(app.PanelC4VM_4, 'numeric');
            app.Valve4EditField_4.Tag = 'valve4';
            app.Valve1EditField_4Label = uilabel(app.PanelC4VM_4);
            app.Valve1EditField_4Label.Text = 'Valve 1';
            app.Valve1EditField_4 = uieditfield(app.PanelC4VM_4, 'numeric');
            app.Valve1EditField_4.Tag = 'valve1';
            app.SwitchButton_4 = uibutton(app.PanelC4VM_4, 'push');
            app.SwitchButton_4.ButtonPushedFcn = createCallbackFcn(app, @SwitchButton_4Pushed, true);
            app.SwitchButton_4.Text = 'Switch';
            app.nochange1A2closed3BLabel_4 = uilabel(app.PanelC4VM_4);
            app.nochange1A2closed3BLabel_4.Text = {'0 - no change'; '1 - A'; '2 - closed'; '3 - B'};
            app.NameEditField_4 = uieditfield(app.Panel_4, 'text');
            app.NameEditField_4.Tag = 'name';
            app.NewNameEditField_4Label = uilabel(app.Panel_4);
            app.NewNameEditField_4Label.Text = 'New Name:';
            app.NewNameEditField_4 = uieditfield(app.Panel_4, 'text');
            app.NewNameEditField_4.Tag = 'nwname';
            app.StopButton_4 = uibutton(app.Panel_4, 'push');
            app.StopButton_4.ButtonPushedFcn = createCallbackFcn(app, @StopButton_4Pushed, true);
            app.StopButton_4.Text = 'Stop';
            app.Panel_5 = uipanel(app.UIFigure);
            app.Panel_5.Tag = 'panel5';
            app.PanelSPS01_5 = uipanel(app.Panel_5);
            app.PanelSPS01_5.Visible = 'off';
            app.PanelSPS01_5.Tag = 'spanel';
            app.MinFRulminEditField_5Label = uilabel(app.PanelSPS01_5);
            app.MinFRulminEditField_5Label.Text = 'MinFR (ul/min)';
            app.MinFRulminEditField_5 = uieditfield(app.PanelSPS01_5, 'numeric');
            app.MinFRulminEditField_5.Tag = 'minFR';
            app.MinFRulminEditField_5.Editable = 'off';
            app.MaxFRulminEditField_5Label = uilabel(app.PanelSPS01_5);
            app.MaxFRulminEditField_5Label.Text = 'MaxFR (ul/min)';
            app.MaxFRulminEditField_5 = uieditfield(app.PanelSPS01_5, 'numeric');
            app.MaxFRulminEditField_5.Tag = 'maxFR';
            app.MaxFRulminEditField_5.Editable = 'off';
            app.UpdateButton_5 = uibutton(app.PanelSPS01_5, 'push');
            app.UpdateButton_5.ButtonPushedFcn = createCallbackFcn(app, @UpdateButton_5Pushed, true);
            app.UpdateButton_5.Text = 'Update';
            app.VmmEditField_5Label = uilabel(app.PanelSPS01_5);
            app.VmmEditField_5Label.Text = 'V (mm)';
            app.VmmEditField_5 = uieditfield(app.PanelSPS01_5, 'numeric');
            app.VmmEditField_5.Tag = 'currentV';
            app.VmmEditField_5.Editable = 'off';
            app.MoveToButton_5 = uibutton(app.PanelSPS01_5, 'push');
            app.MoveToButton_5.ButtonPushedFcn = createCallbackFcn(app, @MoveToButton_5Pushed, true);
            app.MoveToButton_5.Text = 'MoveTo';
            app.VfmmEditField_5Label = uilabel(app.PanelSPS01_5);
            app.VfmmEditField_5Label.Text = 'Vf (mm)';
            app.VfmmEditField_5 = uieditfield(app.PanelSPS01_5, 'numeric');
            app.VfmmEditField_5.Tag = 'finalV';
            app.MaxVulEditField_5Label = uilabel(app.PanelSPS01_5);
            app.MaxVulEditField_5Label.Text = 'MaxV (ul)';
            app.MaxVulEditField_5 = uieditfield(app.PanelSPS01_5, 'numeric');
            app.MaxVulEditField_5.Tag = 'maxV';
            app.MaxVulEditField_5.Editable = 'off';
            app.AutoCalibrationButton_5 = uibutton(app.PanelSPS01_5, 'push');
            app.AutoCalibrationButton_5.ButtonPushedFcn = createCallbackFcn(app, @AutoCalibrationButton_5Pushed, true);
            app.AutoCalibrationButton_5.Text = 'Auto Calibration';
            app.EditField_5 = uieditfield(app.PanelSPS01_5, 'text');
            app.EditField_5.Tag = 'comment';
            app.EditField_5.Editable = 'off';
            app.DiammmEditField_5Label = uilabel(app.PanelSPS01_5);
            app.DiammmEditField_5Label.Text = 'Diam (mm)';
            app.DiammmEditField_5 = uieditfield(app.PanelSPS01_5, 'numeric');
            app.DiammmEditField_5.Tag = 'diam';
            app.DiammmEditField_5.Editable = 'off';
            app.FRulminEditField_5Label = uilabel(app.PanelSPS01_5);
            app.FRulminEditField_5Label.Text = 'FR(ul/min)';
            app.FRulminEditField_5 = uieditfield(app.PanelSPS01_5, 'numeric');
            app.FRulminEditField_5.Tag = 'FlowRate';
            app.AddrEditField_5Label = uilabel(app.Panel_5);
            app.AddrEditField_5Label.Text = 'Addr';
            app.AddrEditField_5 = uieditfield(app.Panel_5, 'numeric');
            app.AddrEditField_5.Tag = 'address';
            app.AddrEditField_5.Editable = 'off';
            app.ClassEditField_5Label = uilabel(app.Panel_5);
            app.ClassEditField_5Label.Text = 'Class';
            app.ClassEditField_5 = uieditfield(app.Panel_5, 'text');
            app.ClassEditField_5.Tag = 'class';
            app.ClassEditField_5.Editable = 'off';
            app.Lamp_5 = uilamp(app.Panel_5);
            app.ChangeButton_5 = uibutton(app.Panel_5, 'push');
            app.ChangeButton_5.ButtonPushedFcn = createCallbackFcn(app, @ChangeButton_5Pushed, true);
            app.ChangeButton_5.Text = 'Change';
            app.FlashButton_5 = uibutton(app.Panel_5, 'push');
            app.FlashButton_5.ButtonPushedFcn = createCallbackFcn(app, @FlashButton_5Pushed, true);
            app.FlashButton_5.Text = 'Flash';
            app.PanelC4VM_5 = uipanel(app.Panel_5);
            app.PanelC4VM_5.Visible = 'off';
            app.PanelC4VM_5.Tag = 'mpanel';
            app.Valve2EditField_5Label = uilabel(app.PanelC4VM_5);
            app.Valve2EditField_5Label.Text = 'Valve 2';
            app.Valve2EditField_5 = uieditfield(app.PanelC4VM_5, 'numeric');
            app.Valve2EditField_5.Tag = 'valve2';
            app.Valve3EditField_5Label = uilabel(app.PanelC4VM_5);
            app.Valve3EditField_5Label.Text = 'Valve 3';
            app.Valve3EditField_5 = uieditfield(app.PanelC4VM_5, 'numeric');
            app.Valve3EditField_5.Tag = 'valve3';
            app.Valve4EditField_5Label = uilabel(app.PanelC4VM_5);
            app.Valve4EditField_5Label.Text = 'Valve 4';
            app.Valve4EditField_5 = uieditfield(app.PanelC4VM_5, 'numeric');
            app.Valve4EditField_5.Tag = 'valve4';
            app.Valve1EditField_5Label = uilabel(app.PanelC4VM_5);
            app.Valve1EditField_5Label.Text = 'Valve 1';
            app.Valve1EditField_5 = uieditfield(app.PanelC4VM_5, 'numeric');
            app.Valve1EditField_5.Tag = 'valve1';
            app.SwitchButton_5 = uibutton(app.PanelC4VM_5, 'push');
            app.SwitchButton_5.ButtonPushedFcn = createCallbackFcn(app, @SwitchButton_5Pushed, true);
            app.SwitchButton_5.Text = 'Switch';
            app.nochange1A2closed3BLabel_5 = uilabel(app.PanelC4VM_5);
            app.nochange1A2closed3BLabel_5.Text = {'0 - no change'; '1 - A'; '2 - closed'; '3 - B'};
            app.NameEditField_5 = uieditfield(app.Panel_5, 'text');
            app.NameEditField_5.Tag = 'name';
            app.NewNameEditField_5Label = uilabel(app.Panel_5);
            app.NewNameEditField_5Label.Text = 'New Name:';
            app.NewNameEditField_5 = uieditfield(app.Panel_5, 'text');
            app.NewNameEditField_5.Tag = 'nwname';
            app.StopButton_5 = uibutton(app.Panel_5, 'push');
            app.StopButton_5.ButtonPushedFcn = createCallbackFcn(app, @StopButton_5Pushed, true);
            app.StopButton_5.Text = 'Stop';
            app.Panel_6 = uipanel(app.UIFigure);
            app.Panel_6.Tag = 'panel6';
            app.PanelSPS01_6 = uipanel(app.Panel_6);
            app.PanelSPS01_6.Visible = 'off';
            app.PanelSPS01_6.Tag = 'spanel';
            app.MinFRulminEditField_6Label = uilabel(app.PanelSPS01_6);
            app.MinFRulminEditField_6Label.Text = 'MinFR (ul/min)';
            app.MinFRulminEditField_6 = uieditfield(app.PanelSPS01_6, 'numeric');
            app.MinFRulminEditField_6.Tag = 'minFR';
            app.MinFRulminEditField_6.Editable = 'off';
            app.MaxFRulminEditField_6Label = uilabel(app.PanelSPS01_6);
            app.MaxFRulminEditField_6Label.Text = 'MaxFR (ul/min)';
            app.MaxFRulminEditField_6 = uieditfield(app.PanelSPS01_6, 'numeric');
            app.MaxFRulminEditField_6.Tag = 'maxFR';
            app.MaxFRulminEditField_6.Editable = 'off';
            app.UpdateButton_6 = uibutton(app.PanelSPS01_6, 'push');
            app.UpdateButton_6.ButtonPushedFcn = createCallbackFcn(app, @UpdateButton_6Pushed, true);
            app.UpdateButton_6.Text = 'Update';
            app.VmmEditField_6Label = uilabel(app.PanelSPS01_6);
            app.VmmEditField_6Label.Text = 'V (mm)';
            app.VmmEditField_6 = uieditfield(app.PanelSPS01_6, 'numeric');
            app.VmmEditField_6.Tag = 'currentV';
            app.VmmEditField_6.Editable = 'off';
            app.MoveToButton_6 = uibutton(app.PanelSPS01_6, 'push');
            app.MoveToButton_6.ButtonPushedFcn = createCallbackFcn(app, @MoveToButton_6Pushed, true);
            app.MoveToButton_6.Text = 'MoveTo';
            app.VfmmEditField_6Label = uilabel(app.PanelSPS01_6);
            app.VfmmEditField_6Label.Text = 'Vf (mm)';
            app.VfmmEditField_6 = uieditfield(app.PanelSPS01_6, 'numeric');
            app.VfmmEditField_6.Tag = 'finalV';
            app.MaxVulEditField_6Label = uilabel(app.PanelSPS01_6);
            app.MaxVulEditField_6Label.Text = 'MaxV (ul)';
            app.MaxVulEditField_6 = uieditfield(app.PanelSPS01_6, 'numeric');
            app.MaxVulEditField_6.Tag = 'maxV';
            app.MaxVulEditField_6.Editable = 'off';
            app.AutoCalibrationButton_6 = uibutton(app.PanelSPS01_6, 'push');
            app.AutoCalibrationButton_6.ButtonPushedFcn = createCallbackFcn(app, @AutoCalibrationButton_6Pushed, true);
            app.AutoCalibrationButton_6.Text = 'Auto Calibration';
            app.EditField_6 = uieditfield(app.PanelSPS01_6, 'text');
            app.EditField_6.Tag = 'comment';
            app.EditField_6.Editable = 'off';
            app.DiammmEditField_6Label = uilabel(app.PanelSPS01_6);
            app.DiammmEditField_6Label.Text = 'Diam (mm)';
            app.DiammmEditField_6 = uieditfield(app.PanelSPS01_6, 'numeric');
            app.DiammmEditField_6.Tag = 'diam';
            app.DiammmEditField_6.Editable = 'off';
            app.FRulminEditField_6Label = uilabel(app.PanelSPS01_6);
            app.FRulminEditField_6Label.Text = 'FR(ul/min)';
            app.FRulminEditField_6 = uieditfield(app.PanelSPS01_6, 'numeric');
            app.FRulminEditField_6.Tag = 'FlowRate';
            app.AddrEditField_6Label = uilabel(app.Panel_6);
            app.AddrEditField_6Label.Text = 'Addr';
            app.AddrEditField_6 = uieditfield(app.Panel_6, 'numeric');
            app.AddrEditField_6.Tag = 'address';
            app.AddrEditField_6.Editable = 'off';
            app.ClassEditField_6Label = uilabel(app.Panel_6);
            app.ClassEditField_6Label.Text = 'Class';
            app.ClassEditField_6 = uieditfield(app.Panel_6, 'text');
            app.ClassEditField_6.Tag = 'class';
            app.ClassEditField_6.Editable = 'off';
            app.Lamp_6 = uilamp(app.Panel_6);
            app.ChangeButton_6 = uibutton(app.Panel_6, 'push');
            app.ChangeButton_6.ButtonPushedFcn = createCallbackFcn(app, @ChangeButton_6Pushed, true);
            app.ChangeButton_6.Text = 'Change';
            app.FlashButton_6 = uibutton(app.Panel_6, 'push');
            app.FlashButton_6.ButtonPushedFcn = createCallbackFcn(app, @FlashButton_6Pushed, true);
            app.FlashButton_6.Text = 'Flash';
            app.PanelC4VM_6 = uipanel(app.Panel_6);
            app.PanelC4VM_6.Visible = 'off';
            app.PanelC4VM_6.Tag = 'mpanel';
            app.Valve2EditField_6Label = uilabel(app.PanelC4VM_6);
            app.Valve2EditField_6Label.Text = 'Valve 2';
            app.Valve2EditField_6 = uieditfield(app.PanelC4VM_6, 'numeric');
            app.Valve2EditField_6.Tag = 'valve2';
            app.Valve3EditField_6Label = uilabel(app.PanelC4VM_6);
            app.Valve3EditField_6Label.Text = 'Valve 3';
            app.Valve3EditField_6 = uieditfield(app.PanelC4VM_6, 'numeric');
            app.Valve3EditField_6.Tag = 'valve3';
            app.Valve4EditField_6Label = uilabel(app.PanelC4VM_6);
            app.Valve4EditField_6Label.Text = 'Valve 4';
            app.Valve4EditField_6 = uieditfield(app.PanelC4VM_6, 'numeric');
            app.Valve4EditField_6.Tag = 'valve4';
            app.Valve1EditField_6Label = uilabel(app.PanelC4VM_6);
            app.Valve1EditField_6Label.Text = 'Valve 1';
            app.Valve1EditField_6 = uieditfield(app.PanelC4VM_6, 'numeric');
            app.Valve1EditField_6.Tag = 'valve1';
            app.SwitchButton_6 = uibutton(app.PanelC4VM_6, 'push');
            app.SwitchButton_6.ButtonPushedFcn = createCallbackFcn(app, @SwitchButton_6Pushed, true);
            app.SwitchButton_6.Text = 'Switch';
            app.nochange1A2closed3BLabel_6 = uilabel(app.PanelC4VM_6);
            app.nochange1A2closed3BLabel_6.Text = {'0 - no change'; '1 - A'; '2 - closed'; '3 - B'};
            app.NameEditField_6 = uieditfield(app.Panel_6, 'text');
            app.NameEditField_6.Tag = 'name';
            app.NewNameEditField_6Label = uilabel(app.Panel_6);
            app.NewNameEditField_6Label.Text = 'New Name:';
            app.NewNameEditField_6 = uieditfield(app.Panel_6, 'text');
            app.NewNameEditField_6.Tag = 'nwname';
            app.StopButton_6 = uibutton(app.Panel_6, 'push');
            app.StopButton_6.ButtonPushedFcn = createCallbackFcn(app, @StopButton_6Pushed, true);
            app.StopButton_6.Text = 'Stop';
            app.Panel_7 = uipanel(app.UIFigure);
            app.Panel_7.Tag = 'panel7';
            app.PanelC4VM_7 = uipanel(app.Panel_7);
            app.PanelC4VM_7.Visible = 'off';
            app.PanelC4VM_7.Tag = 'mpanel';
            app.Valve2EditField_7Label = uilabel(app.PanelC4VM_7);
            app.Valve2EditField_7Label.Text = 'Valve 2';
            app.Valve2EditField_7 = uieditfield(app.PanelC4VM_7, 'numeric');
            app.Valve2EditField_7.Tag = 'valve2';
            app.Valve3EditField_7Label = uilabel(app.PanelC4VM_7);
            app.Valve3EditField_7Label.Text = 'Valve 3';
            app.Valve3EditField_7 = uieditfield(app.PanelC4VM_7, 'numeric');
            app.Valve3EditField_7.Tag = 'valve3';
            app.Valve4EditField_7Label = uilabel(app.PanelC4VM_7);
            app.Valve4EditField_7Label.Text = 'Valve 4';
            app.Valve4EditField_7 = uieditfield(app.PanelC4VM_7, 'numeric');
            app.Valve4EditField_7.Tag = 'valve4';
            app.Valve1EditField_7Label = uilabel(app.PanelC4VM_7);
            app.Valve1EditField_7Label.Text = 'Valve 1';
            app.Valve1EditField_7 = uieditfield(app.PanelC4VM_7, 'numeric');
            app.Valve1EditField_7.Tag = 'valve1';
            app.SwitchButton_7 = uibutton(app.PanelC4VM_7, 'push');
            app.SwitchButton_7.ButtonPushedFcn = createCallbackFcn(app, @SwitchButton_7Pushed, true);
            app.SwitchButton_7.Text = 'Switch';
            app.nochange1A2closed3BLabel_7 = uilabel(app.PanelC4VM_7);
            app.nochange1A2closed3BLabel_7.Text = {'0 - no change'; '1 - A'; '2 - closed'; '3 - B'};
            app.PanelSPS01_7 = uipanel(app.Panel_7);
            app.PanelSPS01_7.Visible = 'off';
            app.PanelSPS01_7.Tag = 'spanel';
            app.MinFRulminEditField_7Label = uilabel(app.PanelSPS01_7);
            app.MinFRulminEditField_7Label.Text = 'MinFR (ul/min)';
            app.MinFRulminEditField_7 = uieditfield(app.PanelSPS01_7, 'numeric');
            app.MinFRulminEditField_7.Tag = 'minFR';
            app.MinFRulminEditField_7.Editable = 'off';
            app.MaxFRulminEditField_7Label = uilabel(app.PanelSPS01_7);
            app.MaxFRulminEditField_7Label.Text = 'MaxFR (ul/min)';
            app.MaxFRulminEditField_7 = uieditfield(app.PanelSPS01_7, 'numeric');
            app.MaxFRulminEditField_7.Tag = 'maxFR';
            app.MaxFRulminEditField_7.Editable = 'off';
            app.UpdateButton_7 = uibutton(app.PanelSPS01_7, 'push');
            app.UpdateButton_7.ButtonPushedFcn = createCallbackFcn(app, @UpdateButton_7Pushed, true);
            app.UpdateButton_7.Text = 'Update';
            app.VmmEditField_7Label = uilabel(app.PanelSPS01_7);
            app.VmmEditField_7Label.Text = 'V (mm)';
            app.VmmEditField_7 = uieditfield(app.PanelSPS01_7, 'numeric');
            app.VmmEditField_7.Tag = 'currentV';
            app.VmmEditField_7.Editable = 'off';
            app.MoveToButton_7 = uibutton(app.PanelSPS01_7, 'push');
            app.MoveToButton_7.ButtonPushedFcn = createCallbackFcn(app, @MoveToButton_7Pushed, true);
            app.MoveToButton_7.Text = 'MoveTo';
            app.VfmmEditField_7Label = uilabel(app.PanelSPS01_7);
            app.VfmmEditField_7Label.Text = 'Vf (mm)';
            app.VfmmEditField_7 = uieditfield(app.PanelSPS01_7, 'numeric');
            app.VfmmEditField_7.Tag = 'finalV';
            app.MaxVulEditField_7Label = uilabel(app.PanelSPS01_7);
            app.MaxVulEditField_7Label.Text = 'MaxV (ul)';
            app.MaxVulEditField_7 = uieditfield(app.PanelSPS01_7, 'numeric');
            app.MaxVulEditField_7.Tag = 'maxV';
            app.MaxVulEditField_7.Editable = 'off';
            app.AutoCalibrationButton_7 = uibutton(app.PanelSPS01_7, 'push');
            app.AutoCalibrationButton_7.ButtonPushedFcn = createCallbackFcn(app, @AutoCalibrationButton_7Pushed, true);
            app.AutoCalibrationButton_7.Text = 'Auto Calibration';
            app.EditField_7 = uieditfield(app.PanelSPS01_7, 'text');
            app.EditField_7.Tag = 'comment';
            app.EditField_7.Editable = 'off';
            app.DiammmEditField_7Label = uilabel(app.PanelSPS01_7);
            app.DiammmEditField_7Label.Text = 'Diam (mm)';
            app.DiammmEditField_7 = uieditfield(app.PanelSPS01_7, 'numeric');
            app.DiammmEditField_7.Tag = 'diam';
            app.DiammmEditField_7.Editable = 'off';
            app.FRulminEditField_7Label = uilabel(app.PanelSPS01_7);
            app.FRulminEditField_7Label.Text = 'FR(ul/min)';
            app.FRulminEditField_7 = uieditfield(app.PanelSPS01_7, 'numeric');
            app.FRulminEditField_7.Tag = 'FlowRate';
            app.AddrEditField_7Label = uilabel(app.Panel_7);
            app.AddrEditField_7Label.Text = 'Addr';
            app.AddrEditField_7 = uieditfield(app.Panel_7, 'numeric');
            app.AddrEditField_7.Tag = 'address';
            app.AddrEditField_7.Editable = 'off';
            app.ClassEditField_7Label = uilabel(app.Panel_7);
            app.ClassEditField_7Label.Text = 'Class';
            app.ClassEditField_7 = uieditfield(app.Panel_7, 'text');
            app.ClassEditField_7.Tag = 'class';
            app.ClassEditField_7.Editable = 'off';
            app.Lamp_7 = uilamp(app.Panel_7);
            app.ChangeButton_7 = uibutton(app.Panel_7, 'push');
            app.ChangeButton_7.ButtonPushedFcn = createCallbackFcn(app, @ChangeButton_7Pushed, true);
            app.ChangeButton_7.Text = 'Change';
            app.FlashButton_7 = uibutton(app.Panel_7, 'push');
            app.FlashButton_7.ButtonPushedFcn = createCallbackFcn(app, @FlashButton_7Pushed, true);
            app.FlashButton_7.Text = 'Flash';
            app.NameEditField_7 = uieditfield(app.Panel_7, 'text');
            app.NameEditField_7.Tag = 'name';
            app.NewNameEditField_7Label = uilabel(app.Panel_7);
            app.NewNameEditField_7Label.Text = 'New Name:';
            app.NewNameEditField_7 = uieditfield(app.Panel_7, 'text');
            app.NewNameEditField_7.Tag = 'nwname';
            app.StopButton_7 = uibutton(app.Panel_7, 'push');
            app.StopButton_7.ButtonPushedFcn = createCallbackFcn(app, @StopButton_7Pushed, true);
            app.StopButton_7.Text = 'Stop';
            app.Panel_8 = uipanel(app.UIFigure);
            app.Panel_8.Tag = 'panel8';
            app.PanelSPS01_8 = uipanel(app.Panel_8);
            app.PanelSPS01_8.Visible = 'off';
            app.PanelSPS01_8.Tag = 'spanel';
            app.MinFRulminEditField_8Label = uilabel(app.PanelSPS01_8);
            app.MinFRulminEditField_8Label.Text = 'MinFR (ul/min)';
            app.MinFRulminEditField_8 = uieditfield(app.PanelSPS01_8, 'numeric');
            app.MinFRulminEditField_8.Tag = 'minFR';
            app.MinFRulminEditField_8.Editable = 'off';
            app.MaxFRulminEditField_8Label = uilabel(app.PanelSPS01_8);
            app.MaxFRulminEditField_8Label.Text = 'MaxFR (ul/min)';
            app.MaxFRulminEditField_8 = uieditfield(app.PanelSPS01_8, 'numeric');
            app.MaxFRulminEditField_8.Tag = 'maxFR';
            app.MaxFRulminEditField_8.Editable = 'off';
            app.UpdateButton_8 = uibutton(app.PanelSPS01_8, 'push');
            app.UpdateButton_8.ButtonPushedFcn = createCallbackFcn(app, @UpdateButton_8Pushed, true);
            app.UpdateButton_8.Text = 'Update';
            app.VmmEditField_8Label = uilabel(app.PanelSPS01_8);
            app.VmmEditField_8Label.Text = 'V (mm)';
            app.VmmEditField_8 = uieditfield(app.PanelSPS01_8, 'numeric');
            app.VmmEditField_8.Tag = 'currentV';
            app.VmmEditField_8.Editable = 'off';
            app.MoveToButton_8 = uibutton(app.PanelSPS01_8, 'push');
            app.MoveToButton_8.ButtonPushedFcn = createCallbackFcn(app, @MoveToButton_8Pushed, true);
            app.MoveToButton_8.Text = 'MoveTo';
            app.VfmmEditField_8Label = uilabel(app.PanelSPS01_8);
            app.VfmmEditField_8Label.Text = 'Vf (mm)';
            app.VfmmEditField_8 = uieditfield(app.PanelSPS01_8, 'numeric');
            app.VfmmEditField_8.Tag = 'finalV';
            app.MaxVulEditField_8Label = uilabel(app.PanelSPS01_8);
            app.MaxVulEditField_8Label.Text = 'MaxV (ul)';
            app.MaxVulEditField_8 = uieditfield(app.PanelSPS01_8, 'numeric');
            app.MaxVulEditField_8.Tag = 'maxV';
            app.MaxVulEditField_8.Editable = 'off';
            app.AutoCalibrationButton_8 = uibutton(app.PanelSPS01_8, 'push');
            app.AutoCalibrationButton_8.ButtonPushedFcn = createCallbackFcn(app, @AutoCalibrationButton_8Pushed, true);
            app.AutoCalibrationButton_8.Text = 'Auto Calibration';
            app.EditField_8 = uieditfield(app.PanelSPS01_8, 'text');
            app.EditField_8.Tag = 'comment';
            app.EditField_8.Editable = 'off';
            app.DiammmEditField_8Label = uilabel(app.PanelSPS01_8);
            app.DiammmEditField_8Label.Text = 'Diam (mm)';
            app.DiammmEditField_8 = uieditfield(app.PanelSPS01_8, 'numeric');
            app.DiammmEditField_8.Tag = 'diam';
            app.DiammmEditField_8.Editable = 'off';
            app.FRulminEditField_8Label = uilabel(app.PanelSPS01_8);
            app.FRulminEditField_8Label.Text = 'FR(ul/min)';
            app.FRulminEditField_8 = uieditfield(app.PanelSPS01_8, 'numeric');
            app.FRulminEditField_8.Tag = 'FlowRate';
            app.AddrEditField_8Label = uilabel(app.Panel_8);
            app.AddrEditField_8Label.Text = 'Addr';
            app.AddrEditField_8 = uieditfield(app.Panel_8, 'numeric');
            app.AddrEditField_8.Tag = 'address';
            app.AddrEditField_8.Editable = 'off';
            app.ClassEditField_8Label = uilabel(app.Panel_8);
            app.ClassEditField_8Label.Text = 'Class';
            app.ClassEditField_8 = uieditfield(app.Panel_8, 'text');
            app.ClassEditField_8.Tag = 'class';
            app.ClassEditField_8.Editable = 'off';
            app.Lamp_8 = uilamp(app.Panel_8);
            app.ChangeButton_8 = uibutton(app.Panel_8, 'push');
            app.ChangeButton_8.ButtonPushedFcn = createCallbackFcn(app, @ChangeButton_8Pushed, true);
            app.ChangeButton_8.Text = 'Change';
            app.FlashButton_8 = uibutton(app.Panel_8, 'push');
            app.FlashButton_8.ButtonPushedFcn = createCallbackFcn(app, @FlashButton_8Pushed, true);
            app.FlashButton_8.Text = 'Flash';
            app.PanelC4VM_8 = uipanel(app.Panel_8);
            app.PanelC4VM_8.Visible = 'off';
            app.PanelC4VM_8.Tag = 'mpanel';
            app.Valve2EditField_8Label = uilabel(app.PanelC4VM_8);
            app.Valve2EditField_8Label.Text = 'Valve 2';
            app.Valve2EditField_8 = uieditfield(app.PanelC4VM_8, 'numeric');
            app.Valve2EditField_8.Tag = 'valve2';
            app.Valve3EditField_8Label = uilabel(app.PanelC4VM_8);
            app.Valve3EditField_8Label.Text = 'Valve 3';
            app.Valve3EditField_8 = uieditfield(app.PanelC4VM_8, 'numeric');
            app.Valve3EditField_8.Tag = 'valve3';
            app.Valve4EditField_8Label = uilabel(app.PanelC4VM_8);
            app.Valve4EditField_8Label.Text = 'Valve 4';
            app.Valve4EditField_8 = uieditfield(app.PanelC4VM_8, 'numeric');
            app.Valve4EditField_8.Tag = 'valve4';
            app.Valve1EditField_8Label = uilabel(app.PanelC4VM_8);
            app.Valve1EditField_8Label.Text = 'Valve 1';
            app.Valve1EditField_8 = uieditfield(app.PanelC4VM_8, 'numeric');
            app.Valve1EditField_8.Tag = 'valve1';
            app.SwitchButton_8 = uibutton(app.PanelC4VM_8, 'push');
            app.SwitchButton_8.ButtonPushedFcn = createCallbackFcn(app, @SwitchButton_8Pushed, true);
            app.SwitchButton_8.Text = 'Switch';
            app.nochange1A2closed3BLabel_8 = uilabel(app.PanelC4VM_8);
            app.nochange1A2closed3BLabel_8.Text = {'0 - no change'; '1 - A'; '2 - closed'; '3 - B'};
            app.NameEditField_8 = uieditfield(app.Panel_8, 'text');
            app.NameEditField_8.Tag = 'name';
            app.NewNameEditField_8Label = uilabel(app.Panel_8);
            app.NewNameEditField_8Label.Text = 'New Name:';
            app.NewNameEditField_8 = uieditfield(app.Panel_8, 'text');
            app.NewNameEditField_8.Tag = 'nwname';
            app.StopButton_8 = uibutton(app.Panel_8, 'push');
            app.StopButton_8.ButtonPushedFcn = createCallbackFcn(app, @StopButton_8Pushed, true);
            app.StopButton_8.Text = 'Stop';
            app.ValveCheckPanel = uipanel(app.UIFigure);
            app.ValveCheckPanel.Title = 'Valve Check';
            app.ValveCheckPanel.Tag = 'VCpanel';
            app.SwitchValvesButton = uibutton(app.ValveCheckPanel, 'push');
            app.SwitchValvesButton.ButtonPushedFcn = createCallbackFcn(app, @SwitchValvesButtonPushed, true);
            app.SwitchValvesButton.Text = 'Switch Valves';
            app.IterationEditFieldLabel = uilabel(app.ValveCheckPanel);
            app.IterationEditFieldLabel.Text = 'Iteration';
            app.IterationEditField = uieditfield(app.ValveCheckPanel, 'numeric');
            app.StopButton_ValveCheck = uibutton(app.ValveCheckPanel, 'push');
            app.StopButton_ValveCheck.ButtonPushedFcn = createCallbackFcn(app, @StopButton_ValveCheckPushed, true);
            app.StopButton_ValveCheck.Text = 'Stop';
            app.ScriptsPanel = uipanel(app.UIFigure);
            app.ScriptsPanel.Title = 'Scripts';
            app.StartButton = uibutton(app.ScriptsPanel, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.StopButton_Script = uibutton(app.ScriptsPanel, 'push');
            app.StopButton_Script.ButtonPushedFcn = createCallbackFcn(app, @StopButton_ScriptPushed, true);
            app.StopButton_Script.Text = 'Stop';
            app.SearchButton = uibutton(app.ScriptsPanel, 'push');
            app.SearchButton.ButtonPushedFcn = createCallbackFcn(app, @SearchButtonPushed, true);
            app.SearchButton.Text = 'Search';
            app.FileDropDown = uidropdown(app.ScriptsPanel);
            app.FileDropDown.Items = {};
            app.FileDropDown.Value = {};
            app.ShowButton = uibutton(app.ScriptsPanel, 'push');
            app.ShowButton.ButtonPushedFcn = createCallbackFcn(app, @ShowButtonPushed, true);
            app.ShowButton.Text = 'Show';
            app.ConnectionPanel = uipanel(app.UIFigure);
            app.ConnectionPanel.Title = 'Connection';
            app.ConnectButton = uibutton(app.ConnectionPanel, 'push');
            app.ConnectButton.ButtonPushedFcn = createCallbackFcn(app, @ConnectButtonPushed, true);
            app.ConnectButton.Text = 'Connect';
            app.LoadBoardButton = uibutton(app.ConnectionPanel, 'push');
            app.LoadBoardButton.ButtonPushedFcn = createCallbackFcn(app, @LoadBoardButtonPushed, true);
            app.LoadBoardButton.Text = 'Load Board';
            app.COMPORTEditFieldLabel = uilabel(app.ConnectionPanel);
            app.COMPORTEditFieldLabel.Text = 'COM PORT';
            app.COMPORTEditField = uieditfield(app.ConnectionPanel, 'numeric');
            app.SyringesControlPanel = uipanel(app.UIFigure);
            app.SyringesControlPanel.Title = 'Syringes Control';
            app.SyringesControlPanel.Tag = 'SGpanel';
            app.FillingButton = uibutton(app.SyringesControlPanel, 'push');
            app.FillingButton.Text = 'Filling';
            app.RunBufferButton = uibutton(app.SyringesControlPanel, 'push');
            app.RunBufferButton.Text = 'Run Buffer';
            app.FRulminEditFieldLabel = uilabel(app.SyringesControlPanel);
            app.FRulminEditFieldLabel.Text = 'FR (ul/min)';
            app.FRulminEditField = uieditfield(app.SyringesControlPanel, 'numeric');
            app.FullAutocalibrationButton = uibutton(app.SyringesControlPanel, 'push');
            app.FullAutocalibrationButton.ButtonPushedFcn = createCallbackFcn(app, @FullAutocalibrationButtonPushed, true);
            app.FullAutocalibrationButton.Text = 'Full Autocalibration';
            app.StopButton_SyringeControl = uibutton(app.SyringesControlPanel, 'push');
            app.StopButton_SyringeControl.ButtonPushedFcn = createCallbackFcn(app, @StopButton_SyringeControlPushed, true);
            app.StopButton_SyringeControl.Text = 'Stop';
            app.DisconnectionPanel = uipanel(app.UIFigure);
            app.DisconnectionPanel.Title = 'Disconnection';
            app.DisconnectButton = uibutton(app.DisconnectionPanel, 'push');
            app.DisconnectButton.ButtonPushedFcn = createCallbackFcn(app, @DisconnectButtonPushed, true);
            app.DisconnectButton.Text = 'Disconnect';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Labsmith_Interface_Stop_NewWindow

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end