classdef appCombined_diffTab < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        TabGroup2                   matlab.ui.container.TabGroup
        LacewingTab                 matlab.ui.container.Tab
        LacewingPanel               matlab.ui.container.Panel
        StopExpButton               matlab.ui.control.Button
        ExpNameEditField            matlab.ui.control.EditField
        ExpNameEditFieldLabel       matlab.ui.control.Label
        REIDEditField               matlab.ui.control.EditField
        REIDEditFieldLabel          matlab.ui.control.Label
        ChipIDEditField             matlab.ui.control.EditField
        ChipIDEditFieldLabel        matlab.ui.control.Label
        ExperimentTimerButtonGroup  matlab.ui.container.ButtonGroup
        timerunit                   matlab.ui.control.DropDown
        timer                       matlab.ui.control.NumericEditField
        YesButton                   matlab.ui.control.RadioButton
        NoButton                    matlab.ui.control.RadioButton
        RunButton                   matlab.ui.control.Button
        TabGroup                    matlab.ui.container.TabGroup
        ArrayTab                    matlab.ui.container.Tab
        UIAxes_2                    matlab.ui.control.UIAxes
        PixelStatusTab              matlab.ui.container.Tab
        UIAxes                      matlab.ui.control.UIAxes
        VrefVEditField              matlab.ui.control.NumericEditField
        VrefVEditFieldLabel         matlab.ui.control.Label
        CalibrationButton           matlab.ui.control.Button
        CheckChipButton             matlab.ui.control.Button
        RefreshButton               matlab.ui.control.Button
        DisconnectButton_2          matlab.ui.control.Button
        ConnectButton_2             matlab.ui.control.Button
        NameDropDown                matlab.ui.control.DropDown
        NameDropDownLabel           matlab.ui.control.Label
        UIAxes_3                    matlab.ui.control.UIAxes
        LabsmithTab                 matlab.ui.container.Tab
        ScriptsPanel                matlab.ui.container.Panel
        NewButton                   matlab.ui.control.Button
        StartScriptButton           matlab.ui.control.Button
        ResumeButton                matlab.ui.control.Button
        PauseButton                 matlab.ui.control.Button
        ResetButton                 matlab.ui.control.Button
        StopScriptButton            matlab.ui.control.Button
        ShowButton                  matlab.ui.control.Button
        FileDropDown                matlab.ui.control.DropDown
        SearchButton                matlab.ui.control.Button
        DeviceControlPanel          matlab.ui.container.Panel
        Panel_8                     matlab.ui.container.Panel
        StopButton_8                matlab.ui.control.Button
        NewNameEditField_8          matlab.ui.control.EditField
        NewNameEditField_8Label     matlab.ui.control.Label
        NameEditField_8             matlab.ui.control.EditField
        FlashButton_8               matlab.ui.control.Button
        ChangeButton_8              matlab.ui.control.Button
        Lamp_8                      matlab.ui.control.Lamp
        ClassEditField_8            matlab.ui.control.EditField
        ClassEditField_8Label       matlab.ui.control.Label
        AddrEditField_8             matlab.ui.control.NumericEditField
        AddrEditField_8Label        matlab.ui.control.Label
        PanelSPS01_8                matlab.ui.container.Panel
        FRulminEditField_8          matlab.ui.control.NumericEditField
        FRulminEditField_8Label     matlab.ui.control.Label
        DiammmEditField_8           matlab.ui.control.NumericEditField
        DiammmEditField_8Label      matlab.ui.control.Label
        EditField_8                 matlab.ui.control.EditField
        AutoCalibrationButton_8     matlab.ui.control.Button
        MaxVulEditField_8           matlab.ui.control.NumericEditField
        MaxVulEditField_8Label      matlab.ui.control.Label
        VfulEditField_8             matlab.ui.control.NumericEditField
        VfulEditField_8Label        matlab.ui.control.Label
        MoveToButton_8              matlab.ui.control.Button
        VulEditField_8              matlab.ui.control.NumericEditField
        VulEditField_8Label         matlab.ui.control.Label
        UpdateButton_8              matlab.ui.control.Button
        MaxFRulminEditField_8       matlab.ui.control.NumericEditField
        MaxFRulminEditField_8Label  matlab.ui.control.Label
        MinFRulminEditField_8       matlab.ui.control.NumericEditField
        MinFRulminEditField_8Label  matlab.ui.control.Label
        PanelC4VM_8                 matlab.ui.container.Panel
        nochange1A2closed3BLabel_8  matlab.ui.control.Label
        SwitchButton_8              matlab.ui.control.Button
        Valve1EditField_8           matlab.ui.control.NumericEditField
        Valve1EditField_8Label      matlab.ui.control.Label
        Valve4EditField_8           matlab.ui.control.NumericEditField
        Valve4EditField_8Label      matlab.ui.control.Label
        Valve3EditField_8           matlab.ui.control.NumericEditField
        Valve3EditField_8Label      matlab.ui.control.Label
        Valve2EditField_8           matlab.ui.control.NumericEditField
        Valve2EditField_8Label      matlab.ui.control.Label
        Panel_7                     matlab.ui.container.Panel
        StopButton_7                matlab.ui.control.Button
        NewNameEditField_7          matlab.ui.control.EditField
        NewNameEditField_7Label     matlab.ui.control.Label
        NameEditField_7             matlab.ui.control.EditField
        FlashButton_7               matlab.ui.control.Button
        ChangeButton_7              matlab.ui.control.Button
        Lamp_7                      matlab.ui.control.Lamp
        ClassEditField_7            matlab.ui.control.EditField
        ClassEditField_7Label       matlab.ui.control.Label
        AddrEditField_7             matlab.ui.control.NumericEditField
        AddrEditField_7Label        matlab.ui.control.Label
        PanelSPS01_7                matlab.ui.container.Panel
        FRulminEditField_7          matlab.ui.control.NumericEditField
        FRulminEditField_7Label     matlab.ui.control.Label
        DiammmEditField_7           matlab.ui.control.NumericEditField
        DiammmEditField_7Label      matlab.ui.control.Label
        EditField_7                 matlab.ui.control.EditField
        AutoCalibrationButton_7     matlab.ui.control.Button
        MaxVulEditField_7           matlab.ui.control.NumericEditField
        MaxVulEditField_7Label      matlab.ui.control.Label
        VfulEditField_7             matlab.ui.control.NumericEditField
        VfulEditField_7Label        matlab.ui.control.Label
        MoveToButton_7              matlab.ui.control.Button
        VulEditField_7              matlab.ui.control.NumericEditField
        VulEditField_7Label         matlab.ui.control.Label
        UpdateButton_7              matlab.ui.control.Button
        MaxFRulminEditField_7       matlab.ui.control.NumericEditField
        MaxFRulminEditField_7Label  matlab.ui.control.Label
        MinFRulminEditField_7       matlab.ui.control.NumericEditField
        MinFRulminEditField_7Label  matlab.ui.control.Label
        PanelC4VM_7                 matlab.ui.container.Panel
        nochange1A2closed3BLabel_7  matlab.ui.control.Label
        SwitchButton_7              matlab.ui.control.Button
        Valve1EditField_7           matlab.ui.control.NumericEditField
        Valve1EditField_7Label      matlab.ui.control.Label
        Valve4EditField_7           matlab.ui.control.NumericEditField
        Valve4EditField_7Label      matlab.ui.control.Label
        Valve3EditField_7           matlab.ui.control.NumericEditField
        Valve3EditField_7Label      matlab.ui.control.Label
        Valve2EditField_7           matlab.ui.control.NumericEditField
        Valve2EditField_7Label      matlab.ui.control.Label
        Panel_6                     matlab.ui.container.Panel
        StopButton_6                matlab.ui.control.Button
        NewNameEditField_6          matlab.ui.control.EditField
        NewNameEditField_6Label     matlab.ui.control.Label
        NameEditField_6             matlab.ui.control.EditField
        FlashButton_6               matlab.ui.control.Button
        ChangeButton_6              matlab.ui.control.Button
        Lamp_6                      matlab.ui.control.Lamp
        ClassEditField_6            matlab.ui.control.EditField
        ClassEditField_6Label       matlab.ui.control.Label
        AddrEditField_6             matlab.ui.control.NumericEditField
        AddrEditField_6Label        matlab.ui.control.Label
        PanelSPS01_6                matlab.ui.container.Panel
        FRulminEditField_6          matlab.ui.control.NumericEditField
        FRulminEditField_6Label     matlab.ui.control.Label
        DiammmEditField_6           matlab.ui.control.NumericEditField
        DiammmEditField_6Label      matlab.ui.control.Label
        EditField_6                 matlab.ui.control.EditField
        AutoCalibrationButton_6     matlab.ui.control.Button
        MaxVulEditField_6           matlab.ui.control.NumericEditField
        MaxVulEditField_6Label      matlab.ui.control.Label
        VfulEditField_6             matlab.ui.control.NumericEditField
        VfulEditField_6Label        matlab.ui.control.Label
        MoveToButton_6              matlab.ui.control.Button
        VulEditField_6              matlab.ui.control.NumericEditField
        VulEditField_6Label         matlab.ui.control.Label
        UpdateButton_6              matlab.ui.control.Button
        MaxFRulminEditField_6       matlab.ui.control.NumericEditField
        MaxFRulminEditField_6Label  matlab.ui.control.Label
        MinFRulminEditField_6       matlab.ui.control.NumericEditField
        MinFRulminEditField_6Label  matlab.ui.control.Label
        PanelC4VM_6                 matlab.ui.container.Panel
        nochange1A2closed3BLabel_6  matlab.ui.control.Label
        SwitchButton_6              matlab.ui.control.Button
        Valve1EditField_6           matlab.ui.control.NumericEditField
        Valve1EditField_6Label      matlab.ui.control.Label
        Valve4EditField_6           matlab.ui.control.NumericEditField
        Valve4EditField_6Label      matlab.ui.control.Label
        Valve3EditField_6           matlab.ui.control.NumericEditField
        Valve3EditField_6Label      matlab.ui.control.Label
        Valve2EditField_6           matlab.ui.control.NumericEditField
        Valve2EditField_6Label      matlab.ui.control.Label
        Panel_5                     matlab.ui.container.Panel
        StopButton_5                matlab.ui.control.Button
        NewNameEditField_5          matlab.ui.control.EditField
        NewNameEditField_5Label     matlab.ui.control.Label
        NameEditField_5             matlab.ui.control.EditField
        FlashButton_5               matlab.ui.control.Button
        ChangeButton_5              matlab.ui.control.Button
        Lamp_5                      matlab.ui.control.Lamp
        ClassEditField_5            matlab.ui.control.EditField
        ClassEditField_5Label       matlab.ui.control.Label
        AddrEditField_5             matlab.ui.control.NumericEditField
        AddrEditField_5Label        matlab.ui.control.Label
        PanelSPS01_5                matlab.ui.container.Panel
        FRulminEditField_5          matlab.ui.control.NumericEditField
        FRulminEditField_5Label     matlab.ui.control.Label
        DiammmEditField_5           matlab.ui.control.NumericEditField
        DiammmEditField_5Label      matlab.ui.control.Label
        EditField_5                 matlab.ui.control.EditField
        AutoCalibrationButton_5     matlab.ui.control.Button
        MaxVulEditField_5           matlab.ui.control.NumericEditField
        MaxVulEditField_5Label      matlab.ui.control.Label
        VfulEditField_5             matlab.ui.control.NumericEditField
        VfulEditField_5Label        matlab.ui.control.Label
        MoveToButton_5              matlab.ui.control.Button
        VulEditField_5              matlab.ui.control.NumericEditField
        VulEditField_5Label         matlab.ui.control.Label
        UpdateButton_5              matlab.ui.control.Button
        MaxFRulminEditField_5       matlab.ui.control.NumericEditField
        MaxFRulminEditField_5Label  matlab.ui.control.Label
        MinFRulminEditField_5       matlab.ui.control.NumericEditField
        MinFRulminEditField_5Label  matlab.ui.control.Label
        PanelC4VM_5                 matlab.ui.container.Panel
        nochange1A2closed3BLabel_5  matlab.ui.control.Label
        SwitchButton_5              matlab.ui.control.Button
        Valve1EditField_5           matlab.ui.control.NumericEditField
        Valve1EditField_5Label      matlab.ui.control.Label
        Valve4EditField_5           matlab.ui.control.NumericEditField
        Valve4EditField_5Label      matlab.ui.control.Label
        Valve3EditField_5           matlab.ui.control.NumericEditField
        Valve3EditField_5Label      matlab.ui.control.Label
        Valve2EditField_5           matlab.ui.control.NumericEditField
        Valve2EditField_5Label      matlab.ui.control.Label
        Panel_4                     matlab.ui.container.Panel
        StopButton_4                matlab.ui.control.Button
        NewNameEditField_4          matlab.ui.control.EditField
        NewNameEditField_4Label     matlab.ui.control.Label
        NameEditField_4             matlab.ui.control.EditField
        FlashButton_4               matlab.ui.control.Button
        ChangeButton_4              matlab.ui.control.Button
        Lamp_4                      matlab.ui.control.Lamp
        ClassEditField_4            matlab.ui.control.EditField
        ClassEditField_4Label       matlab.ui.control.Label
        AddrEditField_4             matlab.ui.control.NumericEditField
        AddrEditField_4Label        matlab.ui.control.Label
        PanelSPS01_4                matlab.ui.container.Panel
        FRulminEditField_4          matlab.ui.control.NumericEditField
        FRulminEditField_4Label     matlab.ui.control.Label
        DiammmEditField_4           matlab.ui.control.NumericEditField
        DiammmEditField_4Label      matlab.ui.control.Label
        EditField_4                 matlab.ui.control.EditField
        AutoCalibrationButton_4     matlab.ui.control.Button
        MaxVulEditField_4           matlab.ui.control.NumericEditField
        MaxVulEditField_4Label      matlab.ui.control.Label
        VfulEditField_4             matlab.ui.control.NumericEditField
        VfulEditField_4Label        matlab.ui.control.Label
        MoveToButton_4              matlab.ui.control.Button
        VulEditField_4              matlab.ui.control.NumericEditField
        VulEditField_4Label         matlab.ui.control.Label
        UpdateButton_4              matlab.ui.control.Button
        MaxFRulminEditField_4       matlab.ui.control.NumericEditField
        MaxFRulminEditField_4Label  matlab.ui.control.Label
        MinFRulminEditField_4       matlab.ui.control.NumericEditField
        MinFRulminEditField_4Label  matlab.ui.control.Label
        PanelC4VM_4                 matlab.ui.container.Panel
        nochange1A2closed3BLabel_4  matlab.ui.control.Label
        SwitchButton_4              matlab.ui.control.Button
        Valve1EditField_4           matlab.ui.control.NumericEditField
        Valve1EditField_4Label      matlab.ui.control.Label
        Valve4EditField_4           matlab.ui.control.NumericEditField
        Valve4EditField_4Label      matlab.ui.control.Label
        Valve3EditField_4           matlab.ui.control.NumericEditField
        Valve3EditField_4Label      matlab.ui.control.Label
        Valve2EditField_4           matlab.ui.control.NumericEditField
        Valve2EditField_4Label      matlab.ui.control.Label
        Panel_3                     matlab.ui.container.Panel
        StopButton_3                matlab.ui.control.Button
        NewNameEditField_3          matlab.ui.control.EditField
        NewNameEditField_3Label     matlab.ui.control.Label
        NameEditField_3             matlab.ui.control.EditField
        FlashButton_3               matlab.ui.control.Button
        ChangeButton_3              matlab.ui.control.Button
        Lamp_3                      matlab.ui.control.Lamp
        ClassEditField_3            matlab.ui.control.EditField
        ClassEditField_3Label       matlab.ui.control.Label
        AddrEditField_3             matlab.ui.control.NumericEditField
        AddrEditField_3Label        matlab.ui.control.Label
        PanelSPS01_3                matlab.ui.container.Panel
        FRulminEditField_3          matlab.ui.control.NumericEditField
        FRulminEditField_3Label     matlab.ui.control.Label
        DiammmEditField_3           matlab.ui.control.NumericEditField
        DiammmEditField_3Label      matlab.ui.control.Label
        EditField_3                 matlab.ui.control.EditField
        AutoCalibrationButton_3     matlab.ui.control.Button
        MaxVulEditField_3           matlab.ui.control.NumericEditField
        MaxVulEditField_3Label      matlab.ui.control.Label
        VfulEditField_3             matlab.ui.control.NumericEditField
        VfulEditField_3Label        matlab.ui.control.Label
        MoveToButton_3              matlab.ui.control.Button
        VulEditField_3              matlab.ui.control.NumericEditField
        VulEditField_3Label         matlab.ui.control.Label
        UpdateButton_3              matlab.ui.control.Button
        MaxFRulminEditField_3       matlab.ui.control.NumericEditField
        MaxFRulminEditField_3Label  matlab.ui.control.Label
        MinFRulminEditField_3       matlab.ui.control.NumericEditField
        MinFRulminEditField_3Label  matlab.ui.control.Label
        PanelC4VM_3                 matlab.ui.container.Panel
        nochange1A2closed3BLabel_3  matlab.ui.control.Label
        SwitchButton_3              matlab.ui.control.Button
        Valve1EditField_3           matlab.ui.control.NumericEditField
        Valve1EditField_3Label      matlab.ui.control.Label
        Valve4EditField_3           matlab.ui.control.NumericEditField
        Valve4EditField_3Label      matlab.ui.control.Label
        Valve3EditField_3           matlab.ui.control.NumericEditField
        Valve3EditField_3Label      matlab.ui.control.Label
        Valve2EditField_3           matlab.ui.control.NumericEditField
        Valve2EditField_3Label      matlab.ui.control.Label
        Panel_2                     matlab.ui.container.Panel
        StopButton_2                matlab.ui.control.Button
        NewNameEditField_2          matlab.ui.control.EditField
        NewNameEditField_2Label     matlab.ui.control.Label
        NameEditField_2             matlab.ui.control.EditField
        Lamp_2                      matlab.ui.control.Lamp
        ChangeButton_2              matlab.ui.control.Button
        FlashButton_2               matlab.ui.control.Button
        ClassEditField_2            matlab.ui.control.EditField
        ClassEditField_2Label       matlab.ui.control.Label
        AddrEditField_2             matlab.ui.control.NumericEditField
        AddrEditField_2Label        matlab.ui.control.Label
        PanelSPS01_2                matlab.ui.container.Panel
        FRulminEditField_2          matlab.ui.control.NumericEditField
        FRulminEditField_2Label     matlab.ui.control.Label
        DiammmEditField_2           matlab.ui.control.NumericEditField
        DiammmEditField_2Label      matlab.ui.control.Label
        EditField_2                 matlab.ui.control.EditField
        AutoCalibrationButton_2     matlab.ui.control.Button
        MaxVulEditField_2           matlab.ui.control.NumericEditField
        MaxVulEditField_2Label      matlab.ui.control.Label
        VfulEditField_2             matlab.ui.control.NumericEditField
        VfulEditField_2Label        matlab.ui.control.Label
        MoveToButton_2              matlab.ui.control.Button
        VulEditField                matlab.ui.control.NumericEditField
        VulEditFieldLabel           matlab.ui.control.Label
        UpdateButton_2              matlab.ui.control.Button
        MaxFRulminEditField_2       matlab.ui.control.NumericEditField
        MaxFRulminEditField_2Label  matlab.ui.control.Label
        MinFRulminEditField_2       matlab.ui.control.NumericEditField
        MinFRulminEditField_2Label  matlab.ui.control.Label
        PanelC4VM_2                 matlab.ui.container.Panel
        nochange1A2closed3BLabel_2  matlab.ui.control.Label
        SwitchButton_2              matlab.ui.control.Button
        Valve1EditField_2           matlab.ui.control.NumericEditField
        Valve1EditField_2Label      matlab.ui.control.Label
        Valve4EditField_2           matlab.ui.control.NumericEditField
        Valve4EditField_2Label      matlab.ui.control.Label
        Valve3EditField_2           matlab.ui.control.NumericEditField
        Valve3EditField_2Label      matlab.ui.control.Label
        Valve2EditField_2           matlab.ui.control.NumericEditField
        Valve2EditField_2Label      matlab.ui.control.Label
        Panel_1                     matlab.ui.container.Panel
        StopButton_1                matlab.ui.control.Button
        NameEditField_1             matlab.ui.control.EditField
        ChangeButton_1              matlab.ui.control.Button
        NewNameEditField_1          matlab.ui.control.EditField
        NewNameEditFieldLabel       matlab.ui.control.Label
        FlashButton_1               matlab.ui.control.Button
        ClassEditField_1            matlab.ui.control.EditField
        ClassEditFieldLabel         matlab.ui.control.Label
        AddrEditField_1             matlab.ui.control.NumericEditField
        AddrEditFieldLabel          matlab.ui.control.Label
        Lamp_1                      matlab.ui.control.Lamp
        PanelSPS01                  matlab.ui.container.Panel
        FRulminEditField            matlab.ui.control.NumericEditField
        FRulminEditFieldLabel       matlab.ui.control.Label
        VfulEditField               matlab.ui.control.NumericEditField
        VfulEditFieldLabel          matlab.ui.control.Label
        VulEditField_2              matlab.ui.control.NumericEditField
        VulEditField_2Label         matlab.ui.control.Label
        MaxFRulminEditField         matlab.ui.control.NumericEditField
        MaxFRulminEditFieldLabel    matlab.ui.control.Label
        MinFRulminEditField         matlab.ui.control.NumericEditField
        MinFRulminEditFieldLabel    matlab.ui.control.Label
        DiammmEditField             matlab.ui.control.NumericEditField
        DiammmEditFieldLabel        matlab.ui.control.Label
        MoveToButton_1              matlab.ui.control.Button
        UpdateButton                matlab.ui.control.Button
        EditField                   matlab.ui.control.EditField
        AutoCalibrationButton_1     matlab.ui.control.Button
        MaxVulEditField             matlab.ui.control.NumericEditField
        MaxVulEditFieldLabel        matlab.ui.control.Label
        PanelC4VM                   matlab.ui.container.Panel
        nochange1A2closed3BLabel    matlab.ui.control.Label
        SwitchButton_1              matlab.ui.control.Button
        Valve1EditField_1           matlab.ui.control.NumericEditField
        Valve1EditFieldLabel        matlab.ui.control.Label
        Valve4EditField_1           matlab.ui.control.NumericEditField
        Valve4EditFieldLabel        matlab.ui.control.Label
        Valve3EditField_1           matlab.ui.control.NumericEditField
        Valve3EditFieldLabel        matlab.ui.control.Label
        Valve2EditField_1           matlab.ui.control.NumericEditField
        Valve2EditFieldLabel        matlab.ui.control.Label
        ConnectionPanel             matlab.ui.container.Panel
        COMPORTEditField            matlab.ui.control.NumericEditField
        COMPORTEditFieldLabel       matlab.ui.control.Label
        DisconnectButton            matlab.ui.control.Button
        ConnectButton               matlab.ui.control.Button
        OutputTextArea              matlab.ui.control.TextArea
        OutputTextAreaLabel         matlab.ui.control.Label
    end

    
    properties (Access = public)
        LI % Labsmith Interface
        LC %Lacewing
        panels % device panels of Labsmith board control interface window
        stop_status % it is 1 when you press the STOP Button
    end


    methods (Access = private)
        
        function EditPanel(app)
            index=0;
            %find index of first available panel
            for j=1:8 %max num device per board
                pstr=app.panels{j};
                nstr=findobj(pstr,'Tag','name');
                if isempty(nstr.Value)
                    index=j;
                    break
                end
            end            
            %assign value to each panel. Check first manifold and then syringes
            if size(app.LI.C4VM,2) ~= 0 % if there is any manifold
                for i=1:size(app.LI.C4VM,2) % for how many manifold there are
                    pstr=app.panels{index};   
                    nstr=findobj(pstr,'Tag','name');
                    cstr=findobj(pstr,'Tag','class');
                    astr=findobj(pstr,'Tag','address');  
                    mstr=findobj(pstr,'Tag','mpanel');
                    mstr.Visible='on';
                    nstr.Value=app.LI.C4VM{1,i}.name;
                    cstr.Value='C4VM';
                    astr.Value=app.LI.C4VM{1,i}.address;
                    index=index+1;
                end
            end
            if size(app.LI.SPS01,2) ~= 0 % if there is any syringe 
                for i=1:size(app.LI.SPS01,2) % for how many syringe there are
                    pstr=app.panels{index};   
                    nstr=findobj(pstr,'Tag','name');
                    cstr=findobj(pstr,'Tag','class');
                    astr=findobj(pstr,'Tag','address');
                    sstr=findobj(pstr,'Tag','spanel');
                    dstr=findobj(sstr,'Tag','diam');
                    mxstr=findobj(sstr,'Tag','maxFR');
                    mnstr=findobj(sstr,'Tag','minFR');
                    mxvstr=findobj(sstr,'Tag','maxV');
                    cvstr=findobj(sstr,'Tag','currentV');
                    sstr.Visible='on';
                    nstr.Value=app.LI.SPS01{1,i}.name;
                    cstr.Value='SPS01';
                    astr.Value=app.LI.SPS01{1,i}.address;
                    dstr.Value=app.LI.SPS01{1,i}.diameter;
                    mxstr.Value=app.LI.SPS01{1,i}.maxFlowrate;
                    mnstr.Value=app.LI.SPS01{1,i}.minFlowrate;
                    mxvstr.Value=app.LI.SPS01{1,i}.maxVolume;
                    cvstr.Value=app.LI.SPS01{1,i}.device.CmdGetVolume();
                    index=index+1;
                end
            end                 
        end

        
        function DeletePanel(app)
            for i=(app.LI.TotNumDev+1):8
                pstr=app.panels{i};
                pstr.Visible="off";                
            end
        end        
        
        function [index_s,index_m] = RecogniceDevice(app,index_panel)
            pstr=app.panels{index_panel};                
            nstr=findobj(pstr,'Tag','name');
            devicelabel=nstr.Value;
            index_s=[];
            index_m=[];
            for i=1:size(app.LI.SPS01,2) 
                if strcmp(devicelabel,app.LI.SPS01{1,i}.name) % (=1). 
                    index_s=i; % is empty if the device is a manifold
                    break
                end
            end
            for i=1:size(app.LI.C4VM,2)  
                if strcmp(devicelabel,app.LI.C4VM{1,i}.name) % (=1)
                    index_m=i; %is empty is the device is a syringe
                    break
                end
            end            
        end
        
        function Flash(app,index_s,index_m,lamp)
            if ~isempty(index_s)
                app.LI.SPS01{1,index_s}.device.CmdShowDevice(true);
                app.(lamp).Color='g';
                pause(2)
                app.LI.SPS01{1,index_s}.device.CmdShowDevice(false);
                app.(lamp).Color=[0.80,0.80,0.80];
                
            elseif ~isempty(index_m)
                app.LI.C4VM{1,index_m}.device.CmdShowDevice(true);
                app.(lamp).Color='g';
                pause(2)
                app.LI.C4VM{1,index_m}.device.CmdShowDevice(false);
                app.(lamp).Color=[0.80,0.80,0.80];
            end
            
        end
        
        function ChangeName(app,index_s,index_m,index_panel) 
            pstr=app.panels{index_panel};   
            nwstr=findobj(pstr,'Tag','nwname');
            label=nwstr.Value;           
            %Check first that the new name is not empty and that the is
            %not equal to any other devices'names             
            check=0; 
            if ~isempty(label)
                for i=1:app.LI.TotNumDev
                    pstr=app.panels{i};
                    nstr=findobj(pstr,'Tag','name');
                    if strcmp(label,nstr.Value) %(=1) they are the same
                        check=1;                   
                    end                     
                end  
            else %if the new name is empty
                check=1;
            end             
            %Change name to the right device
            if ~check %if check=0 continue with changing the name
                if ~isempty(index_s)
                    app.LI.SPS01{1,index_s}.device.CmdSetName(label);
                    app.LI.SPS01{1,index_s}.name=char(app.LI.SPS01{1,index_s}.device.GetName);
                elseif ~isempty(index_m)
                    app.LI.C4VM{1,index_m}.device.CmdSetName(label);
                    app.LI.C4VM{1,index_m}.name=char(app.LI.C4VM{1,index_m}.device.GetName);      
                end
                pstr=app.panels{index_panel};
                nstr=findobj(pstr,'Tag','name');
                nstr.Value=label;
                nwstr.Value='';        
            end            
        end
        
        function ManifoldSwitch(app,index_m,index_panel)
             pstr=app.panels{index_panel};
             v1str=findobj(pstr,'Tag','valve1');
             v2str=findobj(pstr,'Tag','valve2');
             v3str=findobj(pstr,'Tag','valve3');
             v4str=findobj(pstr,'Tag','valve4');             
             if ~isempty(index_m)
                app.LI.C4VM{1,index_m}.device.CmdSetValves(int8(v1str.Value),int8(v2str.Value),int8(v3str.Value),int8(v4str.Value));                
             end 
            
        end
        
        function AutoCalibration(app,index_s)      
            if ~isempty(index_s)
                app.LI.SPS01{1,index_s}.device.CmdAutoCal();
            end            
        end
        
        function UpdateVolume(app,index_s,index_panel)
            pstr=app.panels{index_panel};
            dstr=findobj(pstr,'Tag','diam');
            mxvstr=findobj(pstr,'Tag','maxV');
            cvstr=findobj(pstr,'Tag','currentV');
            cmstr=findobj(pstr,'Tag','comment'); 
            if ~isempty(index_s)
                mxvstr.Value=app.LI.SPS01{1,index_s}.device.GetMaxVolume();
                cvstr.Value=app.LI.SPS01{1,index_s}.device.CmdGetVolume(); 
                if dstr.Value == 1.458 %small
                    Vmaxnom=20;
                elseif dstr.Value > 3 %big (== 3.256 gives error!)
                    Vmaxnom=80;
                end
                if mxvstr.Value>(Vmaxnom-0.1*Vmaxnom) && mxvstr.Value<(Vmaxnom+0.1*Vmaxnom)
                   cmstr.Value="Calibrated";
                   cmstr.FontColor=[0.33,0.5,0.11];                   
                else
                   cmstr.Value="Out of Range";
                   cmstr.FontColor=[0.64,0.08,0.18];
                end
            end
        end


        function MoveToVolume(app,index_s,index_panel)
            app.stop_status=0;
            pstr=app.panels{index_panel};
            vfstr=findobj(pstr,'Tag','finalV'); 
            vcstr=findobj(pstr,'Tag','currentV');
            frstr=findobj(pstr,'Tag','FlowRate');
            mnstr=findobj(pstr,'Tag','minFR');
            mxstr=findobj(pstr,'Tag','maxFR');
            vmstr=findobj(pstr,'Tag','maxV');            
            if ~isempty(index_s)
                if frstr.Value>mnstr.Value && frstr.Value<mxstr.Value %check flow rate is in the right range
                    app.LI.SPS01{1,index_s}.device.CmdSetFlowrate(double(frstr.Value)); %set the flow rate
                    if vfstr.Value < vmstr.Value %check target volume is less than mx volume
                        app.LI.SPS01{1,index_s}.device.CmdMoveToVolume(double(vfstr.Value));
                        scan_rate=0.1; %the scan rate of the counter
                        target=((abs(vfstr.Value-vcstr.Value))/(frstr.Value))*60/scan_rate; %this is the final time of the counter. It is calculated based on the flow rate and the volume to pull/push
                        for i=1:target %this is a counter clock to check if the stop_status variable has changed
                            if app.stop_status==1
                                app.LI.SPS01{1,index_s}.device.CmdStop();
                                break
                            end
                            pause(scan_rate)
                        end
                    end
                end             
            end            
        end      

        
        function UpdateVolumeSyringeIsDone(app,index_s,index_panel)
            app.LI.SPS01{1,index_s}.device.CmdGetStatus();
            syr_status = app.LI.SPS01{1,index_s}.device.IsDone();            
            while syr_status == false
              app.LI.SPS01{1,index_s}.device.CmdGetStatus();
              syr_status = app.LI.SPS01{1,index_s}.device.IsDone();
            end
            UpdateVolume(app,index_s,index_panel);            
        end
    end   
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            fig = app.UIFigure;            
            Tb=findobj(fig,'Tag','tbgroup');
            t2=findobj(Tb,'Tag','tab2');
            ctrpnl=findobj(t2,'Tag','ctrpnl');
            p1=findobj(ctrpnl,'Tag','panel1');
            p2=findobj(ctrpnl,'Tag','panel2');
            p3=findobj(ctrpnl,'Tag','panel3');
            p4=findobj(ctrpnl,'Tag','panel4');
            p5=findobj(ctrpnl,'Tag','panel5');
            p6=findobj(ctrpnl,'Tag','panel6');
            p7=findobj(ctrpnl,'Tag','panel7');
            p8=findobj(ctrpnl,'Tag','panel8');
            app.panels={p1;p2;p3;p4;p5;p6;p7;p8};  
        end

        % Button pushed function: ConnectButton
        function ConnectButtonPushed(app, event)
            app.LI=LabsmithBoard(int8(app.COMPORTEditField.Value));
            text = fileread('OUTPUT');
            app.OutputTextArea.Value=convertStringsToChars(text);
            pause(0.1)
            scroll(app.OutputTextArea,'bottom')            
            if app.LI.isConnected == 1
                app.ConnectButton.BackgroundColor=[0.67,0.87,0.53];
                app.ConnectButton.Text='Connected';
                app.DisconnectButton.BackgroundColor=[0.96,0.96,0.96];
                app.DisconnectButton.Text='Disconnect'; 

                EditPanel(app);
                DeletePanel(app)
            end
        end

        % Button pushed function: DisconnectButton
        function DisconnectButtonPushed(app, event)
            com=Disconnect(app.LI);
            idx_lst_row=size(app.OutputTextArea.Value,1);
            for i=1:size(com,2)
                app.OutputTextArea.Value{idx_lst_row+i}=convertStringsToChars(com(i)); %I need to convert the string in char back.               
            end
            if app.LI.isDisconnected == 1
                app.ConnectButton.BackgroundColor=[0.96,0.96,0.96];
                app.ConnectButton.Text='Connect'; 
                app.DisconnectButton.BackgroundColor=[0.92,0.56,0.56];
                app.DisconnectButton.Text='Disconnected';
                %reset the panels
                for i=1:8 % for how many syringe there are
                    pstr=app.panels{i};   
                    nstr=findobj(pstr,'Tag','name');
                    cstr=findobj(pstr,'Tag','class');
                    astr=findobj(pstr,'Tag','address');
                    mstr=findobj(pstr,'Tag','mpanel');
                    sstr=findobj(pstr,'Tag','spanel');
                    dstr=findobj(sstr,'Tag','diam');
                    mxstr=findobj(sstr,'Tag','maxFR');
                    mnstr=findobj(sstr,'Tag','minFR');
                    mxvstr=findobj(sstr,'Tag','maxV');
                    cvstr=findobj(sstr,'Tag','currentV');
                    pstr.Visible="on";
                    sstr.Visible='off';
                    mstr.Visible='off';
                    nstr.Value='';
                    cstr.Value='';
                    astr.Value=0;
                    dstr.Value=0;
                    mxstr.Value=0;
                    mnstr.Value=0;
                    mxvstr.Value=0;
                    cvstr.Value=0;                    
                end
            end          
                       
        end

        % Callback function
        function MoveButtonPushed(app, event)
            SetFlowRate(app.LI,app.DeviceEditField.Value,app.FlowRateEditField.Value,app.DeviceEditField_2.Value,app.FlowRateEditField_2.Value,app.DeviceEditField_3.Value,app.FlowRateEditField_3.Value,app.DeviceEditField_4.Value,app.FlowRateEditField_4.Value)
            MulMove2(app.LI,app.DeviceEditField.Value,app.VolumeEditField.Value,app.DeviceEditField_2.Value,app.VolumeEditField_2.Value,app.DeviceEditField_3.Value,app.VolumeEditField_3.Value,app.DeviceEditField_4.Value,app.VolumeEditField_4.Value)
        end

        % Button pushed function: StopScriptButton
        function StopScriptButtonPushed(app, event)
            app.LI.Stop = true;
            app.StartScriptButton.Enable = 'off';            
            app.ResumeButton.Enable = 'off';
            
            text = fileread('OUTPUT');
            app.OutputTextArea.Value=convertStringsToChars(text);
            pause(0.1)
            scroll(app.OutputTextArea,'bottom')

            for i=1:app.LI.TotNumDev
                [index_s,~] = RecogniceDevice(app,i);
                UpdateVolume(app,index_s,i);
            end
        end

        % Button pushed function: SearchButton
        function SearchButtonPushed(app, event)
            uigetdir('C:\','Select Script Folder');
            dir_struct= dir ('**/*.m');
            [sorted_names,~] = sortrows({dir_struct.name}');
            app.FileDropDown.Items=sorted_names;
        end

        % Button pushed function: ShowButton
        function ShowButtonPushed(app, event)
            if isempty(app.FileDropDown.Value) 
                diary on
                disp('ERROR : Select File first')
                diary off
            else
                open(app.FileDropDown.Value)
            end   
            
            text = fileread('OUTPUT');
            app.OutputTextArea.Value=convertStringsToChars(text);
            pause(0.1)
            scroll(app.OutputTextArea,'bottom')
        end

        % Button pushed function: StartScriptButton
        function StartScriptButtonPushed(app, event)
            if isempty(app.FileDropDown.Value) 
                diary on
                disp('ERROR : Select File first')
                diary off
            else
                run(app.FileDropDown.Value)
            end
            
            text = fileread('OUTPUT');
            app.OutputTextArea.Value=convertStringsToChars(text);
            pause(0.1)
            scroll(app.OutputTextArea,'bottom')
            
            for i=1:app.LI.TotNumDev
                [index_s,~] = RecogniceDevice(app,i);
                UpdateVolume(app,index_s,i);
            end

            
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            app.LI.Stop = false;
            app.LI.Pause = false;
            app.StartScriptButton.Enable = 'on';            
            app.PauseButton.Enable = 'on';
        end

        % Callback function
        function OutputEditFieldValueChanged(app, event)
%             value = app.OutputEditField.Value;
            fileID = fopen("OUTPUT");
            C = textscan(fileID,'%s');
            app.OutputEditField.Value = C{1};
            fclose(fileID);
        end

        % Button pushed function: PauseButton
        function PauseButtonPushed(app, event)
            app.LI.Pause = true;
            app.LI.Resume = false;
            app.ResumeButton.Enable = 'on';
            app.PauseButton.Enable = 'off';
            
            text = fileread('OUTPUT');
            app.OutputTextArea.Value=convertStringsToChars(text);
            pause(0.1)
            scroll(app.OutputTextArea,'bottom')

            for i=1:app.LI.TotNumDev
                [index_s,~] = RecogniceDevice(app,i);
                UpdateVolume(app,index_s,i);
            end
        end

        % Button pushed function: ResumeButton
        function ResumeButtonPushed(app, event)
            app.LI.Resume = true;
            app.LI.Pause = false;  
            app.PauseButton.Enable = 'on';
            app.ResumeButton.Enable = 'off';
            
            text = fileread('OUTPUT');
            app.OutputTextArea.Value=convertStringsToChars(text);
            pause(0.1)
            scroll(app.OutputTextArea,'bottom')
        end

        % Button pushed function: RefreshButton
        function RefreshButtonPushed(app, event)
            app.LC = Lacewing;
            [app.NameDropDown.Items , app.LC.port] = FindInfo(app.LC);
        end

        % Button pushed function: ConnectButton_2
        function ConnectButton_2Pushed(app, event)
            Connect(app.LC,app.LC.port);

            text = fileread('OUTPUT');
            app.OutputTextArea.Value=convertStringsToChars(text);
            pause(0.1)
            scroll(app.OutputTextArea,'bottom')

            app.ConnectButton_2.BackgroundColor=[0.67,0.87,0.53];
            app.ConnectButton_2.Text='Connected';

        end

        % Button pushed function: DisconnectButton_2
        function DisconnectButton_2Pushed(app, event)
            Disconnect(app.LC);
            
            text = fileread('OUTPUT');
            app.OutputTextArea.Value=convertStringsToChars(text);
            pause(0.1)
            scroll(app.OutputTextArea,'bottom')

            % Reset colour and text
            app.ConnectButton_2.BackgroundColor=[0.96,0.96,0.96];
            app.ConnectButton_2.Text='Connect';
            app.CheckChipButton.BackgroundColor=[0.96,0.96,0.96];
            app.CheckChipButton.Text='Check Chip';
            app.CalibrationButton.BackgroundColor=[0.96,0.96,0.96];
            app.CalibrationButton.Text='Calibration';
            app.DisconnectButton_2.BackgroundColor=[0.92,0.56,0.56];
            app.DisconnectButton_2.Text='Disconnected'; 
        end

        % Button pushed function: CheckChipButton
        function CheckChipButtonPushed(app, event)
            r=CheckChip(app.LC);

            text = fileread('OUTPUT');
            app.OutputTextArea.Value=convertStringsToChars(text);
            pause(0.1)
            scroll(app.OutputTextArea,'bottom')

            if r == 3
                app.CheckChipButton.BackgroundColor=[0.67,0.87,0.53];
                app.CheckChipButton.Text='Chip Ready';
            end
        end

        % Button pushed function: CalibrationButton
        function CalibrationButtonPushed(app, event)
            diary on
            disp ('Wait, chip is being calibrated...')
            diary off
            text = fileread('OUTPUT');
            app.OutputTextArea.Value=convertStringsToChars(text);
            pause(0.1)
            scroll(app.OutputTextArea,'bottom')
            
            app.VrefVEditField.Value = Calibration(app.LC);           

            text = fileread('OUTPUT');
            app.OutputTextArea.Value=convertStringsToChars(text);
            pause(0.1)
            scroll(app.OutputTextArea,'bottom')
            app.CalibrationButton.BackgroundColor=[0.67,0.87,0.53];
            app.CalibrationButton.Text='Calibrated';

            app.UIAxes.Visible='on';
            array_status=PixelStatus(app.LC);
            surf(app.UIAxes,flipud(reshape(array_status,app.LC.ROWS,app.LC.COLS))); 
            view(app.UIAxes,2);
            axis(app.UIAxes,'tight');
            title(app.UIAxes,'Pixels status');
            xlabel(app.UIAxes,'COLS')
            ylabel(app.UIAxes,'ROWS')
            colormap(app.UIAxes,summer(3))
            colorbar(app.UIAxes,'Ticks', [0,511,1023] , 'TickLabels',["too fast" "active" "too slow"])

            app.UIAxes_2.Visible='on';
            array_calibrated = CalibArray(app.LC);
            surf(app.UIAxes_2,flipud(reshape(array_calibrated,app.LC.ROWS,app.LC.COLS))); 
            view(app.UIAxes_2,2);
            axis(app.UIAxes_2,'tight');
            title(app.UIAxes_2,'Calibrated Pixels Array');
            xlabel(app.UIAxes_2,'COLS')
            ylabel(app.UIAxes_2,'ROWS')
            colorbar(app.UIAxes_2)
            
            
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            if app.NoButton.Value == 'on'
                RunOut(app.LC,7,'day')
            else
                RunOut(app.LC,app.timer.Value,app.timerunit.Value)
            end
        end

        % Button pushed function: FlashButton_1
        function FlashButton_1Pushed(app, event)
            [index_s,index_m] = RecogniceDevice(app,1);
            Flash(app,index_s,index_m,"Lamp_1");
        end

        % Button pushed function: FlashButton_2
        function FlashButton_2Pushed(app, event)
            [index_s,index_m] = RecogniceDevice(app,2);
            Flash(app,index_s,index_m,"Lamp_2");
        end

        % Button pushed function: FlashButton_3
        function FlashButton_3Pushed(app, event)
            [index_s,index_m] = RecogniceDevice(app,3);
            Flash(app,index_s,index_m,"Lamp_3");
        end

        % Button pushed function: FlashButton_4
        function FlashButton_4Pushed(app, event)
            [index_s,index_m] = RecogniceDevice(app,4);
            Flash(app,index_s,index_m,"Lamp_4");
        end

        % Button pushed function: FlashButton_5
        function FlashButton_5Pushed(app, event)
            [index_s,index_m] = RecogniceDevice(app,5);
            Flash(app,index_s,index_m,"Lamp_5");
        end

        % Button pushed function: FlashButton_6
        function FlashButton_6Pushed(app, event)
            [index_s,index_m] = RecogniceDevice(app,6);
            Flash(app,index_s,index_m,"Lamp_6");
        end

        % Button pushed function: FlashButton_7
        function FlashButton_7Pushed(app, event)
            [index_s,index_m] = RecogniceDevice(app,7);
            Flash(app,index_s,index_m,"Lamp_7");
        end

        % Button pushed function: FlashButton_8
        function FlashButton_8Pushed(app, event)
            [index_s,index_m] = RecogniceDevice(app,8);
            Flash(app,index_s,index_m,"Lamp_8");
        end

        % Button pushed function: ChangeButton_1
        function ChangeButton_1Pushed(app, event)
            [index_s,index_m] = RecogniceDevice(app,1);
            ChangeName(app,index_s,index_m,1);
        end

        % Button pushed function: ChangeButton_2
        function ChangeButton_2Pushed(app, event)
            [index_s,index_m] = RecogniceDevice(app,2);
            ChangeName(app,index_s,index_m,2);
        end

        % Button pushed function: ChangeButton_3
        function ChangeButton_3Pushed(app, event)
            [index_s,index_m] = RecogniceDevice(app,3);
            ChangeName(app,index_s,index_m,3);
        end

        % Button pushed function: ChangeButton_4
        function ChangeButton_4Pushed(app, event)
            [index_s,index_m] = RecogniceDevice(app,4);
            ChangeName(app,index_s,index_m,4);
        end

        % Button pushed function: ChangeButton_5
        function ChangeButton_5Pushed(app, event)
            [index_s,index_m] = RecogniceDevice(app,5);
            ChangeName(app,index_s,index_m,5);
        end

        % Button pushed function: ChangeButton_6
        function ChangeButton_6Pushed(app, event)
            [index_s,index_m] = RecogniceDevice(app,6);
            ChangeName(app,index_s,index_m,6);
        end

        % Button pushed function: ChangeButton_7
        function ChangeButton_7Pushed(app, event)
            [index_s,index_m] = RecogniceDevice(app,7);
            ChangeName(app,index_s,index_m,7);
        end

        % Button pushed function: ChangeButton_8
        function ChangeButton_8Pushed(app, event)
            [index_s,index_m] = RecogniceDevice(app,8);
            ChangeName(app,index_s,index_m,8);
        end

        % Button pushed function: SwitchButton_1
        function SwitchButton_1Pushed(app, event)
            [~,index_m] = RecogniceDevice(app,1);
            ManifoldSwitch(app,index_m,1);
        end

        % Button pushed function: SwitchButton_2
        function SwitchButton_2Pushed(app, event)
            [~,index_m] = RecogniceDevice(app,2);
            ManifoldSwitch(app,index_m,2);
        end

        % Button pushed function: SwitchButton_3
        function SwitchButton_3Pushed(app, event)
            [~,index_m] = RecogniceDevice(app,3);
            ManifoldSwitch(app,index_m,3);
        end

        % Button pushed function: SwitchButton_4
        function SwitchButton_4Pushed(app, event)
            [~,index_m] = RecogniceDevice(app,4);
            ManifoldSwitch(app,index_m,4);
        end

        % Button pushed function: SwitchButton_5
        function SwitchButton_5Pushed(app, event)
            [~,index_m] = RecogniceDevice(app,5);
            ManifoldSwitch(app,index_m,5);
        end

        % Button pushed function: SwitchButton_6
        function SwitchButton_6Pushed(app, event)
            [~,index_m] = RecogniceDevice(app,6);
            ManifoldSwitch(app,index_m,6);
        end

        % Button pushed function: SwitchButton_7
        function SwitchButton_7Pushed(app, event)
            [~,index_m] = RecogniceDevice(app,7);
            ManifoldSwitch(app,index_m,7);
        end

        % Button pushed function: SwitchButton_8
        function SwitchButton_8Pushed(app, event)
            [~,index_m] = RecogniceDevice(app,8);
            ManifoldSwitch(app,index_m,8);
        end

        % Button pushed function: AutoCalibrationButton_1
        function AutoCalibrationButton_1Pushed(app, event)
            [index_s,~] = RecogniceDevice(app,1);
            AutoCalibration(app,index_s);
            UpdateVolume(app,index_s,1)
        end

        % Button pushed function: AutoCalibrationButton_2
        function AutoCalibrationButton_2Pushed(app, event)
            [index_s,~] = RecogniceDevice(app,2);
            AutoCalibration(app,index_s);
            UpdateVolume(app,index_s,2)
        end

        % Button pushed function: AutoCalibrationButton_3
        function AutoCalibrationButton_3Pushed(app, event)
            [index_s,~] = RecogniceDevice(app,3);
            AutoCalibration(app,index_s);
            UpdateVolume(app,index_s,3)
        end

        % Button pushed function: AutoCalibrationButton_4
        function AutoCalibrationButton_4Pushed(app, event)
            [index_s,~] = RecogniceDevice(app,4);
            AutoCalibration(app,index_s);
            UpdateVolume(app,index_s,4)
        end

        % Button pushed function: AutoCalibrationButton_5
        function AutoCalibrationButton_5Pushed(app, event)
            [index_s,~] = RecogniceDevice(app,5);
            AutoCalibration(app,index_s);
            UpdateVolume(app,index_s,5)
        end

        % Button pushed function: AutoCalibrationButton_6
        function AutoCalibrationButton_6Pushed(app, event)
            [index_s,~] = RecogniceDevice(app,6);
            AutoCalibration(app,index_s);
            UpdateVolume(app,index_s,6)
        end

        % Button pushed function: AutoCalibrationButton_7
        function AutoCalibrationButton_7Pushed(app, event)
            [index_s,~] = RecogniceDevice(app,7);
            AutoCalibration(app,index_s);
            UpdateVolume(app,index_s,7)
        end

        % Button pushed function: AutoCalibrationButton_8
        function AutoCalibrationButton_8Pushed(app, event)
            [index_s,~] = RecogniceDevice(app,8);
            AutoCalibration(app,index_s);
            UpdateVolume(app,index_s,8)
        end

        % Button pushed function: MoveToButton_1
        function MoveToButton_1Pushed(app, event)
            [index_s,~] = RecogniceDevice(app,1);
            app.StopButton_1.Enable='on';
            MoveToVolume(app,index_s,1);
            UpdateVolumeSyringeIsDone(app,index_s,1);
            app.StopButton_1.Enable='off';
        end

        % Button pushed function: MoveToButton_2
        function MoveToButton_2Pushed(app, event)
            [index_s,~] = RecogniceDevice(app,2);
            app.StopButton_2.Enable='on';
            MoveToVolume(app,index_s,2);
            UpdateVolumeSyringeIsDone(app,index_s,2);
            app.StopButton_2.Enable='off';
        end

        % Button pushed function: MoveToButton_3
        function MoveToButton_3Pushed(app, event)
            [index_s,~] = RecogniceDevice(app,3);
            app.StopButton_3.Enable='on';
            MoveToVolume(app,index_s,3);
            UpdateVolumeSyringeIsDone(app,index_s,3);
            app.StopButton_3.Enable='off';
        end

        % Button pushed function: MoveToButton_4
        function MoveToButton_4Pushed(app, event)
            [index_s,~] = RecogniceDevice(app,4);
            app.StopButton_4.Enable='on';
            MoveToVolume(app,index_s,4);
            UpdateVolumeSyringeIsDone(app,index_s,4);
            app.StopButton_4.Enable='off';
        end

        % Button pushed function: MoveToButton_5
        function MoveToButton_5Pushed(app, event)
            [index_s,~] = RecogniceDevice(app,5);
            app.StopButton_5.Enable='on';
            MoveToVolume(app,index_s,5);
            UpdateVolumeSyringeIsDone(app,index_s,5);
            app.StopButton_5.Enable='off';
        end

        % Button pushed function: MoveToButton_6
        function MoveToButton_6Pushed(app, event)
            [index_s,~] = RecogniceDevice(app,6);
            app.StopButton_6.Enable='on';
            MoveToVolume(app,index_s,6);
            UpdateVolumeSyringeIsDone(app,index_s,6);
            app.StopButton_6.Enable='off';
        end

        % Button pushed function: MoveToButton_7
        function MoveToButton_7Pushed(app, event)
            [index_s,~] = RecogniceDevice(app,7);
            app.StopButton_7.Enable='on';
            MoveToVolume(app,index_s,7);
            UpdateVolumeSyringeIsDone(app,index_s,7);
            app.StopButton_7.Enable='off';
        end

        % Button pushed function: MoveToButton_8
        function MoveToButton_8Pushed(app, event)
            [index_s,~] = RecogniceDevice(app,8);
            app.StopButton_8.Enable='on';
            MoveToVolume(app,index_s,8);
            UpdateVolumeSyringeIsDone(app,index_s,8);
            app.StopButton_8.Enable='off';
        end

        % Button pushed function: StopButton_1
        function StopButton_1Pushed(app, event)
            app.stop_status=1;
            [index_s,~] = RecogniceDevice(app,1);
            UpdateVolume(app,index_s,1);
            app.StopButton_1.Enable='off';
        end

        % Button pushed function: StopButton_2
        function StopButton_2Pushed(app, event)
            app.stop_status=1;
            [index_s,~] = RecogniceDevice(app,2);
            UpdateVolume(app,index_s,2);
            app.StopButton_2.Enable='off';
        end

        % Button pushed function: StopButton_3
        function StopButton_3Pushed(app, event)
            app.stop_status=1;
            [index_s,~] = RecogniceDevice(app,3);
            UpdateVolume(app,index_s,3);
            app.StopButton_3.Enable='off';
        end

        % Button pushed function: StopButton_4
        function StopButton_4Pushed(app, event)
            app.stop_status=1;
            [index_s,~] = RecogniceDevice(app,4);
            UpdateVolume(app,index_s,4);
            app.StopButton_4.Enable='off';
        end

        % Button pushed function: StopButton_5
        function StopButton_5Pushed(app, event)
            app.stop_status=1;
            [index_s,~] = RecogniceDevice(app,5);
            UpdateVolume(app,index_s,5);
            app.StopButton_5.Enable='off';
        end

        % Button pushed function: StopButton_6
        function StopButton_6Pushed(app, event)
            app.stop_status=1;
            [index_s,~] = RecogniceDevice(app,6);
            UpdateVolume(app,index_s,6);
            app.StopButton_6.Enable='off';
        end

        % Button pushed function: StopButton_7
        function StopButton_7Pushed(app, event)
            app.stop_status=1;
            [index_s,~] = RecogniceDevice(app,7);
            UpdateVolume(app,index_s,7);
            app.StopButton_7.Enable='off';
        end

        % Button pushed function: StopButton_8
        function StopButton_8Pushed(app, event)
            app.stop_status=1;
            [index_s,~] = RecogniceDevice(app,8);
            UpdateVolume(app,index_s,8);
            app.StopButton_8.Enable='off';
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.OutputTextAreaLabel = uilabel(app.UIFigure);
            app.OutputTextAreaLabel.Text = 'Output';
            app.OutputTextArea = uitextarea(app.UIFigure);
            app.OutputTextArea.Tag = 'out';
            app.OutputTextArea.Editable = 'off';
            app.TabGroup2 = uitabgroup(app.UIFigure);
            app.TabGroup2.Tag = 'tbgroup';
            app.LacewingTab = uitab(app.TabGroup2);
            app.LacewingTab.Title = 'Lacewing';
            app.LacewingTab.Tag = 'tab1';
            app.LacewingPanel = uipanel(app.LacewingTab);
            app.LacewingPanel.Title = 'Lacewing';
            app.UIAxes_3 = uiaxes(app.LacewingPanel);
            title(app.UIAxes_3, 'Title')
            xlabel(app.UIAxes_3, 'X')
            ylabel(app.UIAxes_3, 'Y')
            zlabel(app.UIAxes_3, 'Z')
            app.NameDropDownLabel = uilabel(app.LacewingPanel);
            app.NameDropDownLabel.Text = 'Name';
            app.NameDropDown = uidropdown(app.LacewingPanel);
            app.NameDropDown.Items = {};
            app.NameDropDown.Value = {};
            app.ConnectButton_2 = uibutton(app.LacewingPanel, 'push');
            app.ConnectButton_2.ButtonPushedFcn = createCallbackFcn(app, @ConnectButton_2Pushed, true);
            app.ConnectButton_2.Text = 'Connect';
            app.DisconnectButton_2 = uibutton(app.LacewingPanel, 'push');
            app.DisconnectButton_2.ButtonPushedFcn = createCallbackFcn(app, @DisconnectButton_2Pushed, true);
            app.DisconnectButton_2.Text = 'Disconnect';
            app.RefreshButton = uibutton(app.LacewingPanel, 'push');
            app.RefreshButton.ButtonPushedFcn = createCallbackFcn(app, @RefreshButtonPushed, true);
            app.RefreshButton.Text = 'Refresh';
            app.CheckChipButton = uibutton(app.LacewingPanel, 'push');
            app.CheckChipButton.ButtonPushedFcn = createCallbackFcn(app, @CheckChipButtonPushed, true);
            app.CheckChipButton.Text = 'Check Chip';
            app.CalibrationButton = uibutton(app.LacewingPanel, 'push');
            app.CalibrationButton.ButtonPushedFcn = createCallbackFcn(app, @CalibrationButtonPushed, true);
            app.CalibrationButton.Text = 'Calibration';
            app.VrefVEditFieldLabel = uilabel(app.LacewingPanel);
            app.VrefVEditFieldLabel.Text = 'Vref (V)';
            app.VrefVEditField = uieditfield(app.LacewingPanel, 'numeric');
            app.TabGroup = uitabgroup(app.LacewingPanel);
            app.ArrayTab = uitab(app.TabGroup);
            app.ArrayTab.Title = 'Array';
            app.UIAxes_2 = uiaxes(app.ArrayTab);
            title(app.UIAxes_2, 'Title')
            xlabel(app.UIAxes_2, 'X')
            ylabel(app.UIAxes_2, 'Y')
            zlabel(app.UIAxes_2, 'Z')
            app.UIAxes_2.Visible = 'off';
            app.PixelStatusTab = uitab(app.TabGroup);
            app.PixelStatusTab.Title = 'Pixel Status';
            app.UIAxes = uiaxes(app.PixelStatusTab);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.Visible = 'off';
            app.RunButton = uibutton(app.LacewingPanel, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.ExperimentTimerButtonGroup = uibuttongroup(app.LacewingPanel);
            app.ExperimentTimerButtonGroup.Title = 'Experiment Timer';
            app.NoButton = uiradiobutton(app.ExperimentTimerButtonGroup);
            app.NoButton.Text = 'No';
            app.NoButton.Value = true;
            app.YesButton = uiradiobutton(app.ExperimentTimerButtonGroup);
            app.YesButton.Text = 'Yes';
            app.timer = uieditfield(app.ExperimentTimerButtonGroup, 'numeric');
            app.timerunit = uidropdown(app.ExperimentTimerButtonGroup);
            app.timerunit.Items = {'sec', 'min', 'hour', 'day'};
            app.timerunit.Value = 'sec';
            app.ChipIDEditFieldLabel = uilabel(app.LacewingPanel);
            app.ChipIDEditFieldLabel.Text = 'Chip ID';
            app.ChipIDEditField = uieditfield(app.LacewingPanel, 'text');
            app.REIDEditFieldLabel = uilabel(app.LacewingPanel);
            app.REIDEditFieldLabel.Text = 'RE ID';
            app.REIDEditField = uieditfield(app.LacewingPanel, 'text');
            app.ExpNameEditFieldLabel = uilabel(app.LacewingPanel);
            app.ExpNameEditFieldLabel.Text = 'Exp Name';
            app.ExpNameEditField = uieditfield(app.LacewingPanel, 'text');
            app.StopExpButton = uibutton(app.LacewingPanel, 'push');
            app.StopExpButton.Text = 'Stop Exp';
            app.LabsmithTab = uitab(app.TabGroup2);
            app.LabsmithTab.Title = 'Labsmith';
            app.LabsmithTab.Tag = 'tab2';
            app.ConnectionPanel = uipanel(app.LabsmithTab);
            app.ConnectionPanel.Title = 'Connection';
            app.ConnectButton = uibutton(app.ConnectionPanel, 'push');
            app.ConnectButton.ButtonPushedFcn = createCallbackFcn(app, @ConnectButtonPushed, true);
            app.ConnectButton.Text = 'Connect';
            app.DisconnectButton = uibutton(app.ConnectionPanel, 'push');
            app.DisconnectButton.ButtonPushedFcn = createCallbackFcn(app, @DisconnectButtonPushed, true);
            app.DisconnectButton.Text = 'Disconnect';
            app.COMPORTEditFieldLabel = uilabel(app.ConnectionPanel);
            app.COMPORTEditFieldLabel.Text = 'COM PORT';
            app.COMPORTEditField = uieditfield(app.ConnectionPanel, 'numeric');
            app.DeviceControlPanel = uipanel(app.LabsmithTab);
            app.DeviceControlPanel.Title = 'Device Control';
            app.DeviceControlPanel.Tag = 'ctrpnl';
            app.Panel_1 = uipanel(app.DeviceControlPanel);
            app.Panel_1.Tag = 'panel1';
            app.PanelC4VM = uipanel(app.Panel_1);
            app.PanelC4VM.Visible = 'off';
            app.PanelC4VM.Tag = 'mpanel';
            app.Valve2EditFieldLabel = uilabel(app.PanelC4VM);
            app.Valve2EditFieldLabel.Text = 'Valve 2';
            app.Valve2EditField_1 = uieditfield(app.PanelC4VM, 'numeric');
            app.Valve2EditField_1.Tag = 'valve2';
            app.Valve3EditFieldLabel = uilabel(app.PanelC4VM);
            app.Valve3EditFieldLabel.Text = 'Valve 3';
            app.Valve3EditField_1 = uieditfield(app.PanelC4VM, 'numeric');
            app.Valve3EditField_1.Tag = 'valve3';
            app.Valve4EditFieldLabel = uilabel(app.PanelC4VM);
            app.Valve4EditFieldLabel.Text = 'Valve 4';
            app.Valve4EditField_1 = uieditfield(app.PanelC4VM, 'numeric');
            app.Valve4EditField_1.Tag = 'valve4';
            app.Valve1EditFieldLabel = uilabel(app.PanelC4VM);
            app.Valve1EditFieldLabel.Text = 'Valve 1';
            app.Valve1EditField_1 = uieditfield(app.PanelC4VM, 'numeric');
            app.Valve1EditField_1.Tag = 'valve1';
            app.SwitchButton_1 = uibutton(app.PanelC4VM, 'push');
            app.SwitchButton_1.ButtonPushedFcn = createCallbackFcn(app, @SwitchButton_1Pushed, true);
            app.SwitchButton_1.Tag = 'switch1';
            app.SwitchButton_1.Text = 'Switch';
            app.nochange1A2closed3BLabel = uilabel(app.PanelC4VM);
            app.nochange1A2closed3BLabel.Text = {'0 - no change'; '1 - A'; '2 - closed'; '3 - B'};
            app.PanelSPS01 = uipanel(app.Panel_1);
            app.PanelSPS01.Visible = 'off';
            app.PanelSPS01.Tag = 'spanel';
            app.MaxVulEditFieldLabel = uilabel(app.PanelSPS01);
            app.MaxVulEditFieldLabel.Text = 'MaxV (ul)';
            app.MaxVulEditField = uieditfield(app.PanelSPS01, 'numeric');
            app.MaxVulEditField.Tag = 'maxV';
            app.MaxVulEditField.Editable = 'off';
            app.AutoCalibrationButton_1 = uibutton(app.PanelSPS01, 'push');
            app.AutoCalibrationButton_1.ButtonPushedFcn = createCallbackFcn(app, @AutoCalibrationButton_1Pushed, true);
            app.AutoCalibrationButton_1.Text = 'Auto Calibration';
            app.EditField = uieditfield(app.PanelSPS01, 'text');
            app.EditField.Tag = 'comment';
            app.EditField.Editable = 'off';
            app.UpdateButton = uibutton(app.PanelSPS01, 'push');
            app.UpdateButton.Text = 'Update';
            app.MoveToButton_1 = uibutton(app.PanelSPS01, 'push');
            app.MoveToButton_1.ButtonPushedFcn = createCallbackFcn(app, @MoveToButton_1Pushed, true);
            app.MoveToButton_1.Text = 'MoveTo';
            app.DiammmEditFieldLabel = uilabel(app.PanelSPS01);
            app.DiammmEditFieldLabel.Text = 'Diam (mm)';
            app.DiammmEditField = uieditfield(app.PanelSPS01, 'numeric');
            app.DiammmEditField.Tag = 'diam';
            app.DiammmEditField.Editable = 'off';
            app.MinFRulminEditFieldLabel = uilabel(app.PanelSPS01);
            app.MinFRulminEditFieldLabel.Text = 'MinFR (ul/min)';
            app.MinFRulminEditField = uieditfield(app.PanelSPS01, 'numeric');
            app.MinFRulminEditField.Tag = 'minFR';
            app.MinFRulminEditField.Editable = 'off';
            app.MaxFRulminEditFieldLabel = uilabel(app.PanelSPS01);
            app.MaxFRulminEditFieldLabel.Text = 'MaxFR (ul/min)';
            app.MaxFRulminEditField = uieditfield(app.PanelSPS01, 'numeric');
            app.MaxFRulminEditField.Tag = 'maxFR';
            app.MaxFRulminEditField.Editable = 'off';
            app.VulEditField_2Label = uilabel(app.PanelSPS01);
            app.VulEditField_2Label.Text = 'V (ul)';
            app.VulEditField_2 = uieditfield(app.PanelSPS01, 'numeric');
            app.VulEditField_2.Tag = 'currentV';
            app.VulEditField_2.Editable = 'off';
            app.VfulEditFieldLabel = uilabel(app.PanelSPS01);
            app.VfulEditFieldLabel.Text = 'Vf (ul)';
            app.VfulEditField = uieditfield(app.PanelSPS01, 'numeric');
            app.VfulEditField.Tag = 'finalV';
            app.FRulminEditFieldLabel = uilabel(app.PanelSPS01);
            app.FRulminEditFieldLabel.Text = 'FR(ul/min)';
            app.FRulminEditField = uieditfield(app.PanelSPS01, 'numeric');
            app.FRulminEditField.Tag = 'FlowRate';
            app.Lamp_1 = uilamp(app.Panel_1);
            app.Lamp_1.Tag = 'lamp1';
            app.AddrEditFieldLabel = uilabel(app.Panel_1);
            app.AddrEditFieldLabel.Text = 'Addr';
            app.AddrEditField_1 = uieditfield(app.Panel_1, 'numeric');
            app.AddrEditField_1.Tag = 'address';
            app.AddrEditField_1.Editable = 'off';
            app.ClassEditFieldLabel = uilabel(app.Panel_1);
            app.ClassEditFieldLabel.Text = 'Class';
            app.ClassEditField_1 = uieditfield(app.Panel_1, 'text');
            app.ClassEditField_1.Tag = 'class';
            app.ClassEditField_1.Editable = 'off';
            app.FlashButton_1 = uibutton(app.Panel_1, 'push');
            app.FlashButton_1.ButtonPushedFcn = createCallbackFcn(app, @FlashButton_1Pushed, true);
            app.FlashButton_1.Text = 'Flash';
            app.NewNameEditFieldLabel = uilabel(app.Panel_1);
            app.NewNameEditFieldLabel.Text = 'New Name:';
            app.NewNameEditField_1 = uieditfield(app.Panel_1, 'text');
            app.NewNameEditField_1.Tag = 'nwname';
            app.ChangeButton_1 = uibutton(app.Panel_1, 'push');
            app.ChangeButton_1.ButtonPushedFcn = createCallbackFcn(app, @ChangeButton_1Pushed, true);
            app.ChangeButton_1.Text = 'Change';
            app.NameEditField_1 = uieditfield(app.Panel_1, 'text');
            app.NameEditField_1.Tag = 'name';
            app.StopButton_1 = uibutton(app.Panel_1, 'push');
            app.StopButton_1.ButtonPushedFcn = createCallbackFcn(app, @StopButton_1Pushed, true);
            app.StopButton_1.Enable = 'off';
            app.StopButton_1.Text = 'Stop';
            app.Panel_2 = uipanel(app.DeviceControlPanel);
            app.Panel_2.Tag = 'panel2';
            app.PanelC4VM_2 = uipanel(app.Panel_2);
            app.PanelC4VM_2.Visible = 'off';
            app.PanelC4VM_2.Tag = 'mpanel';
            app.Valve2EditField_2Label = uilabel(app.PanelC4VM_2);
            app.Valve2EditField_2Label.Text = 'Valve 2';
            app.Valve2EditField_2 = uieditfield(app.PanelC4VM_2, 'numeric');
            app.Valve2EditField_2.Tag = 'valve2';
            app.Valve3EditField_2Label = uilabel(app.PanelC4VM_2);
            app.Valve3EditField_2Label.Text = 'Valve 3';
            app.Valve3EditField_2 = uieditfield(app.PanelC4VM_2, 'numeric');
            app.Valve3EditField_2.Tag = 'valve3';
            app.Valve4EditField_2Label = uilabel(app.PanelC4VM_2);
            app.Valve4EditField_2Label.Text = 'Valve 4';
            app.Valve4EditField_2 = uieditfield(app.PanelC4VM_2, 'numeric');
            app.Valve4EditField_2.Tag = 'valve4';
            app.Valve1EditField_2Label = uilabel(app.PanelC4VM_2);
            app.Valve1EditField_2Label.Text = 'Valve 1';
            app.Valve1EditField_2 = uieditfield(app.PanelC4VM_2, 'numeric');
            app.Valve1EditField_2.Tag = 'valve1';
            app.SwitchButton_2 = uibutton(app.PanelC4VM_2, 'push');
            app.SwitchButton_2.ButtonPushedFcn = createCallbackFcn(app, @SwitchButton_2Pushed, true);
            app.SwitchButton_2.Text = 'Switch';
            app.nochange1A2closed3BLabel_2 = uilabel(app.PanelC4VM_2);
            app.nochange1A2closed3BLabel_2.Text = {'0 - no change'; '1 - A'; '2 - closed'; '3 - B'};
            app.PanelSPS01_2 = uipanel(app.Panel_2);
            app.PanelSPS01_2.Visible = 'off';
            app.PanelSPS01_2.Tag = 'spanel';
            app.MinFRulminEditField_2Label = uilabel(app.PanelSPS01_2);
            app.MinFRulminEditField_2Label.Text = 'MinFR (ul/min)';
            app.MinFRulminEditField_2 = uieditfield(app.PanelSPS01_2, 'numeric');
            app.MinFRulminEditField_2.Tag = 'minFR';
            app.MinFRulminEditField_2.Editable = 'off';
            app.MaxFRulminEditField_2Label = uilabel(app.PanelSPS01_2);
            app.MaxFRulminEditField_2Label.Text = 'MaxFR (ul/min)';
            app.MaxFRulminEditField_2 = uieditfield(app.PanelSPS01_2, 'numeric');
            app.MaxFRulminEditField_2.Tag = 'maxFR';
            app.MaxFRulminEditField_2.Editable = 'off';
            app.UpdateButton_2 = uibutton(app.PanelSPS01_2, 'push');
            app.UpdateButton_2.Text = 'Update';
            app.VulEditFieldLabel = uilabel(app.PanelSPS01_2);
            app.VulEditFieldLabel.Text = 'V (ul)';
            app.VulEditField = uieditfield(app.PanelSPS01_2, 'numeric');
            app.VulEditField.Tag = 'currentV';
            app.VulEditField.Editable = 'off';
            app.MoveToButton_2 = uibutton(app.PanelSPS01_2, 'push');
            app.MoveToButton_2.ButtonPushedFcn = createCallbackFcn(app, @MoveToButton_2Pushed, true);
            app.MoveToButton_2.Text = 'MoveTo';
            app.VfulEditField_2Label = uilabel(app.PanelSPS01_2);
            app.VfulEditField_2Label.Text = 'Vf (ul)';
            app.VfulEditField_2 = uieditfield(app.PanelSPS01_2, 'numeric');
            app.VfulEditField_2.Tag = 'finalV';
            app.MaxVulEditField_2Label = uilabel(app.PanelSPS01_2);
            app.MaxVulEditField_2Label.Text = 'MaxV (ul)';
            app.MaxVulEditField_2 = uieditfield(app.PanelSPS01_2, 'numeric');
            app.MaxVulEditField_2.Tag = 'maxV';
            app.MaxVulEditField_2.Editable = 'off';
            app.AutoCalibrationButton_2 = uibutton(app.PanelSPS01_2, 'push');
            app.AutoCalibrationButton_2.ButtonPushedFcn = createCallbackFcn(app, @AutoCalibrationButton_2Pushed, true);
            app.AutoCalibrationButton_2.Text = 'Auto Calibration';
            app.EditField_2 = uieditfield(app.PanelSPS01_2, 'text');
            app.EditField_2.Tag = 'comment';
            app.EditField_2.Editable = 'off';
            app.DiammmEditField_2Label = uilabel(app.PanelSPS01_2);
            app.DiammmEditField_2Label.Text = 'Diam (mm)';
            app.DiammmEditField_2 = uieditfield(app.PanelSPS01_2, 'numeric');
            app.DiammmEditField_2.Tag = 'diam';
            app.DiammmEditField_2.Editable = 'off';
            app.FRulminEditField_2Label = uilabel(app.PanelSPS01_2);
            app.FRulminEditField_2Label.Text = 'FR(ul/min)';
            app.FRulminEditField_2 = uieditfield(app.PanelSPS01_2, 'numeric');
            app.FRulminEditField_2.Tag = 'FlowRate';
            app.AddrEditField_2Label = uilabel(app.Panel_2);
            app.AddrEditField_2Label.Text = 'Addr';
            app.AddrEditField_2 = uieditfield(app.Panel_2, 'numeric');
            app.AddrEditField_2.Tag = 'address';
            app.AddrEditField_2.Editable = 'off';
            app.ClassEditField_2Label = uilabel(app.Panel_2);
            app.ClassEditField_2Label.Text = 'Class';
            app.ClassEditField_2 = uieditfield(app.Panel_2, 'text');
            app.ClassEditField_2.Tag = 'class';
            app.ClassEditField_2.Editable = 'off';
            app.FlashButton_2 = uibutton(app.Panel_2, 'push');
            app.FlashButton_2.ButtonPushedFcn = createCallbackFcn(app, @FlashButton_2Pushed, true);
            app.FlashButton_2.Text = 'Flash';
            app.ChangeButton_2 = uibutton(app.Panel_2, 'push');
            app.ChangeButton_2.ButtonPushedFcn = createCallbackFcn(app, @ChangeButton_2Pushed, true);
            app.ChangeButton_2.Text = 'Change';
            app.Lamp_2 = uilamp(app.Panel_2);
            app.NameEditField_2 = uieditfield(app.Panel_2, 'text');
            app.NameEditField_2.Tag = 'name';
            app.NewNameEditField_2Label = uilabel(app.Panel_2);
            app.NewNameEditField_2Label.Text = 'New Name:';
            app.NewNameEditField_2 = uieditfield(app.Panel_2, 'text');
            app.NewNameEditField_2.Tag = 'nwname';
            app.StopButton_2 = uibutton(app.Panel_2, 'push');
            app.StopButton_2.ButtonPushedFcn = createCallbackFcn(app, @StopButton_2Pushed, true);
            app.StopButton_2.Enable = 'off';
            app.StopButton_2.Text = 'Stop';
            app.Panel_3 = uipanel(app.DeviceControlPanel);
            app.Panel_3.Tag = 'panel3';
            app.PanelC4VM_3 = uipanel(app.Panel_3);
            app.PanelC4VM_3.Visible = 'off';
            app.PanelC4VM_3.Tag = 'mpanel';
            app.Valve2EditField_3Label = uilabel(app.PanelC4VM_3);
            app.Valve2EditField_3Label.Text = 'Valve 2';
            app.Valve2EditField_3 = uieditfield(app.PanelC4VM_3, 'numeric');
            app.Valve2EditField_3.Tag = 'valve2';
            app.Valve3EditField_3Label = uilabel(app.PanelC4VM_3);
            app.Valve3EditField_3Label.Text = 'Valve 3';
            app.Valve3EditField_3 = uieditfield(app.PanelC4VM_3, 'numeric');
            app.Valve3EditField_3.Tag = 'valve3';
            app.Valve4EditField_3Label = uilabel(app.PanelC4VM_3);
            app.Valve4EditField_3Label.Text = 'Valve 4';
            app.Valve4EditField_3 = uieditfield(app.PanelC4VM_3, 'numeric');
            app.Valve4EditField_3.Tag = 'valve4';
            app.Valve1EditField_3Label = uilabel(app.PanelC4VM_3);
            app.Valve1EditField_3Label.Text = 'Valve 1';
            app.Valve1EditField_3 = uieditfield(app.PanelC4VM_3, 'numeric');
            app.Valve1EditField_3.Tag = 'valve1';
            app.SwitchButton_3 = uibutton(app.PanelC4VM_3, 'push');
            app.SwitchButton_3.ButtonPushedFcn = createCallbackFcn(app, @SwitchButton_3Pushed, true);
            app.SwitchButton_3.Text = 'Switch';
            app.nochange1A2closed3BLabel_3 = uilabel(app.PanelC4VM_3);
            app.nochange1A2closed3BLabel_3.Text = {'0 - no change'; '1 - A'; '2 - closed'; '3 - B'};
            app.PanelSPS01_3 = uipanel(app.Panel_3);
            app.PanelSPS01_3.Visible = 'off';
            app.PanelSPS01_3.Tag = 'spanel';
            app.MinFRulminEditField_3Label = uilabel(app.PanelSPS01_3);
            app.MinFRulminEditField_3Label.Text = 'MinFR (ul/min)';
            app.MinFRulminEditField_3 = uieditfield(app.PanelSPS01_3, 'numeric');
            app.MinFRulminEditField_3.Tag = 'minFR';
            app.MinFRulminEditField_3.Editable = 'off';
            app.MaxFRulminEditField_3Label = uilabel(app.PanelSPS01_3);
            app.MaxFRulminEditField_3Label.Text = 'MaxFR (ul/min)';
            app.MaxFRulminEditField_3 = uieditfield(app.PanelSPS01_3, 'numeric');
            app.MaxFRulminEditField_3.Tag = 'maxFR';
            app.MaxFRulminEditField_3.Editable = 'off';
            app.UpdateButton_3 = uibutton(app.PanelSPS01_3, 'push');
            app.UpdateButton_3.Text = 'Update';
            app.VulEditField_3Label = uilabel(app.PanelSPS01_3);
            app.VulEditField_3Label.Text = 'V (ul)';
            app.VulEditField_3 = uieditfield(app.PanelSPS01_3, 'numeric');
            app.VulEditField_3.Tag = 'currentV';
            app.VulEditField_3.Editable = 'off';
            app.MoveToButton_3 = uibutton(app.PanelSPS01_3, 'push');
            app.MoveToButton_3.ButtonPushedFcn = createCallbackFcn(app, @MoveToButton_3Pushed, true);
            app.MoveToButton_3.Text = 'MoveTo';
            app.VfulEditField_3Label = uilabel(app.PanelSPS01_3);
            app.VfulEditField_3Label.Text = 'Vf (ul)';
            app.VfulEditField_3 = uieditfield(app.PanelSPS01_3, 'numeric');
            app.VfulEditField_3.Tag = 'finalV';
            app.MaxVulEditField_3Label = uilabel(app.PanelSPS01_3);
            app.MaxVulEditField_3Label.Text = 'MaxV (ul)';
            app.MaxVulEditField_3 = uieditfield(app.PanelSPS01_3, 'numeric');
            app.MaxVulEditField_3.Tag = 'maxV';
            app.MaxVulEditField_3.Editable = 'off';
            app.AutoCalibrationButton_3 = uibutton(app.PanelSPS01_3, 'push');
            app.AutoCalibrationButton_3.ButtonPushedFcn = createCallbackFcn(app, @AutoCalibrationButton_3Pushed, true);
            app.AutoCalibrationButton_3.Text = 'Auto Calibration';
            app.EditField_3 = uieditfield(app.PanelSPS01_3, 'text');
            app.EditField_3.Tag = 'comment';
            app.EditField_3.Editable = 'off';
            app.DiammmEditField_3Label = uilabel(app.PanelSPS01_3);
            app.DiammmEditField_3Label.Text = 'Diam (mm)';
            app.DiammmEditField_3 = uieditfield(app.PanelSPS01_3, 'numeric');
            app.DiammmEditField_3.Tag = 'diam';
            app.DiammmEditField_3.Editable = 'off';
            app.FRulminEditField_3Label = uilabel(app.PanelSPS01_3);
            app.FRulminEditField_3Label.Text = 'FR(ul/min)';
            app.FRulminEditField_3 = uieditfield(app.PanelSPS01_3, 'numeric');
            app.FRulminEditField_3.Tag = 'FlowRate';
            app.AddrEditField_3Label = uilabel(app.Panel_3);
            app.AddrEditField_3Label.Text = 'Addr';
            app.AddrEditField_3 = uieditfield(app.Panel_3, 'numeric');
            app.AddrEditField_3.Tag = 'address';
            app.AddrEditField_3.Editable = 'off';
            app.ClassEditField_3Label = uilabel(app.Panel_3);
            app.ClassEditField_3Label.Text = 'Class';
            app.ClassEditField_3 = uieditfield(app.Panel_3, 'text');
            app.ClassEditField_3.Tag = 'class';
            app.ClassEditField_3.Editable = 'off';
            app.Lamp_3 = uilamp(app.Panel_3);
            app.ChangeButton_3 = uibutton(app.Panel_3, 'push');
            app.ChangeButton_3.ButtonPushedFcn = createCallbackFcn(app, @ChangeButton_3Pushed, true);
            app.ChangeButton_3.Text = 'Change';
            app.FlashButton_3 = uibutton(app.Panel_3, 'push');
            app.FlashButton_3.ButtonPushedFcn = createCallbackFcn(app, @FlashButton_3Pushed, true);
            app.FlashButton_3.Text = 'Flash';
            app.NameEditField_3 = uieditfield(app.Panel_3, 'text');
            app.NameEditField_3.Tag = 'name';
            app.NewNameEditField_3Label = uilabel(app.Panel_3);
            app.NewNameEditField_3Label.Text = 'New Name:';
            app.NewNameEditField_3 = uieditfield(app.Panel_3, 'text');
            app.NewNameEditField_3.Tag = 'nwname';
            app.StopButton_3 = uibutton(app.Panel_3, 'push');
            app.StopButton_3.ButtonPushedFcn = createCallbackFcn(app, @StopButton_3Pushed, true);
            app.StopButton_3.Enable = 'off';
            app.StopButton_3.Text = 'Stop';
            app.Panel_4 = uipanel(app.DeviceControlPanel);
            app.Panel_4.Tag = 'panel4';
            app.PanelC4VM_4 = uipanel(app.Panel_4);
            app.PanelC4VM_4.Visible = 'off';
            app.PanelC4VM_4.Tag = 'mpanel';
            app.Valve2EditField_4Label = uilabel(app.PanelC4VM_4);
            app.Valve2EditField_4Label.Text = 'Valve 2';
            app.Valve2EditField_4 = uieditfield(app.PanelC4VM_4, 'numeric');
            app.Valve2EditField_4.Tag = 'valve2';
            app.Valve3EditField_4Label = uilabel(app.PanelC4VM_4);
            app.Valve3EditField_4Label.Text = 'Valve 3';
            app.Valve3EditField_4 = uieditfield(app.PanelC4VM_4, 'numeric');
            app.Valve3EditField_4.Tag = 'valve3';
            app.Valve4EditField_4Label = uilabel(app.PanelC4VM_4);
            app.Valve4EditField_4Label.Text = 'Valve 4';
            app.Valve4EditField_4 = uieditfield(app.PanelC4VM_4, 'numeric');
            app.Valve4EditField_4.Tag = 'valve4';
            app.Valve1EditField_4Label = uilabel(app.PanelC4VM_4);
            app.Valve1EditField_4Label.Text = 'Valve 1';
            app.Valve1EditField_4 = uieditfield(app.PanelC4VM_4, 'numeric');
            app.Valve1EditField_4.Tag = 'valve1';
            app.SwitchButton_4 = uibutton(app.PanelC4VM_4, 'push');
            app.SwitchButton_4.ButtonPushedFcn = createCallbackFcn(app, @SwitchButton_4Pushed, true);
            app.SwitchButton_4.Text = 'Switch';
            app.nochange1A2closed3BLabel_4 = uilabel(app.PanelC4VM_4);
            app.nochange1A2closed3BLabel_4.Text = {'0 - no change'; '1 - A'; '2 - closed'; '3 - B'};
            app.PanelSPS01_4 = uipanel(app.Panel_4);
            app.PanelSPS01_4.Visible = 'off';
            app.PanelSPS01_4.Tag = 'spanel';
            app.MinFRulminEditField_4Label = uilabel(app.PanelSPS01_4);
            app.MinFRulminEditField_4Label.Text = 'MinFR (ul/min)';
            app.MinFRulminEditField_4 = uieditfield(app.PanelSPS01_4, 'numeric');
            app.MinFRulminEditField_4.Tag = 'minFR';
            app.MinFRulminEditField_4.Editable = 'off';
            app.MaxFRulminEditField_4Label = uilabel(app.PanelSPS01_4);
            app.MaxFRulminEditField_4Label.Text = 'MaxFR (ul/min)';
            app.MaxFRulminEditField_4 = uieditfield(app.PanelSPS01_4, 'numeric');
            app.MaxFRulminEditField_4.Tag = 'maxFR';
            app.MaxFRulminEditField_4.Editable = 'off';
            app.UpdateButton_4 = uibutton(app.PanelSPS01_4, 'push');
            app.UpdateButton_4.Text = 'Update';
            app.VulEditField_4Label = uilabel(app.PanelSPS01_4);
            app.VulEditField_4Label.Text = 'V (ul)';
            app.VulEditField_4 = uieditfield(app.PanelSPS01_4, 'numeric');
            app.VulEditField_4.Tag = 'currentV';
            app.VulEditField_4.Editable = 'off';
            app.MoveToButton_4 = uibutton(app.PanelSPS01_4, 'push');
            app.MoveToButton_4.ButtonPushedFcn = createCallbackFcn(app, @MoveToButton_4Pushed, true);
            app.MoveToButton_4.Text = 'MoveTo';
            app.VfulEditField_4Label = uilabel(app.PanelSPS01_4);
            app.VfulEditField_4Label.Text = 'Vf (ul)';
            app.VfulEditField_4 = uieditfield(app.PanelSPS01_4, 'numeric');
            app.VfulEditField_4.Tag = 'finalV';
            app.MaxVulEditField_4Label = uilabel(app.PanelSPS01_4);
            app.MaxVulEditField_4Label.Text = 'MaxV (ul)';
            app.MaxVulEditField_4 = uieditfield(app.PanelSPS01_4, 'numeric');
            app.MaxVulEditField_4.Tag = 'maxV';
            app.MaxVulEditField_4.Editable = 'off';
            app.AutoCalibrationButton_4 = uibutton(app.PanelSPS01_4, 'push');
            app.AutoCalibrationButton_4.ButtonPushedFcn = createCallbackFcn(app, @AutoCalibrationButton_4Pushed, true);
            app.AutoCalibrationButton_4.Text = 'Auto Calibration';
            app.EditField_4 = uieditfield(app.PanelSPS01_4, 'text');
            app.EditField_4.Tag = 'comment';
            app.EditField_4.Editable = 'off';
            app.DiammmEditField_4Label = uilabel(app.PanelSPS01_4);
            app.DiammmEditField_4Label.Text = 'Diam (mm)';
            app.DiammmEditField_4 = uieditfield(app.PanelSPS01_4, 'numeric');
            app.DiammmEditField_4.Tag = 'diam';
            app.DiammmEditField_4.Editable = 'off';
            app.FRulminEditField_4Label = uilabel(app.PanelSPS01_4);
            app.FRulminEditField_4Label.Text = 'FR(ul/min)';
            app.FRulminEditField_4 = uieditfield(app.PanelSPS01_4, 'numeric');
            app.FRulminEditField_4.Tag = 'FlowRate';
            app.AddrEditField_4Label = uilabel(app.Panel_4);
            app.AddrEditField_4Label.Text = 'Addr';
            app.AddrEditField_4 = uieditfield(app.Panel_4, 'numeric');
            app.AddrEditField_4.Tag = 'address';
            app.AddrEditField_4.Editable = 'off';
            app.ClassEditField_4Label = uilabel(app.Panel_4);
            app.ClassEditField_4Label.Text = 'Class';
            app.ClassEditField_4 = uieditfield(app.Panel_4, 'text');
            app.ClassEditField_4.Tag = 'class';
            app.ClassEditField_4.Editable = 'off';
            app.Lamp_4 = uilamp(app.Panel_4);
            app.ChangeButton_4 = uibutton(app.Panel_4, 'push');
            app.ChangeButton_4.ButtonPushedFcn = createCallbackFcn(app, @ChangeButton_4Pushed, true);
            app.ChangeButton_4.Text = 'Change';
            app.FlashButton_4 = uibutton(app.Panel_4, 'push');
            app.FlashButton_4.ButtonPushedFcn = createCallbackFcn(app, @FlashButton_4Pushed, true);
            app.FlashButton_4.Text = 'Flash';
            app.NameEditField_4 = uieditfield(app.Panel_4, 'text');
            app.NameEditField_4.Tag = 'name';
            app.NewNameEditField_4Label = uilabel(app.Panel_4);
            app.NewNameEditField_4Label.Text = 'New Name:';
            app.NewNameEditField_4 = uieditfield(app.Panel_4, 'text');
            app.NewNameEditField_4.Tag = 'nwname';
            app.StopButton_4 = uibutton(app.Panel_4, 'push');
            app.StopButton_4.ButtonPushedFcn = createCallbackFcn(app, @StopButton_4Pushed, true);
            app.StopButton_4.Enable = 'off';
            app.StopButton_4.Text = 'Stop';
            app.Panel_5 = uipanel(app.DeviceControlPanel);
            app.Panel_5.Tag = 'panel5';
            app.PanelC4VM_5 = uipanel(app.Panel_5);
            app.PanelC4VM_5.Visible = 'off';
            app.PanelC4VM_5.Tag = 'mpanel';
            app.Valve2EditField_5Label = uilabel(app.PanelC4VM_5);
            app.Valve2EditField_5Label.Text = 'Valve 2';
            app.Valve2EditField_5 = uieditfield(app.PanelC4VM_5, 'numeric');
            app.Valve2EditField_5.Tag = 'valve2';
            app.Valve3EditField_5Label = uilabel(app.PanelC4VM_5);
            app.Valve3EditField_5Label.Text = 'Valve 3';
            app.Valve3EditField_5 = uieditfield(app.PanelC4VM_5, 'numeric');
            app.Valve3EditField_5.Tag = 'valve3';
            app.Valve4EditField_5Label = uilabel(app.PanelC4VM_5);
            app.Valve4EditField_5Label.Text = 'Valve 4';
            app.Valve4EditField_5 = uieditfield(app.PanelC4VM_5, 'numeric');
            app.Valve4EditField_5.Tag = 'valve4';
            app.Valve1EditField_5Label = uilabel(app.PanelC4VM_5);
            app.Valve1EditField_5Label.Text = 'Valve 1';
            app.Valve1EditField_5 = uieditfield(app.PanelC4VM_5, 'numeric');
            app.Valve1EditField_5.Tag = 'valve1';
            app.SwitchButton_5 = uibutton(app.PanelC4VM_5, 'push');
            app.SwitchButton_5.ButtonPushedFcn = createCallbackFcn(app, @SwitchButton_5Pushed, true);
            app.SwitchButton_5.Text = 'Switch';
            app.nochange1A2closed3BLabel_5 = uilabel(app.PanelC4VM_5);
            app.nochange1A2closed3BLabel_5.Text = {'0 - no change'; '1 - A'; '2 - closed'; '3 - B'};
            app.PanelSPS01_5 = uipanel(app.Panel_5);
            app.PanelSPS01_5.Visible = 'off';
            app.PanelSPS01_5.Tag = 'spanel';
            app.MinFRulminEditField_5Label = uilabel(app.PanelSPS01_5);
            app.MinFRulminEditField_5Label.Text = 'MinFR (ul/min)';
            app.MinFRulminEditField_5 = uieditfield(app.PanelSPS01_5, 'numeric');
            app.MinFRulminEditField_5.Tag = 'minFR';
            app.MinFRulminEditField_5.Editable = 'off';
            app.MaxFRulminEditField_5Label = uilabel(app.PanelSPS01_5);
            app.MaxFRulminEditField_5Label.Text = 'MaxFR (ul/min)';
            app.MaxFRulminEditField_5 = uieditfield(app.PanelSPS01_5, 'numeric');
            app.MaxFRulminEditField_5.Tag = 'maxFR';
            app.MaxFRulminEditField_5.Editable = 'off';
            app.UpdateButton_5 = uibutton(app.PanelSPS01_5, 'push');
            app.UpdateButton_5.Text = 'Update';
            app.VulEditField_5Label = uilabel(app.PanelSPS01_5);
            app.VulEditField_5Label.Text = 'V (ul)';
            app.VulEditField_5 = uieditfield(app.PanelSPS01_5, 'numeric');
            app.VulEditField_5.Tag = 'currentV';
            app.VulEditField_5.Editable = 'off';
            app.MoveToButton_5 = uibutton(app.PanelSPS01_5, 'push');
            app.MoveToButton_5.ButtonPushedFcn = createCallbackFcn(app, @MoveToButton_5Pushed, true);
            app.MoveToButton_5.Text = 'MoveTo';
            app.VfulEditField_5Label = uilabel(app.PanelSPS01_5);
            app.VfulEditField_5Label.Text = 'Vf (ul)';
            app.VfulEditField_5 = uieditfield(app.PanelSPS01_5, 'numeric');
            app.VfulEditField_5.Tag = 'finalV';
            app.MaxVulEditField_5Label = uilabel(app.PanelSPS01_5);
            app.MaxVulEditField_5Label.Text = 'MaxV (ul)';
            app.MaxVulEditField_5 = uieditfield(app.PanelSPS01_5, 'numeric');
            app.MaxVulEditField_5.Tag = 'maxV';
            app.MaxVulEditField_5.Editable = 'off';
            app.AutoCalibrationButton_5 = uibutton(app.PanelSPS01_5, 'push');
            app.AutoCalibrationButton_5.ButtonPushedFcn = createCallbackFcn(app, @AutoCalibrationButton_5Pushed, true);
            app.AutoCalibrationButton_5.Text = 'Auto Calibration';
            app.EditField_5 = uieditfield(app.PanelSPS01_5, 'text');
            app.EditField_5.Tag = 'comment';
            app.EditField_5.Editable = 'off';
            app.DiammmEditField_5Label = uilabel(app.PanelSPS01_5);
            app.DiammmEditField_5Label.Text = 'Diam (mm)';
            app.DiammmEditField_5 = uieditfield(app.PanelSPS01_5, 'numeric');
            app.DiammmEditField_5.Tag = 'diam';
            app.DiammmEditField_5.Editable = 'off';
            app.FRulminEditField_5Label = uilabel(app.PanelSPS01_5);
            app.FRulminEditField_5Label.Text = 'FR(ul/min)';
            app.FRulminEditField_5 = uieditfield(app.PanelSPS01_5, 'numeric');
            app.FRulminEditField_5.Tag = 'FlowRate';
            app.AddrEditField_5Label = uilabel(app.Panel_5);
            app.AddrEditField_5Label.Text = 'Addr';
            app.AddrEditField_5 = uieditfield(app.Panel_5, 'numeric');
            app.AddrEditField_5.Tag = 'address';
            app.AddrEditField_5.Editable = 'off';
            app.ClassEditField_5Label = uilabel(app.Panel_5);
            app.ClassEditField_5Label.Text = 'Class';
            app.ClassEditField_5 = uieditfield(app.Panel_5, 'text');
            app.ClassEditField_5.Tag = 'class';
            app.ClassEditField_5.Editable = 'off';
            app.Lamp_5 = uilamp(app.Panel_5);
            app.ChangeButton_5 = uibutton(app.Panel_5, 'push');
            app.ChangeButton_5.ButtonPushedFcn = createCallbackFcn(app, @ChangeButton_5Pushed, true);
            app.ChangeButton_5.Text = 'Change';
            app.FlashButton_5 = uibutton(app.Panel_5, 'push');
            app.FlashButton_5.ButtonPushedFcn = createCallbackFcn(app, @FlashButton_5Pushed, true);
            app.FlashButton_5.Text = 'Flash';
            app.NameEditField_5 = uieditfield(app.Panel_5, 'text');
            app.NameEditField_5.Tag = 'name';
            app.NewNameEditField_5Label = uilabel(app.Panel_5);
            app.NewNameEditField_5Label.Text = 'New Name:';
            app.NewNameEditField_5 = uieditfield(app.Panel_5, 'text');
            app.NewNameEditField_5.Tag = 'nwname';
            app.StopButton_5 = uibutton(app.Panel_5, 'push');
            app.StopButton_5.ButtonPushedFcn = createCallbackFcn(app, @StopButton_5Pushed, true);
            app.StopButton_5.Enable = 'off';
            app.StopButton_5.Text = 'Stop';
            app.Panel_6 = uipanel(app.DeviceControlPanel);
            app.Panel_6.Tag = 'panel6';
            app.PanelC4VM_6 = uipanel(app.Panel_6);
            app.PanelC4VM_6.Visible = 'off';
            app.PanelC4VM_6.Tag = 'mpanel';
            app.Valve2EditField_6Label = uilabel(app.PanelC4VM_6);
            app.Valve2EditField_6Label.Text = 'Valve 2';
            app.Valve2EditField_6 = uieditfield(app.PanelC4VM_6, 'numeric');
            app.Valve2EditField_6.Tag = 'valve2';
            app.Valve3EditField_6Label = uilabel(app.PanelC4VM_6);
            app.Valve3EditField_6Label.Text = 'Valve 3';
            app.Valve3EditField_6 = uieditfield(app.PanelC4VM_6, 'numeric');
            app.Valve3EditField_6.Tag = 'valve3';
            app.Valve4EditField_6Label = uilabel(app.PanelC4VM_6);
            app.Valve4EditField_6Label.Text = 'Valve 4';
            app.Valve4EditField_6 = uieditfield(app.PanelC4VM_6, 'numeric');
            app.Valve4EditField_6.Tag = 'valve4';
            app.Valve1EditField_6Label = uilabel(app.PanelC4VM_6);
            app.Valve1EditField_6Label.Text = 'Valve 1';
            app.Valve1EditField_6 = uieditfield(app.PanelC4VM_6, 'numeric');
            app.Valve1EditField_6.Tag = 'valve1';
            app.SwitchButton_6 = uibutton(app.PanelC4VM_6, 'push');
            app.SwitchButton_6.ButtonPushedFcn = createCallbackFcn(app, @SwitchButton_6Pushed, true);
            app.SwitchButton_6.Text = 'Switch';
            app.nochange1A2closed3BLabel_6 = uilabel(app.PanelC4VM_6);
            app.nochange1A2closed3BLabel_6.Text = {'0 - no change'; '1 - A'; '2 - closed'; '3 - B'};
            app.PanelSPS01_6 = uipanel(app.Panel_6);
            app.PanelSPS01_6.Visible = 'off';
            app.PanelSPS01_6.Tag = 'spanel';
            app.MinFRulminEditField_6Label = uilabel(app.PanelSPS01_6);
            app.MinFRulminEditField_6Label.Text = 'MinFR (ul/min)';
            app.MinFRulminEditField_6 = uieditfield(app.PanelSPS01_6, 'numeric');
            app.MinFRulminEditField_6.Tag = 'minFR';
            app.MinFRulminEditField_6.Editable = 'off';
            app.MaxFRulminEditField_6Label = uilabel(app.PanelSPS01_6);
            app.MaxFRulminEditField_6Label.Text = 'MaxFR (ul/min)';
            app.MaxFRulminEditField_6 = uieditfield(app.PanelSPS01_6, 'numeric');
            app.MaxFRulminEditField_6.Tag = 'maxFR';
            app.MaxFRulminEditField_6.Editable = 'off';
            app.UpdateButton_6 = uibutton(app.PanelSPS01_6, 'push');
            app.UpdateButton_6.Text = 'Update';
            app.VulEditField_6Label = uilabel(app.PanelSPS01_6);
            app.VulEditField_6Label.Text = 'V (ul)';
            app.VulEditField_6 = uieditfield(app.PanelSPS01_6, 'numeric');
            app.VulEditField_6.Tag = 'currentV';
            app.VulEditField_6.Editable = 'off';
            app.MoveToButton_6 = uibutton(app.PanelSPS01_6, 'push');
            app.MoveToButton_6.ButtonPushedFcn = createCallbackFcn(app, @MoveToButton_6Pushed, true);
            app.MoveToButton_6.Text = 'MoveTo';
            app.VfulEditField_6Label = uilabel(app.PanelSPS01_6);
            app.VfulEditField_6Label.Text = 'Vf (ul)';
            app.VfulEditField_6 = uieditfield(app.PanelSPS01_6, 'numeric');
            app.VfulEditField_6.Tag = 'finalV';
            app.MaxVulEditField_6Label = uilabel(app.PanelSPS01_6);
            app.MaxVulEditField_6Label.Text = 'MaxV (ul)';
            app.MaxVulEditField_6 = uieditfield(app.PanelSPS01_6, 'numeric');
            app.MaxVulEditField_6.Tag = 'maxV';
            app.MaxVulEditField_6.Editable = 'off';
            app.AutoCalibrationButton_6 = uibutton(app.PanelSPS01_6, 'push');
            app.AutoCalibrationButton_6.ButtonPushedFcn = createCallbackFcn(app, @AutoCalibrationButton_6Pushed, true);
            app.AutoCalibrationButton_6.Text = 'Auto Calibration';
            app.EditField_6 = uieditfield(app.PanelSPS01_6, 'text');
            app.EditField_6.Tag = 'comment';
            app.EditField_6.Editable = 'off';
            app.DiammmEditField_6Label = uilabel(app.PanelSPS01_6);
            app.DiammmEditField_6Label.Text = 'Diam (mm)';
            app.DiammmEditField_6 = uieditfield(app.PanelSPS01_6, 'numeric');
            app.DiammmEditField_6.Tag = 'diam';
            app.DiammmEditField_6.Editable = 'off';
            app.FRulminEditField_6Label = uilabel(app.PanelSPS01_6);
            app.FRulminEditField_6Label.Text = 'FR(ul/min)';
            app.FRulminEditField_6 = uieditfield(app.PanelSPS01_6, 'numeric');
            app.FRulminEditField_6.Tag = 'FlowRate';
            app.AddrEditField_6Label = uilabel(app.Panel_6);
            app.AddrEditField_6Label.Text = 'Addr';
            app.AddrEditField_6 = uieditfield(app.Panel_6, 'numeric');
            app.AddrEditField_6.Tag = 'address';
            app.AddrEditField_6.Editable = 'off';
            app.ClassEditField_6Label = uilabel(app.Panel_6);
            app.ClassEditField_6Label.Text = 'Class';
            app.ClassEditField_6 = uieditfield(app.Panel_6, 'text');
            app.ClassEditField_6.Tag = 'class';
            app.ClassEditField_6.Editable = 'off';
            app.Lamp_6 = uilamp(app.Panel_6);
            app.ChangeButton_6 = uibutton(app.Panel_6, 'push');
            app.ChangeButton_6.ButtonPushedFcn = createCallbackFcn(app, @ChangeButton_6Pushed, true);
            app.ChangeButton_6.Text = 'Change';
            app.FlashButton_6 = uibutton(app.Panel_6, 'push');
            app.FlashButton_6.ButtonPushedFcn = createCallbackFcn(app, @FlashButton_6Pushed, true);
            app.FlashButton_6.Text = 'Flash';
            app.NameEditField_6 = uieditfield(app.Panel_6, 'text');
            app.NameEditField_6.Tag = 'name';
            app.NewNameEditField_6Label = uilabel(app.Panel_6);
            app.NewNameEditField_6Label.Text = 'New Name:';
            app.NewNameEditField_6 = uieditfield(app.Panel_6, 'text');
            app.NewNameEditField_6.Tag = 'nwname';
            app.StopButton_6 = uibutton(app.Panel_6, 'push');
            app.StopButton_6.ButtonPushedFcn = createCallbackFcn(app, @StopButton_6Pushed, true);
            app.StopButton_6.Enable = 'off';
            app.StopButton_6.Text = 'Stop';
            app.Panel_7 = uipanel(app.DeviceControlPanel);
            app.Panel_7.Tag = 'panel7';
            app.PanelC4VM_7 = uipanel(app.Panel_7);
            app.PanelC4VM_7.Visible = 'off';
            app.PanelC4VM_7.Tag = 'mpanel';
            app.Valve2EditField_7Label = uilabel(app.PanelC4VM_7);
            app.Valve2EditField_7Label.Text = 'Valve 2';
            app.Valve2EditField_7 = uieditfield(app.PanelC4VM_7, 'numeric');
            app.Valve2EditField_7.Tag = 'valve2';
            app.Valve3EditField_7Label = uilabel(app.PanelC4VM_7);
            app.Valve3EditField_7Label.Text = 'Valve 3';
            app.Valve3EditField_7 = uieditfield(app.PanelC4VM_7, 'numeric');
            app.Valve3EditField_7.Tag = 'valve3';
            app.Valve4EditField_7Label = uilabel(app.PanelC4VM_7);
            app.Valve4EditField_7Label.Text = 'Valve 4';
            app.Valve4EditField_7 = uieditfield(app.PanelC4VM_7, 'numeric');
            app.Valve4EditField_7.Tag = 'valve4';
            app.Valve1EditField_7Label = uilabel(app.PanelC4VM_7);
            app.Valve1EditField_7Label.Text = 'Valve 1';
            app.Valve1EditField_7 = uieditfield(app.PanelC4VM_7, 'numeric');
            app.Valve1EditField_7.Tag = 'valve1';
            app.SwitchButton_7 = uibutton(app.PanelC4VM_7, 'push');
            app.SwitchButton_7.ButtonPushedFcn = createCallbackFcn(app, @SwitchButton_7Pushed, true);
            app.SwitchButton_7.Text = 'Switch';
            app.nochange1A2closed3BLabel_7 = uilabel(app.PanelC4VM_7);
            app.nochange1A2closed3BLabel_7.Text = {'0 - no change'; '1 - A'; '2 - closed'; '3 - B'};
            app.PanelSPS01_7 = uipanel(app.Panel_7);
            app.PanelSPS01_7.Visible = 'off';
            app.PanelSPS01_7.Tag = 'spanel';
            app.MinFRulminEditField_7Label = uilabel(app.PanelSPS01_7);
            app.MinFRulminEditField_7Label.Text = 'MinFR (ul/min)';
            app.MinFRulminEditField_7 = uieditfield(app.PanelSPS01_7, 'numeric');
            app.MinFRulminEditField_7.Tag = 'minFR';
            app.MinFRulminEditField_7.Editable = 'off';
            app.MaxFRulminEditField_7Label = uilabel(app.PanelSPS01_7);
            app.MaxFRulminEditField_7Label.Text = 'MaxFR (ul/min)';
            app.MaxFRulminEditField_7 = uieditfield(app.PanelSPS01_7, 'numeric');
            app.MaxFRulminEditField_7.Tag = 'maxFR';
            app.MaxFRulminEditField_7.Editable = 'off';
            app.UpdateButton_7 = uibutton(app.PanelSPS01_7, 'push');
            app.UpdateButton_7.Text = 'Update';
            app.VulEditField_7Label = uilabel(app.PanelSPS01_7);
            app.VulEditField_7Label.Text = 'V (ul)';
            app.VulEditField_7 = uieditfield(app.PanelSPS01_7, 'numeric');
            app.VulEditField_7.Tag = 'currentV';
            app.VulEditField_7.Editable = 'off';
            app.MoveToButton_7 = uibutton(app.PanelSPS01_7, 'push');
            app.MoveToButton_7.ButtonPushedFcn = createCallbackFcn(app, @MoveToButton_7Pushed, true);
            app.MoveToButton_7.Text = 'MoveTo';
            app.VfulEditField_7Label = uilabel(app.PanelSPS01_7);
            app.VfulEditField_7Label.Text = 'Vf (ul)';
            app.VfulEditField_7 = uieditfield(app.PanelSPS01_7, 'numeric');
            app.VfulEditField_7.Tag = 'finalV';
            app.MaxVulEditField_7Label = uilabel(app.PanelSPS01_7);
            app.MaxVulEditField_7Label.Text = 'MaxV (ul)';
            app.MaxVulEditField_7 = uieditfield(app.PanelSPS01_7, 'numeric');
            app.MaxVulEditField_7.Tag = 'maxV';
            app.MaxVulEditField_7.Editable = 'off';
            app.AutoCalibrationButton_7 = uibutton(app.PanelSPS01_7, 'push');
            app.AutoCalibrationButton_7.ButtonPushedFcn = createCallbackFcn(app, @AutoCalibrationButton_7Pushed, true);
            app.AutoCalibrationButton_7.Text = 'Auto Calibration';
            app.EditField_7 = uieditfield(app.PanelSPS01_7, 'text');
            app.EditField_7.Tag = 'comment';
            app.EditField_7.Editable = 'off';
            app.DiammmEditField_7Label = uilabel(app.PanelSPS01_7);
            app.DiammmEditField_7Label.Text = 'Diam (mm)';
            app.DiammmEditField_7 = uieditfield(app.PanelSPS01_7, 'numeric');
            app.DiammmEditField_7.Tag = 'diam';
            app.DiammmEditField_7.Editable = 'off';
            app.FRulminEditField_7Label = uilabel(app.PanelSPS01_7);
            app.FRulminEditField_7Label.Text = 'FR(ul/min)';
            app.FRulminEditField_7 = uieditfield(app.PanelSPS01_7, 'numeric');
            app.FRulminEditField_7.Tag = 'FlowRate';
            app.AddrEditField_7Label = uilabel(app.Panel_7);
            app.AddrEditField_7Label.Text = 'Addr';
            app.AddrEditField_7 = uieditfield(app.Panel_7, 'numeric');
            app.AddrEditField_7.Tag = 'address';
            app.AddrEditField_7.Editable = 'off';
            app.ClassEditField_7Label = uilabel(app.Panel_7);
            app.ClassEditField_7Label.Text = 'Class';
            app.ClassEditField_7 = uieditfield(app.Panel_7, 'text');
            app.ClassEditField_7.Tag = 'class';
            app.ClassEditField_7.Editable = 'off';
            app.Lamp_7 = uilamp(app.Panel_7);
            app.ChangeButton_7 = uibutton(app.Panel_7, 'push');
            app.ChangeButton_7.ButtonPushedFcn = createCallbackFcn(app, @ChangeButton_7Pushed, true);
            app.ChangeButton_7.Text = 'Change';
            app.FlashButton_7 = uibutton(app.Panel_7, 'push');
            app.FlashButton_7.ButtonPushedFcn = createCallbackFcn(app, @FlashButton_7Pushed, true);
            app.FlashButton_7.Text = 'Flash';
            app.NameEditField_7 = uieditfield(app.Panel_7, 'text');
            app.NameEditField_7.Tag = 'name';
            app.NewNameEditField_7Label = uilabel(app.Panel_7);
            app.NewNameEditField_7Label.Text = 'New Name:';
            app.NewNameEditField_7 = uieditfield(app.Panel_7, 'text');
            app.NewNameEditField_7.Tag = 'nwname';
            app.StopButton_7 = uibutton(app.Panel_7, 'push');
            app.StopButton_7.ButtonPushedFcn = createCallbackFcn(app, @StopButton_7Pushed, true);
            app.StopButton_7.Enable = 'off';
            app.StopButton_7.Text = 'Stop';
            app.Panel_8 = uipanel(app.DeviceControlPanel);
            app.Panel_8.Tag = 'panel8';
            app.PanelC4VM_8 = uipanel(app.Panel_8);
            app.PanelC4VM_8.Visible = 'off';
            app.PanelC4VM_8.Tag = 'mpanel';
            app.Valve2EditField_8Label = uilabel(app.PanelC4VM_8);
            app.Valve2EditField_8Label.Text = 'Valve 2';
            app.Valve2EditField_8 = uieditfield(app.PanelC4VM_8, 'numeric');
            app.Valve2EditField_8.Tag = 'valve2';
            app.Valve3EditField_8Label = uilabel(app.PanelC4VM_8);
            app.Valve3EditField_8Label.Text = 'Valve 3';
            app.Valve3EditField_8 = uieditfield(app.PanelC4VM_8, 'numeric');
            app.Valve3EditField_8.Tag = 'valve3';
            app.Valve4EditField_8Label = uilabel(app.PanelC4VM_8);
            app.Valve4EditField_8Label.Text = 'Valve 4';
            app.Valve4EditField_8 = uieditfield(app.PanelC4VM_8, 'numeric');
            app.Valve4EditField_8.Tag = 'valve4';
            app.Valve1EditField_8Label = uilabel(app.PanelC4VM_8);
            app.Valve1EditField_8Label.Text = 'Valve 1';
            app.Valve1EditField_8 = uieditfield(app.PanelC4VM_8, 'numeric');
            app.Valve1EditField_8.Tag = 'valve1';
            app.SwitchButton_8 = uibutton(app.PanelC4VM_8, 'push');
            app.SwitchButton_8.ButtonPushedFcn = createCallbackFcn(app, @SwitchButton_8Pushed, true);
            app.SwitchButton_8.Text = 'Switch';
            app.nochange1A2closed3BLabel_8 = uilabel(app.PanelC4VM_8);
            app.nochange1A2closed3BLabel_8.Text = {'0 - no change'; '1 - A'; '2 - closed'; '3 - B'};
            app.PanelSPS01_8 = uipanel(app.Panel_8);
            app.PanelSPS01_8.Visible = 'off';
            app.PanelSPS01_8.Tag = 'spanel';
            app.MinFRulminEditField_8Label = uilabel(app.PanelSPS01_8);
            app.MinFRulminEditField_8Label.Text = 'MinFR (ul/min)';
            app.MinFRulminEditField_8 = uieditfield(app.PanelSPS01_8, 'numeric');
            app.MinFRulminEditField_8.Tag = 'minFR';
            app.MinFRulminEditField_8.Editable = 'off';
            app.MaxFRulminEditField_8Label = uilabel(app.PanelSPS01_8);
            app.MaxFRulminEditField_8Label.Text = 'MaxFR (ul/min)';
            app.MaxFRulminEditField_8 = uieditfield(app.PanelSPS01_8, 'numeric');
            app.MaxFRulminEditField_8.Tag = 'maxFR';
            app.MaxFRulminEditField_8.Editable = 'off';
            app.UpdateButton_8 = uibutton(app.PanelSPS01_8, 'push');
            app.UpdateButton_8.Text = 'Update';
            app.VulEditField_8Label = uilabel(app.PanelSPS01_8);
            app.VulEditField_8Label.Text = 'V (ul)';
            app.VulEditField_8 = uieditfield(app.PanelSPS01_8, 'numeric');
            app.VulEditField_8.Tag = 'currentV';
            app.VulEditField_8.Editable = 'off';
            app.MoveToButton_8 = uibutton(app.PanelSPS01_8, 'push');
            app.MoveToButton_8.ButtonPushedFcn = createCallbackFcn(app, @MoveToButton_8Pushed, true);
            app.MoveToButton_8.Text = 'MoveTo';
            app.VfulEditField_8Label = uilabel(app.PanelSPS01_8);
            app.VfulEditField_8Label.Text = 'Vf (ul)';
            app.VfulEditField_8 = uieditfield(app.PanelSPS01_8, 'numeric');
            app.VfulEditField_8.Tag = 'finalV';
            app.MaxVulEditField_8Label = uilabel(app.PanelSPS01_8);
            app.MaxVulEditField_8Label.Text = 'MaxV (ul)';
            app.MaxVulEditField_8 = uieditfield(app.PanelSPS01_8, 'numeric');
            app.MaxVulEditField_8.Tag = 'maxV';
            app.MaxVulEditField_8.Editable = 'off';
            app.AutoCalibrationButton_8 = uibutton(app.PanelSPS01_8, 'push');
            app.AutoCalibrationButton_8.ButtonPushedFcn = createCallbackFcn(app, @AutoCalibrationButton_8Pushed, true);
            app.AutoCalibrationButton_8.Text = 'Auto Calibration';
            app.EditField_8 = uieditfield(app.PanelSPS01_8, 'text');
            app.EditField_8.Tag = 'comment';
            app.EditField_8.Editable = 'off';
            app.DiammmEditField_8Label = uilabel(app.PanelSPS01_8);
            app.DiammmEditField_8Label.Text = 'Diam (mm)';
            app.DiammmEditField_8 = uieditfield(app.PanelSPS01_8, 'numeric');
            app.DiammmEditField_8.Tag = 'diam';
            app.DiammmEditField_8.Editable = 'off';
            app.FRulminEditField_8Label = uilabel(app.PanelSPS01_8);
            app.FRulminEditField_8Label.Text = 'FR(ul/min)';
            app.FRulminEditField_8 = uieditfield(app.PanelSPS01_8, 'numeric');
            app.FRulminEditField_8.Tag = 'FlowRate';
            app.AddrEditField_8Label = uilabel(app.Panel_8);
            app.AddrEditField_8Label.Text = 'Addr';
            app.AddrEditField_8 = uieditfield(app.Panel_8, 'numeric');
            app.AddrEditField_8.Tag = 'address';
            app.AddrEditField_8.Editable = 'off';
            app.ClassEditField_8Label = uilabel(app.Panel_8);
            app.ClassEditField_8Label.Text = 'Class';
            app.ClassEditField_8 = uieditfield(app.Panel_8, 'text');
            app.ClassEditField_8.Tag = 'class';
            app.ClassEditField_8.Editable = 'off';
            app.Lamp_8 = uilamp(app.Panel_8);
            app.ChangeButton_8 = uibutton(app.Panel_8, 'push');
            app.ChangeButton_8.ButtonPushedFcn = createCallbackFcn(app, @ChangeButton_8Pushed, true);
            app.ChangeButton_8.Text = 'Change';
            app.FlashButton_8 = uibutton(app.Panel_8, 'push');
            app.FlashButton_8.ButtonPushedFcn = createCallbackFcn(app, @FlashButton_8Pushed, true);
            app.FlashButton_8.Text = 'Flash';
            app.NameEditField_8 = uieditfield(app.Panel_8, 'text');
            app.NameEditField_8.Tag = 'name';
            app.NewNameEditField_8Label = uilabel(app.Panel_8);
            app.NewNameEditField_8Label.Text = 'New Name:';
            app.NewNameEditField_8 = uieditfield(app.Panel_8, 'text');
            app.NewNameEditField_8.Tag = 'nwname';
            app.StopButton_8 = uibutton(app.Panel_8, 'push');
            app.StopButton_8.ButtonPushedFcn = createCallbackFcn(app, @StopButton_8Pushed, true);
            app.StopButton_8.Enable = 'off';
            app.StopButton_8.Text = 'Stop';
            app.ScriptsPanel = uipanel(app.LabsmithTab);
            app.ScriptsPanel.Title = 'Scripts';
            app.SearchButton = uibutton(app.ScriptsPanel, 'push');
            app.SearchButton.ButtonPushedFcn = createCallbackFcn(app, @SearchButtonPushed, true);
            app.SearchButton.Text = 'Search';
            app.FileDropDown = uidropdown(app.ScriptsPanel);
            app.FileDropDown.Items = {};
            app.FileDropDown.Value = {};
            app.ShowButton = uibutton(app.ScriptsPanel, 'push');
            app.ShowButton.ButtonPushedFcn = createCallbackFcn(app, @ShowButtonPushed, true);
            app.ShowButton.Text = 'Show';
            app.StopScriptButton = uibutton(app.ScriptsPanel, 'push');
            app.StopScriptButton.ButtonPushedFcn = createCallbackFcn(app, @StopScriptButtonPushed, true);
            app.StopScriptButton.Text = 'Stop Script';
            app.ResetButton = uibutton(app.ScriptsPanel, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.PauseButton = uibutton(app.ScriptsPanel, 'push');
            app.PauseButton.ButtonPushedFcn = createCallbackFcn(app, @PauseButtonPushed, true);
            app.PauseButton.Text = 'Pause';
            app.ResumeButton = uibutton(app.ScriptsPanel, 'push');
            app.ResumeButton.ButtonPushedFcn = createCallbackFcn(app, @ResumeButtonPushed, true);
            app.ResumeButton.Enable = 'off';
            app.ResumeButton.Text = 'Resume';
            app.StartScriptButton = uibutton(app.ScriptsPanel, 'push');
            app.StartScriptButton.ButtonPushedFcn = createCallbackFcn(app, @StartScriptButtonPushed, true);
            app.StartScriptButton.Text = 'Start Script';
            app.NewButton = uibutton(app.ScriptsPanel, 'push');
            app.NewButton.Text = 'New';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = appCombined_diffTab

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end